classdef analyze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AngleEdit               matlab.ui.control.NumericEditField
        AngleEditFieldLabel     matlab.ui.control.Label
        TopoButton              matlab.ui.control.Button
        LogScaleCheckBox        matlab.ui.control.CheckBox
        HeaderPanel             matlab.ui.container.Panel
        InfoImage               matlab.ui.control.Image
        MagneticFieldValue      matlab.ui.control.Label
        TemperatureValue        matlab.ui.control.Label
        MagneticFieldLabel      matlab.ui.control.Label
        TemperatureLabel        matlab.ui.control.Label
        MousecontrolsCheckBox   matlab.ui.control.CheckBox
        KeepChangesButton       matlab.ui.control.Button
        AnalyzeLabel            matlab.ui.control.Label
        MinSlider               matlab.ui.control.Slider
        MinEditField            matlab.ui.control.NumericEditField
        MaxEditField            matlab.ui.control.NumericEditField
        MaxSlider               matlab.ui.control.Slider
        BilinearinterpCheckBox  matlab.ui.control.CheckBox
        PasteButton             matlab.ui.control.Button
        ContrasttoallButton     matlab.ui.control.Button
        SavecontrastButton      matlab.ui.control.Button
        AutoContrastButton      matlab.ui.control.Button
        ThresholdEditField      matlab.ui.control.NumericEditField
        EnergySpinner           matlab.ui.control.Spinner
        meVLabel                matlab.ui.control.Label
        SquareOnOffButton       matlab.ui.control.Button
        SquareOnOffSpinner      matlab.ui.control.Spinner
        HexagonOnOffButton      matlab.ui.control.Button
        HexagonOnOffSpinner     matlab.ui.control.Spinner
        ExportAvgAreasButton    matlab.ui.control.Button
        LoadAvgAreasButton      matlab.ui.control.Button
        LineOnOffButton         matlab.ui.control.Button
        ProfileButton           matlab.ui.control.Button
        MaxEditFieldLabel       matlab.ui.control.Label
        MinEditFieldLabel       matlab.ui.control.Label
        LowerBtnPanel           matlab.ui.container.Panel
        SavematrixButton        matlab.ui.control.Button
        SaveFigureButton        matlab.ui.control.Button
        CopyasbitmapButton      matlab.ui.control.Button
        CopyasvectorButton      matlab.ui.control.Button
        CmapPanel               matlab.ui.container.Panel
        ColormapValue           matlab.ui.control.Label
        ColormapLabel           matlab.ui.control.Label
        ColorbarCheckBox        matlab.ui.control.CheckBox
        Axes                    matlab.ui.control.UIAxes
        HexagonContextMenu      matlab.ui.container.ContextMenu
        UTe2011Menu             matlab.ui.container.Menu
        ClipboardVecMenu        matlab.ui.container.ContextMenu
        CopyColorbarVecMenu     matlab.ui.container.Menu
        CopyColorbarVecLimMenu  matlab.ui.container.Menu
        ClipboardImgMenu        matlab.ui.container.ContextMenu
        CopyColorbarImgMenu     matlab.ui.container.Menu
        CopyColorbarImgLimMenu  matlab.ui.container.Menu
        SaveImgMenu             matlab.ui.container.ContextMenu
        SaveRawImageMenu        matlab.ui.container.Menu
    end

    
    properties (Access = public)
        CallingApp % main app object
        
        Cell % Set of maps to show        
        k % map index
        isReal % true-Real, false-FFT
        Interpolation %nearest or bilinear
        Colorbar 
        Threshold %Fraction of points to leave out for auto contrast
        Cross

        ModifierPress % Modifier keys pressed at the moment
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, Type)
            app.CallingApp = mainApp;
            app.Threshold = app.ThresholdEditField.Value/100;
            %this makes nothing as it is loaded before the value has been set,
            % so it is still empty and always defaults to fft
            app.Axes.Interactions = [];
            app.Interpolation = app.BilinearinterpCheckBox.Value;
            %We could leave the data cursor on, but its better to disable it
            %altogether
            % axtoolbar(app.Axes,{'datacursor'})
            app.Axes.Toolbar.Visible = 'off';
            axtoolbar(app.Axes,{});
            switch Type
                case 'FFT'
                    app.isReal = false;
                    app.Axes.Tag = 'FFTAxes';

                    app.LoadAvgAreasButton.Enable = false;
                    app.LoadAvgAreasButton.Visible = false;
                    app.ExportAvgAreasButton.Enable = false;
                    app.ExportAvgAreasButton.Visible = false;
                    app.SquareOnOffButton.Visible = true;
                    app.SquareOnOffSpinner.Visible = true;
                    app.HexagonOnOffButton.Visible = true;
                    app.HexagonOnOffSpinner.Visible = true;
                    app.SquareOnOffButton.Enable = true;
                    app.SquareOnOffSpinner.Enable = true;
                    app.HexagonOnOffButton.Enable = true;
                    app.HexagonOnOffSpinner.Enable = true;

                    app.UIFigure.Name = 'Analyze FFT Maps';
                    app.AnalyzeLabel.Text = 'Analyze FFT';

                    txt = "Return: Saves contrast of current map\n" +...
                        "Space: Saves current contrast to all maps\n" +...
                        "A: Autoadjust contrast of current map\n" + ...
                        "Right Arrow: Next map\n" +...
                        "Left Arrow: Previous map\n" + ...
                        "O: Return to original FOV\n" + ...
                        "S: Makes a square window\n" + ...
                        "B: Focus on 1st Brilloiun zone\n" + ...
                        "P: Focus on area within the Bragg Peaks\n" + ...
                        "C: Centers map at origin\n" + ...
                        "T: Draws a centered cross to align peaks\n" + ...
                        "Ctrl+Z: Deletes last curve\n" + ...
                        "Ctrl+C: Copy map as vector\n" + ...
                        "X: Print mouse position and map value";
                case 'Real'
                    app.isReal = true;
                    app.Axes.Tag = 'RealAxes';

                    app.LoadAvgAreasButton.Enable = true;
                    app.LoadAvgAreasButton.Visible = true;
                    app.ExportAvgAreasButton.Enable = true;
                    app.ExportAvgAreasButton.Visible = true;
                    app.SquareOnOffButton.Visible = false;
                    app.SquareOnOffSpinner.Visible = false;
                    app.HexagonOnOffButton.Visible = false;
                    app.HexagonOnOffSpinner.Visible = false;
                    app.SquareOnOffButton.Enable = false;
                    app.SquareOnOffSpinner.Enable = false;
                    app.HexagonOnOffButton.Enable = false;
                    app.HexagonOnOffSpinner.Enable = false;

                    app.UIFigure.Name = 'Analyze Real Maps';
                    app.AnalyzeLabel.Text = 'Analyze Real';
            
                    txt = "Return: Saves contrast of current map\n" +...
                        "Space: Saves current contrast to all maps\n" +...
                        "A: Autoadjust contrast of current map\n" + ...
                        "Right Arrow: Next map\n" +...
                        "Left Arrow: Previous map\n" + ...
                        "O: Return to original FOV\n" + ...
                        "S: Makes a square window\n" + ...
                        "Ctrl+Z: Deletes last curve\n" + ...
                        "Ctrl+C: Copy map as vector\n" + ...
                        "X: Print mouse position and map value";


            end
%            if app.isReal 
%
%                 app.LoadAvgAreasButton.Enable = true;
%                 app.LoadAvgAreasButton.Visible = true;
%                 app.ExportAvgAreasButton.Enable = true;
%                 app.ExportAvgAreasButton.Visible = true;
%                 app.SquareOnOffButton.Visible = false;
%                 app.SquareOnOffSpinner.Visible = false;
%                 app.HexagonOnOffButton.Visible = false;
%                 app.HexagonOnOffSpinner.Visible = false;
%                 app.SquareOnOffButton.Enable = false;
%                 app.SquareOnOffSpinner.Enable = false;
%                 app.HexagonOnOffButton.Enable = false;
%                 app.HexagonOnOffSpinner.Enable = false;
%
%            else
%
%                 app.LoadAvgAreasButton.Enable = false;
%                 app.LoadAvgAreasButton.Visible = false;
%                 app.ExportAvgAreasButton.Enable = false;
%                 app.ExportAvgAreasButton.Visible = false;
%                 app.SquareOnOffButton.Visible = true;
%                 app.SquareOnOffSpinner.Visible = true;
%                 app.HexagonOnOffButton.Visible = true;
%                 app.HexagonOnOffSpinner.Visible = true;
%
%            end           

            [~, app.k] = min(abs(app.CallingApp.InfoStruct.Energia));
            app.EnergySpinner.Limits = [app.CallingApp.InfoStruct.Energia(1), app.CallingApp.InfoStruct.Energia(end)];
            app.EnergySpinner.Step = abs(app.CallingApp.InfoStruct.Energia(2) - app.CallingApp.InfoStruct.Energia(1));
            app.EnergySpinner.Value = app.CallingApp.InfoStruct.Energia(app.k);
            
            if isfield(app.CallingApp.Struct,'Campo')
                app.MagneticFieldLabel.Visible = true;
                app.MagneticFieldValue.Visible = true;
                app.MagneticFieldValue.Text = [num2str(app.CallingApp.Struct.Campo), ' T'];
            else
                app.MagneticFieldLabel.Visible = false;
                app.MagneticFieldValue.Visible = false;
            end
            if isfield(app.CallingApp.Struct,'Temperatura')
                app.TemperatureLabel.Visible = true;
                app.TemperatureValue.Visible = true;
                app.TemperatureValue.Text = [num2str(app.CallingApp.Struct.Temperatura), ' K'];
            else
                app.TemperatureLabel.Visible = false;
                app.TemperatureValue.Visible = false;
            end
            if isfield(app.CallingApp.InfoStruct,'ColormapName')
                app.ColormapLabel.Visible = true;
                app.ColormapValue.Visible = true;
                app.ColormapValue.Text = app.CallingApp.InfoStruct.ColormapName;              
            else 
                app.ColormapLabel.Visible = false;
                app.ColormapValue.Visible = false;
            end
            % Llamar a la función que pinta

            % Create Tooltip with KeyPress info
            % txt = "Return: Saves contrast of current map\n" +...
            %     "Space: Saves current contrast to all maps\n" +...
            %     "A: Autoadjust contrast of current map\n" + ...
            %     "Right Arrow: Next map\n" +...
            %     "Left Arrow: Previous map\n" + ...
            %     "O: Return to original FOV\n" + ...
            %     "S: Makes a square window\n" + ...
            %     "Ctrl+Z: Deletes last curve\n" + ...
            %     "Ctrl+C: Copy map as vector\n" + ...
            %     "\nOnly in FFT:\n" + ...
            %     "B: Focus on 1st Brilloiun zone\n" + ...
            %     "P: Focus on area within the Bragg Peaks\n" + ...
            %     "C: Centers map at origin\n" + ...
            %     "T: Draws a centered cross to align peaks";
            txt=sprintf(txt);
            app.InfoImage.Tooltip = txt;
        end

        % Value changed function: EnergySpinner
        function EnergySpinnerValueChanged(app, event)
            [~, app.k] = min(abs(app.CallingApp.InfoStruct.Energia - app.EnergySpinner.Value));
            app.EnergySpinner.Value = app.CallingApp.InfoStruct.Energia(app.k);
            
            [app.CallingApp.InfoData] = showCell(app, app.Cell, app.CallingApp.InfoData, app.k, app.Axes);
%             Ratio = (app.Axes.XLim(2) - app.Axes.XLim(1))/(app.Axes.YLim(2) - app.Axes.YLim(1));
%             app.Axes.DataAspectRatio = [1,1,1];
%             disp(app.Axes.DataAspectRatio)
%             app.Axes.DataAspectRatio = app.Axes.DataAspectRatio;
        end

        % Value changed function: MinSlider
        function MinSliderValueChanged(app, event)
            if app.MinSlider.Value > app.MaxSlider.Value
                app.MinSlider.Value = app.MaxSlider.Value;
            end
            app.MinEditField.Value = app.MinSlider.Value;
            app.Axes.CLim(1) = app.MinSlider.Value;
            if app.ColorbarCheckBox.Value
                set(app.Colorbar, 'Limits',[app.MinSlider.Value app.MaxSlider.Value],...
                'YTick', [app.MinSlider.Value app.MaxSlider.Value],'TickLength', 0)
            end
        end

        % Value changed function: MaxSlider
        function MaxSliderValueChanged(app, event)
            if app.MaxSlider.Value < app.MinSlider.Value
                app.MaxSlider.Value = app.MinSlider.Value;
            end
            app.MaxEditField.Value = app.MaxSlider.Value;
            app.Axes.CLim(2) = app.MaxSlider.Value;
            if app.ColorbarCheckBox.Value
                set(app.Colorbar, 'Limits',[app.MinSlider.Value app.MaxSlider.Value],...
                'YTick', [app.MinSlider.Value app.MaxSlider.Value],'TickLength', 0)
            end
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            value = app.MinEditField.Value;
            app.MinSlider.Value = value;
            app.MinSliderValueChanged
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            value = app.MaxEditField.Value;
            app.MaxSlider.Value = value;
            app.MaxSliderValueChanged
        end

        % Button pushed function: SavecontrastButton
        function SavecontrastButtonPushed(app, event)
            if app.isReal
                app.CallingApp.InfoData.ContrastReal(1,app.k) = app.Axes.CLim(1);
                app.CallingApp.InfoData.ContrastReal(2,app.k) = app.Axes.CLim(2);
            else
                app.CallingApp.InfoData.ContrastFFT(1,app.k) = app.Axes.CLim(1);
                app.CallingApp.InfoData.ContrastFFT(2,app.k) = app.Axes.CLim(2);
            end
        end

        % Button pushed function: ContrasttoallButton
        function ContrasttoallButtonPushed(app, event)
            if app.isReal
                app.CallingApp.InfoData.ContrastReal(1,:) = app.Axes.CLim(1);
                app.CallingApp.InfoData.ContrastReal(2,:) = app.Axes.CLim(2);
            else
                app.CallingApp.InfoData.ContrastFFT(1,:) = app.Axes.CLim(1);
                app.CallingApp.InfoData.ContrastFFT(2,:) = app.Axes.CLim(2);
            end
        end

        % Button pushed function: KeepChangesButton
        function KeepChangesButtonPushed(app, event)
            if app.isReal
                app.CallingApp.InfoStructAnalisis = app.CallingApp.InfoData;
                app.CallingApp.InfoStructAnalisis.MapasConductancia = app.CallingApp.InfoStructAnalisisCopy.MapasConductancia;
                app.CallingApp.InfoStructAnalisisCopy = app.CallingApp.InfoStructAnalisis;
            else
                app.CallingApp.InfoStructAnalisis = app.CallingApp.InfoData;
                app.CallingApp.InfoStructAnalisis.Transformadas = app.CallingApp.InfoStructAnalisisCopy.Transformadas;
                app.CallingApp.InfoStructAnalisisCopy = app.CallingApp.InfoStructAnalisis;
            end

            % Display question to overwrite InfoStruct with
            % InfoStructAnalysis, in order to easily save analysis
            % parameters to file
            if ismember(app.ModifierPress,'shift')
                selection = uiconfirm(app.UIFigure,"Overwrite InfoStruct with Analysis parameters?", ...
                    'Giving up already?','Icon','question');
                switch selection
                    case 'OK'
                        app.CallingApp.InfoStruct = app.CallingApp.InfoStructAnalisis;
                        app.ModifierPress = {};
                    case 'Cancel'
                        app.ModifierPress = {};
                        return
                end
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if app.MousecontrolsCheckBox.Value
                Down_v2(app.UIFigure)
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.MousecontrolsCheckBox.Value
                CurrentPoint_v2(app.UIFigure)
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if app.MousecontrolsCheckBox.Value
                checkMapChange = app.EnergySpinner.Value;
                mapsClickAnalyze(app, app.CallingApp.InfoData)
                if app.isReal
%                     app.CallingApp.InfoStruct.XLimReal                      = app.Axes.XLim;
%                     app.CallingApp.InfoStruct.YLimReal                      = app.Axes.YLim;
%                     app.CallingApp.InfoStructAnalisis.XLimReal              = app.Axes.XLim;
%                     app.CallingApp.InfoStructAnalisis.YLimReal              = app.Axes.YLim;
%                     app.CallingApp.InfoStructAnalisisCopy.XLimReal          = app.Axes.XLim;
%                     app.CallingApp.InfoStructAnalisisCopy.YLimReal          = app.Axes.YLim;
                    app.CallingApp.InfoData.XLimReal                        = app.Axes.XLim;
                    app.CallingApp.InfoData.YLimReal                        = app.Axes.YLim;
                else
%                     app.CallingApp.InfoStruct.XLimFFT                       = app.Axes.XLim;
%                     app.CallingApp.InfoStruct.YLimFFT                       = app.Axes.YLim;
%                     app.CallingApp.InfoStructAnalisis.XLimFFT               = app.Axes.XLim;
%                     app.CallingApp.InfoStructAnalisis.YLimFFT               = app.Axes.YLim;
%                     app.CallingApp.InfoStructAnalisisCopy.XLimFFT           = app.Axes.XLim;
%                     app.CallingApp.InfoStructAnalisisCopy.YLimFFT           = app.Axes.YLim;
                    app.CallingApp.InfoData.XLimFFT                         = app.Axes.XLim;
                    app.CallingApp.InfoData.YLimFFT                         = app.Axes.YLim;
                end
%                 Ratio = (app.Axes.XLim(2) - app.Axes.XLim(1))/(app.Axes.YLim(2) - app.Axes.YLim(1));
%                 app.Axes.DataAspectRatio = [1,Ratio,1];
% app.Axes.DataAspectRatio
% app.Axes.DataAspectRatioMode
% app.Axes.PlotBoxAspectRatio
% app.Axes.PlotBoxAspectRatioMode
                if app.EnergySpinner.Value ~= checkMapChange
                    app.EnergySpinnerValueChanged
                end
            end
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            idx = app.Axes.ColorOrderIndex;
            if verticalScrollCount < 0
                if app.EnergySpinner.Value ~= app.EnergySpinner.Limits(2)
                    app.EnergySpinner.Value = app.EnergySpinner.Value + (-1*verticalScrollCount)*app.EnergySpinner.Step;
                    app.EnergySpinnerValueChanged
                end
            else
                if app.EnergySpinner.Value ~= app.EnergySpinner.Limits(1)
                    app.EnergySpinner.Value = app.EnergySpinner.Value + (-1*verticalScrollCount)*app.EnergySpinner.Step;
                    app.EnergySpinnerValueChanged
                end
            end     
            app.Axes.ColorOrderIndex = idx;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            modifier = event.Modifier;
            
            if strcmp(key, 'return')
                app.SavecontrastButtonPushed
            end
            
            if strcmp(key, 'space')
                app.ContrasttoallButtonPushed
            end
            
            if strcmp(key, 'rightarrow')
                if app.EnergySpinner.Value ~= app.EnergySpinner.Limits(2)
                    app.EnergySpinner.Value = app.EnergySpinner.Value + app.EnergySpinner.Step;
                    app.EnergySpinnerValueChanged
                end
            end
            
            if strcmp(key, 'leftarrow')
                if app.EnergySpinner.Value ~= app.EnergySpinner.Limits(1)
                    app.EnergySpinner.Value = app.EnergySpinner.Value - app.EnergySpinner.Step;
                    app.EnergySpinnerValueChanged
                end
            end
            
            if strcmp(key, 'o')
                if app.isReal
                    app.Axes.XLim = [app.CallingApp.InfoData.DistanciaColumnas(1) app.CallingApp.InfoData.DistanciaColumnas(end)];
                    app.Axes.YLim = [app.CallingApp.InfoData.DistanciaFilas(1) app.CallingApp.InfoData.DistanciaFilas(end)];
                    app.CallingApp.InfoData.XLimReal = app.Axes.XLim;
                    app.CallingApp.InfoData.YLimReal = app.Axes.YLim;
                else
                    app.Axes.XLim = [app.CallingApp.InfoData.DistanciaFourierColumnas(1) app.CallingApp.InfoData.DistanciaFourierColumnas(end)];
                    app.Axes.YLim = [app.CallingApp.InfoData.DistanciaFourierFilas(1) app.CallingApp.InfoData.DistanciaFourierFilas(end)];
                    app.CallingApp.InfoData.XLimFFT = app.Axes.XLim;
                    app.CallingApp.InfoData.YLimFFT = app.Axes.YLim;
                end
%                 Ratio = (app.Axes.XLim(2) - app.Axes.XLim(1))/(app.Axes.YLim(2) - app.Axes.YLim(1));
%                 app.Axes.DataAspectRatio = [1,Ratio,1];
            end

            if strcmp(key,'a') %auto contrast for the current map
                %For now we use the data in InfoStructAnalisis as the
                %reference, as they are the changes that we have saved.
                %We can change this to InfoData so the calculation is
                %always refered to what is being shown in the screen
                if app.isReal
                    Contrast = autoContrast(app.CallingApp.InfoStructAnalisis.MapasConductancia{app.k},app.Threshold);
                else
                    Contrast = autoContrast(app.CallingApp.InfoStructAnalisis.Transformadas{app.k},app.Threshold);
                end
                Min = Contrast(1);
                Max = Contrast(2);
                app.MinEditField.Value = Min;
                app.MinSlider.Value = Min;
                app.MaxEditField.Value = Max;
                app.MaxSlider.Value = Max;
                app.Axes.CLim = [Min Max];
            end
            
            if ismember('control', modifier) && strcmp(key, 'c')
                CopyasvectorButtonPushed(app,event);
            end
            
            if strcmp(key, 's') % Makes a square window, maintainig down-left corner
                %app.Axes.YLim = app.Axes.XLim;
                app.Axes.YLim(2) = app.Axes.YLim(1) + diff(app.Axes.XLim);
                if app.isReal                    
                    app.CallingApp.InfoData.XLimReal = app.Axes.XLim;
                    app.CallingApp.InfoData.YLimReal = app.Axes.YLim;
                else
                    app.CallingApp.InfoData.XLimFFT = app.Axes.XLim;
                    app.CallingApp.InfoData.YLimFFT = app.Axes.YLim;
                end
%                 Ratio = (app.Axes.XLim(2) - app.Axes.XLim(1))/(app.Axes.YLim(2) - app.Axes.YLim(1));
%                 app.Axes.DataAspectRatio = [1,Ratio,1];
            end

            %Revert last drawn selection and delete the corresponding curves
            if ismember('control', modifier) && strcmp(key, 'z')
                rmLastIV(app.Axes)
            end


            if strcmp(key,'x')
                %Print on the terminal the current position of the mouse
                pos =app.Axes.CurrentPoint;
                % pos(1,1:2)
                if app.isReal
                    [~,indx] = min(app.CallingApp.InfoData.DistanciaColumnas - pos(1,1),[],ComparisonMethod="abs");
                    [~,indy] = min(app.CallingApp.InfoData.DistanciaFilas - pos(1,2),[],ComparisonMethod="abs");
                    map = findobj(app.Axes.Children,'flat','Type','image');
                   
                    fprintf('Current Location = [%4g, %4g] nm \\\\ Value %4g \n',...
                        app.CallingApp.InfoData.DistanciaColumnas(indx),...
                        app.CallingApp.InfoData.DistanciaFilas(indy),...
                        map(1).CData(indy,indx))
                else
                    [~,indx] = min(app.CallingApp.InfoData.DistanciaFourierColumnas - pos(1,1),[],ComparisonMethod="abs");
                    [~,indy] = min(app.CallingApp.InfoData.DistanciaFourierFilas - pos(1,2),[],ComparisonMethod="abs");
                    map = findobj(app.Axes.Children,'flat','Type','image');

                    kx_pos = app.CallingApp.InfoData.DistanciaFourierColumnas(indx);
                    ky_pos = app.CallingApp.InfoData.DistanciaFourierFilas(indy);
                    real_dist = 1/sqrt(kx_pos^2+ ky_pos^2);
                    angle = atan2d(ky_pos,kx_pos);
                    %calculate the angle with respect to the horizontal
                    fprintf('Current Location = [%.4g, %.4g] 2pi/nm \\\\ Value %.4g \\\\ Equivalent distance in real space %.4g nm \\\\ Angle %.2f° \n',...
                        kx_pos,ky_pos,map(1).CData(indy,indx),real_dist,angle)
                end
                
                %We should change this at some point into displaying the values
                %within the window that updates in real time, and this works as
                %a simple toggle
            end
            
            if ~app.isReal % Only for FFT Analysis
                
                % Focus axes on 1st Brilloin zone.
                if strcmp(key, 'b')
                    ParametroRedColumnas = app.CallingApp.InfoData.ParametroRedColumnas;
                    ParametroRedFilas = app.CallingApp.InfoData.ParametroRedFilas;
                    app.Axes.XLim  = [-1/(2*ParametroRedColumnas), 1/(2*ParametroRedColumnas)];
                    app.Axes.YLim  = [-1/(2*ParametroRedFilas), 1/(2*ParametroRedFilas)];
                    app.CallingApp.InfoData.XLimFFT = app.Axes.XLim;
                    app.CallingApp.InfoData.YLimFFT = app.Axes.YLim;
                    
%                     Ratio = (app.Axes.XLim(2) - app.Axes.XLim(1))/(app.Axes.YLim(2) - app.Axes.YLim(1));
%                     app.Axes.DataAspectRatio = [1,Ratio,1];
                end
                % Focus axes on bragg peaks.
                if strcmp(key, 'p')
                    ParametroRedColumnas = app.CallingApp.InfoData.ParametroRedColumnas;
                    ParametroRedFilas = app.CallingApp.InfoData.ParametroRedFilas;
                    app.Axes.XLim  = [-1/(ParametroRedColumnas), 1/(ParametroRedColumnas)]*1.1;
                    app.Axes.YLim  = [-1/(ParametroRedFilas), 1/(ParametroRedFilas)]*1.1;
                    app.CallingApp.InfoData.XLimFFT = app.Axes.XLim;
                    app.CallingApp.InfoData.YLimFFT = app.Axes.YLim;
                    %This leaves some bragg peaks out for a hexagonal lattice.
                    %We should try something else more specific for each case
%                     Ratio = (app.Axes.XLim(2) - app.Axes.XLim(1))/(app.Axes.YLim(2) - app.Axes.YLim(1));
%                     app.Axes.DataAspectRatio = [1,Ratio,1];
                end
                
                % Centers map at origin without changing size
                if strcmp(key, 'c')
                    %app.Axes.XLim(2) = -app.Axes.XLim(1);
                    %app.Axes.YLim(2) = -app.Axes.YLim(1);
                    app.Axes.XLim = diff(app.Axes.XLim)/2 * [-1 1];
                    app.Axes.YLim = diff(app.Axes.YLim)/2 * [-1 1];
                    app.CallingApp.InfoData.XLimFFT = app.Axes.XLim;
                    app.CallingApp.InfoData.YLimFFT = app.Axes.YLim;
                    
%                     Ratio = (app.Axes.XLim(2) - app.Axes.XLim(1))/(app.Axes.YLim(2) - app.Axes.YLim(1));
%                     app.Axes.DataAspectRatio = [1,Ratio,1];
                end

                % Toggle a crosshair around the center of the image, to
                % help align the bragg peaks with the axes   
                if strcmp(key,'t')
                    if isempty(app.Cross)
                        app.Cross = drawcrosshair(app.Axes,...
                            Position = [0, 0],Color = 'red',...
                            InteractionsAllowed = 'none');
                    else
                        if isvalid(app.Cross)
                        delete(app.Cross)
                        else
                            app.Cross = drawcrosshair(app.Axes,...
                                Position = [0, 0],Color = 'red',...
                                InteractionsAllowed = 'none');
                        end
                    end
                end
                
                if ismember('control', modifier) && strcmp(key, 'c')
                CopyasvectorButtonPushed(app,event);
                end
                
            end


        end

        % Button pushed function: LineOnOffButton
        function LineOnOffButtonPushed(app, event)
            LX = (app.Axes.XLim(2)-app.Axes.XLim(1))/2;
            LY = (app.Axes.YLim(2)-app.Axes.YLim(1))/2;
            Xinicio = app.Axes.XLim(1) + LX;
            Yinicio = app.Axes.YLim(1) + LY;
            Xfinal = Xinicio + LX/4;
            Yfinal = Yinicio + LY/4;
            % Find if there is any line present already
            LineObj = findobj(app.Axes,'Tag','lineProfile');
            if isempty(LineObj)
                if isfield(app.Axes.UserData, 'lineProfilePosition')
                    drawline(app.Axes,'Position', app.Axes.UserData.lineProfilePosition,'Tag', 'lineProfile','Color','red');
                else
                    drawline(app.Axes,'Position', [Xinicio Yinicio; Xfinal Yfinal],'Tag', 'lineProfile','Color','red');
                end
            else
                %Save the position of the last drawn profile
                app.Axes.UserData.lineProfilePosition = LineObj(1).Position;
                %Delete all line profiles present on the axis
                delete(LineObj) 
            end
            %Is not such a bad idea to keep it and just make it invisible,
            %if the performance is not too different
        end

        % Button pushed function: ProfileButton
        function ProfileButtonPushed(app, event)
            if app.isReal
                profileReal(app.Axes, app.CallingApp.InfoStructAnalisis, app.k);
            else
                profileFFT(app.Axes, app.CallingApp.InfoStructAnalisis, app.k);
            end
        end

        % Button pushed function: SquareOnOffButton
        function SquareOnOffButtonPushed(app, event)
            a = app.SquareOnOffSpinner.Value/app.CallingApp.InfoStruct.ParametroRedColumnas;
            fprintf('Equivalent lattice parameter: %.3g nm\n',1/a)
            angle = app.AngleEdit.Value;
            trot = rigidtform2d(angle,[0,0]);
            % Real space coordinates for a square of radius a with vertices
            % along the axes
            u = a*[-1 0 1 0];
            v = a*[0 -1 0 1];
            [x,y] = transformPointsForward(trot,u,v);
            % Create a square 
            BraggSquare = polyshape(x,y);
            % Create a square for the corresponding First Brillouin zone
            BrillVert = wignerSeitz2D_V2([x.' y.']);
            BrillouinSquare = polyshape(BrillVert(:,1),BrillVert(:,2));
            % % Plot the shapes on Analysis window
            hold(app.Axes,'on')
            plot(app.Axes,BraggSquare,'FaceAlpha',0,'EdgeColor','r')
            plot(app.Axes,BrillouinSquare,'FaceAlpha',0,'EdgeColor','r','LineStyle','--')
        end

        % Button pushed function: HexagonOnOffButton
        function HexagonOnOffButtonPushed(app, event)
            a = app.HexagonOnOffSpinner.Value*...
                2/sqrt(3)/app.CallingApp.InfoStruct.ParametroRedColumnas;
            angle = app.AngleEdit.Value;
            trot = rigidtform2d(angle,[0,0]);
            % Coordinates for a hexagon with one vertex alligned with the
            % vertical axis, with radius a
            u = a*[1 0 -1 -1 0 1]*sqrt(3)/2;
            v = a*[1 2 1 -1 -2 -1]/2;
            [x,y] = transformPointsForward(trot,u,v);
            % Create a hexagon for the bragg peaks acording to the given radius
            BraggHexagon = polyshape(x,y);
            % Create a rotated hexagon for the corresponding First Brillouin zone
            BrillVert = wignerSeitz2D_V2([x.' y.']);
            BrillouinHexagon = polyshape(BrillVert(:,1),BrillVert(:,2));
            hold(app.Axes,'on')
            plot(app.Axes,BraggHexagon,'FaceAlpha',0,'EdgeColor','r')
            plot(app.Axes,BrillouinHexagon,'FaceAlpha',0,'EdgeColor','r','LineStyle','--')
        end

        % Button pushed function: PasteButton
        function PasteButtonPushed(app, event)
            Paste = evalin('base', 'Paste'); %Load matrix 'Paste' to paint from base workspace
            
            DistanciaColumnas = app.CallingApp.InfoStruct.DistanciaColumnas;
            DistanciaFilas = app.CallingApp.InfoStruct.DistanciaFilas;
            
            ImagenReal = imagesc(app.Axes,DistanciaColumnas,DistanciaFilas,Paste);
            app.Axes.YDir = 'normal';
            ImagenReal.HitTest = 'Off';
            axis(app.Axes,'square');
            app.Axes.Box = 'On';
%             app.Axes.XLim = Info.XLimReal;
%             app.Axes.YLim = Info.YLimReal;
            app.Axes.Colormap = feval(app.CallingApp.ColormapRealDropDownNew.Value);
        end

        % Value changed function: BilinearinterpCheckBox
        function BilinearinterpCheckBoxValueChanged(app, event)
            app.Interpolation = app.BilinearinterpCheckBox.Value;
            % app.EnergySpinnerValueChanged;
            Image = findobj(app.Axes,'Type','image');
            if app.Interpolation
                Image.Interpolation = 'bilinear';
            else
                Image.Interpolation = 'nearest';
            end
        end

        % Callback function
        function ContrastIndButtonPushed(app, event)
            if app.isReal
                for i=1:length(app.Cell)
                    Centro = mean(mean(app.CallingApp.InfoData.MapasConductancia{i}));
                    app.CallingApp.InfoData.ContrastReal(1,i) = Centro-app.EditContrastIndiv.Value;
                    app.CallingApp.InfoData.ContrastReal(2,i) = Centro+app.EditContrastIndiv.Value;
                end
                app.Axes.CLim = [app.CallingApp.InfoData.ContrastReal(1,app.k) app.CallingApp.InfoData.ContrastReal(2,app.k)];
            else
                for i=1:length(app.Cell)
                    Centro = mean(mean(app.CallingApp.InfoData.Transformadas{i}));
                    app.CallingApp.InfoData.ContrastFFT(1,i) = Centro-app.EditContrastIndiv.Value;
                    app.CallingApp.InfoData.ContrastFFT(2,i) = Centro+app.EditContrastIndiv.Value;
                end
                app.Axes.CLim = [app.CallingApp.InfoData.ContrastFFT(1,app.k) app.CallingApp.InfoData.ContrastFFT(2,app.k)];
            end
        end

        % Button pushed function: CopyasvectorButton
        function CopyasvectorButtonPushed(app, event)
            set(app.Axes,'visible','off')
            copygraphics(app.Axes,ContentType="vector",BackgroundColor="none")
            set(app.Axes,'visible','on')
        end

        % Button pushed function: CopyasbitmapButton
        function CopyasbitmapButtonPushed(app, event)
            set(app.Axes,'visible','off')
            copygraphics(app.Axes,BackgroundColor="none")
            set(app.Axes,'visible','on')
        end

        % Menu selected function: CopyColorbarVecMenu
        function CopyColorbarVecMenuSelected(app, event)
            exportColorbar(colormap(app.Axes))
        end

        % Menu selected function: CopyColorbarVecLimMenu
        function CopyColorbarVecLimMenuSelected(app, event)
            exportColorbar(colormap(app.Axes),...
                UpText=num2str(app.Axes.CLim(2)),DownText=num2str(app.Axes.CLim(1)))
        end

        % Menu selected function: CopyColorbarImgMenu
        function CopyColorbarImgMenuSelected(app, event)
            exportColorbar(colormap(app.Axes),Format='image')
        end

        % Menu selected function: CopyColorbarImgLimMenu
        function CopyColorbarImgLimMenuSelected(app, event)
            exportColorbar(colormap(app.Axes),Format='image',...
                UpText=num2str(app.Axes.CLim(2)),DownText=num2str(app.Axes.CLim(1)))
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            [imgsavefile,imgpath] = uiputfile({'*.png','PNG Image (*.png)';...
                '*.jpg;*.jpeg','Jpeg Image (*.jpg,*.jpeg)';'*.pdf','PDF file (*.pdf)';...
                '*.emf','Enhanced Metafile (Windows Only) (*.emf)';'*.tiff', 'TIFF Image (*.tiff)';...
                '*.*','All Files (*.*)'},'Save Map as image');
            if ~isequal(imgsavefile,0)
                set(app.Axes,'visible','off')
                exportgraphics(app.Axes,[imgpath filesep imgsavefile],Resolution=200,BackgroundColor='none')
                set(app.Axes,'visible','on')
            end
            
            
        end

        % Value changed function: ColorbarCheckBox
        function ColorbarCheckBoxValueChanged(app, event)
            cb_on = app.ColorbarCheckBox.Value;
            cb = colorbar(app.Axes);
            set(cb, 'Limits',[app.MinSlider.Value app.MaxSlider.Value],...
                'YTick', [app.MinSlider.Value app.MaxSlider.Value],'TickLength', 0)
            app.Colorbar = cb;
            
            if ~cb_on
                %set(cb, 'visible', 'off')
                delete(cb)
                app.Colorbar = [];
            end
            
        end

        % Button pushed function: SavematrixButton
        function SavematrixButtonPushed(app, event)
            [datasavefile,datapath] = uiputfile({'*.stp','WSxM Image (*.stp)';...
                '*.dat','Data File (*.dat)';'*.txt','Data File (*.txt)'},...
                'Save Map as data matrix',num2str(app.CallingApp.InfoStructAnalisis.Energia(app.k)));
            %Habria que considerar si es FFT on Real
            if isequal(datasavefile,0)
                return
            end
            [~,~,ext_type] = fileparts(datasavefile);
            fullpath = [datapath filesep datasavefile];
            switch ext_type
                case '.stp'
                    if isfield(app.CallingApp.InfoStructAnalisis,'Type') &&...
                            strcmp(app.CallingApp.InfoStructAnalisis.Type,'Current')
                        units = 'nA';
                    else
                        units = 'a.u.';
                    end
                    if app.isReal
                        saveSTP(fullpath,...
                            app.CallingApp.InfoStructAnalisis.MapasConductancia{app.k},...
                            app.CallingApp.InfoStructAnalisis.TamanhoRealFilas,...
                            app.CallingApp.InfoStructAnalisis.TamanhoRealColumnas,...
                            units,Real = app.isReal)
                    else
                        map = app.CallingApp.InfoStructAnalisis.Transformadas{app.k};
                        saveSTP(fullpath,map,size(map,1)/app.CallingApp.InfoStructAnalisis.TamanhoRealFilas,...
                            size(map,2)/app.CallingApp.InfoStructAnalisis.TamanhoRealColumnas,...
                            units,Real = app.isReal)
                    end
                case {'.dat','.txt'}
                    if app.isReal
                        writematrix(fliplr(app.CallingApp.InfoStructAnalisis.MapasConductancia{app.k}),fullpath)
                    else
                        writematrix(fliplr(app.CallingApp.InfoStructAnalisis.Transformadas{app.k}),fullpath)
                    end
            end
%             ext_type=datasavefile(end-2:end);
%             if app.isReal
%                 switch ext_type
%                     case 'stp'
%                         stpID=fopen([datapath filesep datasavefile],'w');
%                         fprintf(stpID,'WSxM file copyright UAM\r\n');
%                         fprintf(stpID,'SxM Image file\r\n');
%                         fprintf(stpID,'Image header size: 0\r\n');
%                         fprintf(stpID,'[Control]\r\n\r\n');
%                         fprintf(stpID,'    X Amplitude: %.2f nm\r\n',app.CallingApp.InfoStructAnalisis.TamanhoRealColumnas);
%                         fprintf(stpID,'    Y Amplitude: %.2f nm\r\n',app.CallingApp.InfoStructAnalisis.TamanhoRealFilas);
%                         fprintf(stpID,'\r\n[General Info]\r\n\r\n');
%                         fprintf(stpID,'    Image Data Type: double\r\n');
%                         fprintf(stpID,'    Number of rows: %i\r\n    Number of columns: %i\r\n',...
%                             size(app.CallingApp.InfoStructAnalisis.MapasConductancia{app.k}));
%                         fprintf(stpID,'    Z Amplitude: 1\r\n');
%                         fprintf(stpID,'[Miscellaneous]\r\n\r\n');
%                         fprintf(stpID,'Saved with version: blqApp\r\n');
%                         fprintf(stpID,'\r\n[Header end]\r\n');
%                         MapaResize=app.CallingApp.InfoStructAnalisis.MapasConductancia{app.k};
%                         fwrite(stpID,rot90(MapaResize),'double','l');
%                         fclose(stpID);
%                     case {'dat','txt'}
%                         writematrix(fliplr(app.CallingApp.InfoStructAnalisis.MapasConductancia{app.k}),[datapath filesep datasavefile])
%                 end
%             else
%                 switch ext_type
%                     case 'stp'
%                         stpID=fopen([datapath filesep datasavefile],'w');
%                         fprintf(stpID,'WSxM file copyright UAM\r\n');
%                         fprintf(stpID,'SxM Image file\r\n');
%                         fprintf(stpID,'Image header size: 0\r\n');
%                         fprintf(stpID,'[Control]\r\n\r\n');
%                         fprintf(stpID,'    X Amplitude: %.4f 1/nm\r\n',...
%                             size(app.CallingApp.InfoStructAnalisis.Transformadas{app.k},2)/app.CallingApp.InfoStructAnalisis.TamanhoRealColumnas);
%                         fprintf(stpID,'    Y Amplitude: %.4f 1/nm\r\n',...
%                             size(app.CallingApp.InfoStructAnalisis.Transformadas{app.k},1)/app.CallingApp.InfoStructAnalisis.TamanhoRealFilas);
%                         fprintf(stpID,'\r\n[General Info]\r\n\r\n');
%                         fprintf(stpID,'    Image Data Type: double\r\n');
%                         fprintf(stpID,'    Number of rows: %i\r\n    Number of columns: %i\r\n',...
%                             size(app.CallingApp.InfoStructAnalisis.Transformadas{app.k}));
%                         fprintf(stpID,'    Z Amplitude: 1\r\n');
%                         fprintf(stpID,'[Miscellaneous]\r\n\r\n');
%                         fprintf(stpID,'Saved with version: blqApp\r\n');
%                         fprintf(stpID,'\r\n[Header end]\r\n');
%                         MapaResize=app.CallingApp.InfoStructAnalisis.Transformadas{app.k};
%                         fwrite(stpID,rot90(MapaResize),'double','l');
%                         fclose(stpID);
%                     case {'dat','txt'}
%                         writematrix(fliplr(app.CallingApp.InfoStructAnalisis.Transformadas{app.k}),[datapath filesep datasavefile])
%                 end
%             end
        end

        % Button pushed function: ExportAvgAreasButton
        function ExportAvgAreasButtonPushed(app, event)
            areas2Workspace('AvgRectAreas',37290)
        end

        % Button pushed function: LoadAvgAreasButton
        function LoadAvgAreasButtonPushed(app, event)
            %Load back some rectangular areas from a previous analysis
            recoverIVfromrectangles(app.Axes,app.CallingApp.InfoStructAnalisis)
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            app.Threshold = app.ThresholdEditField.Value/100;
        end

        % Button pushed function: AutoContrastButton
        function AutoContrastButtonPushed(app, event)
            %For now we use the data in InfoStructAnalisis as the
            %reference, as they are the changes that we have saved.
            %We can change this to InfoData so the calculation is
            %always refered to what is being shown in the screen
            if app.isReal 
                Contrast = autoContrastCell(app.CallingApp.InfoStructAnalisis.MapasConductancia,app.Threshold);
                app.CallingApp.InfoData.ContrastReal = Contrast;
            else
                Contrast = autoContrastCell(app.CallingApp.InfoStructAnalisis.Transformadas,app.Threshold);
                app.CallingApp.InfoData.ContrastFFT = Contrast;
            end

            %Change the current colormap limits accordingly
            app.MinSlider.Value = Contrast(1,app.k);
            app.MinSliderValueChanged
            app.MaxSlider.Value = Contrast(2,app.k);
            app.MaxSliderValueChanged

        end

        % Value changed function: LogScaleCheckBox
        function LogScaleCheckBoxValueChanged(app, event)
            value = app.LogScaleCheckBox.Value;
            if value
                app.Axes.ColorScale = 'log';
            else
                app.Axes.ColorScale = 'linear';
            end
        end

        % Menu selected function: UTe2011Menu
        function HexagonUTe2011MenuSelected(app, event)
        % Draw the Bragg peaks and Brillouin zone for the UTe2 (011). 
        % a and b form the rectangle that encloses the Bragg peaks

        a = app.HexagonOnOffSpinner.Value ./ ...
            app.CallingApp.InfoStruct.ParametroRedColumnas;
        b = app.HexagonOnOffSpinner.Value ./ ...
            app.CallingApp.InfoStruct.ParametroRedFilas;
        angle = app.AngleEdit.Value;
        trot = rigidtform2d(angle,[0,0]);
        % Bragg peaks position, from UTe2 (011) surface
        u = a.*[1 0 -1 -1 0 1]';
        v = b/2.*[1 2 1 -1 -2 -1]';
        [x,y] = transformPointsForward(trot,u,v);
        % Calculate BZ vertices
        BrillVert = wignerSeitz2D_V2([x y]);

        % Create shapes and plot them on Analysis window
        BraggHexagon = polyshape(x,y);
        BrillouinHexagon = polyshape(BrillVert(:,1),BrillVert(:,2));

        hold(app.Axes,'on')
        plot(app.Axes,BraggHexagon,'FaceAlpha',0,'EdgeColor','r')
        plot(app.Axes,BrillouinHexagon,'FaceAlpha',0,'EdgeColor','r','LineStyle','--')
        end

        % Button pushed function: TopoButton
        function TopoButtonPushed(app, event)
            %Display topography in the current axis in real or reciprocal space
            %NOTE: right now, this function assumes that the topography is the
            %same size than the spectroscopic data.
            if isfield(app.CallingApp.InfoStructAnalisis,'Topo')
                if app.isReal
                    Map = app.CallingApp.InfoData.Topo;
                    XAxis = app.CallingApp.InfoStruct.DistanciaColumnas;
                    YAxis = app.CallingApp.InfoStruct.DistanciaFilas;                 
                else
                    Map = fft2d(app.CallingApp.InfoData.Topo);
                    XAxis = app.CallingApp.InfoStruct.DistanciaFourierColumnas;
                    YAxis = app.CallingApp.InfoStruct.DistanciaFourierFilas;     
                end
                Image = imagesc(app.Axes,XAxis,YAxis,Map);  
                Image.HitTest = 'Off';
                app.Axes.YDir = 'normal';
                app.Axes.DataAspectRatio = [1 1 1];
                app.Axes.CLimMode ="auto";
                app.Axes.Colormap = app.CallingApp.InfoStructAnalisis.Colormap;
            end
        end

        % Menu selected function: SaveRawImageMenu
        function SaveRawImageMenuSelected(app, event)
            %Saves the currently displayed map as is, with no axes, arrows on
            %any other object on top. 
            % Currently, it saves the full size image, independently of the
            % figure current axis
            [imgsavefile,imgpath] = uiputfile({'*.png','PNG Image (*.png)'},'Save Map as image');
            if ~isequal(imgsavefile,0)
                % Image = app.Axes.Children(end);
                Image =findobj(app.Axes.Children,'Type','image');
                if strcmp(Image.Type,'image')
                    exportIndexedPNG(Image.CData,app.Axes.CLim,app.Axes.Colormap,imgpath,imgsavefile)
                end
            end
            
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            modifier = event.Modifier;

            % Save pressed modifier for other purposes in the app
            if ~isempty(modifier)
                app.ModifierPress = modifier;
            end

            % Maybe execute as well UIFIgureKeyPress ? 
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            % Get released keys
            key = event.Key;
            modifier = event.Modifier;

            % Remove the released keys from variable
            app.ModifierPress(strcmp(key,app.ModifierPress)) = [];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Axes = uiaxes(app.UIFigure);
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            colormap(app.Axes, 'parula')
            app.CmapPanel = uipanel(app.UIFigure);
            app.ColorbarCheckBox = uicheckbox(app.CmapPanel);
            app.ColorbarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorbarCheckBoxValueChanged, true);
            app.ColorbarCheckBox.Text = 'Colorbar';
            app.ColormapLabel = uilabel(app.CmapPanel);
            app.ColormapLabel.Enable = 'off';
            app.ColormapLabel.Visible = 'off';
            app.ColormapLabel.Text = 'Colormap:';
            app.ColormapValue = uilabel(app.CmapPanel);
            app.ColormapValue.Enable = 'off';
            app.ColormapValue.Visible = 'off';
            app.ColormapValue.Text = '';
            app.LowerBtnPanel = uipanel(app.UIFigure);
            app.CopyasvectorButton = uibutton(app.LowerBtnPanel, 'push');
            app.CopyasvectorButton.ButtonPushedFcn = createCallbackFcn(app, @CopyasvectorButtonPushed, true);
            app.CopyasvectorButton.Text = 'Copy as vector';
            app.CopyasbitmapButton = uibutton(app.LowerBtnPanel, 'push');
            app.CopyasbitmapButton.ButtonPushedFcn = createCallbackFcn(app, @CopyasbitmapButtonPushed, true);
            app.CopyasbitmapButton.Text = 'Copy as bitmap';
            app.SaveFigureButton = uibutton(app.LowerBtnPanel, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.SavematrixButton = uibutton(app.LowerBtnPanel, 'push');
            app.SavematrixButton.ButtonPushedFcn = createCallbackFcn(app, @SavematrixButtonPushed, true);
            app.SavematrixButton.Text = 'Save matrix';
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.ProfileButton = uibutton(app.UIFigure, 'push');
            app.ProfileButton.ButtonPushedFcn = createCallbackFcn(app, @ProfileButtonPushed, true);
            app.ProfileButton.Text = 'Profile';
            app.LineOnOffButton = uibutton(app.UIFigure, 'push');
            app.LineOnOffButton.ButtonPushedFcn = createCallbackFcn(app, @LineOnOffButtonPushed, true);
            app.LineOnOffButton.Text = 'Line On/Off';
            app.LoadAvgAreasButton = uibutton(app.UIFigure, 'push');
            app.LoadAvgAreasButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAvgAreasButtonPushed, true);
            app.LoadAvgAreasButton.Text = 'Load Avg Areas';
            app.ExportAvgAreasButton = uibutton(app.UIFigure, 'push');
            app.ExportAvgAreasButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAvgAreasButtonPushed, true);
            app.ExportAvgAreasButton.Text = 'Export Avg Areas';
            app.HexagonOnOffSpinner = uispinner(app.UIFigure);
            app.HexagonOnOffSpinner.Step = 0.01;
            app.HexagonOnOffSpinner.LowerLimitInclusive = 'off';
            app.HexagonOnOffSpinner.Limits = [0 Inf];
            app.HexagonOnOffSpinner.Value = 1;
            app.HexagonOnOffButton = uibutton(app.UIFigure, 'push');
            app.HexagonOnOffButton.ButtonPushedFcn = createCallbackFcn(app, @HexagonOnOffButtonPushed, true);
            app.HexagonOnOffButton.Text = 'Hexagon';
            app.SquareOnOffSpinner = uispinner(app.UIFigure);
            app.SquareOnOffSpinner.Step = 0.01;
            app.SquareOnOffSpinner.LowerLimitInclusive = 'off';
            app.SquareOnOffSpinner.Limits = [0 Inf];
            app.SquareOnOffSpinner.Value = 1;
            app.SquareOnOffButton = uibutton(app.UIFigure, 'push');
            app.SquareOnOffButton.ButtonPushedFcn = createCallbackFcn(app, @SquareOnOffButtonPushed, true);
            app.SquareOnOffButton.Text = 'Square';
            app.meVLabel = uilabel(app.UIFigure);
            app.meVLabel.Text = 'meV';
            app.EnergySpinner = uispinner(app.UIFigure);
            app.EnergySpinner.ValueChangedFcn = createCallbackFcn(app, @EnergySpinnerValueChanged, true);
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.UpperLimitInclusive = 'off';
            app.ThresholdEditField.Limits = [0 100];
            app.ThresholdEditField.ValueDisplayFormat = '%11.4g %%';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 0.5;
            app.AutoContrastButton = uibutton(app.UIFigure, 'push');
            app.AutoContrastButton.ButtonPushedFcn = createCallbackFcn(app, @AutoContrastButtonPushed, true);
            app.AutoContrastButton.Tooltip = {'Automatically calculates the contrast for all the maps according to the Threshold bellow. To apply to the current map only, use Ctrl+a instead.'};
            app.AutoContrastButton.Text = 'Auto Contrast';
            app.SavecontrastButton = uibutton(app.UIFigure, 'push');
            app.SavecontrastButton.ButtonPushedFcn = createCallbackFcn(app, @SavecontrastButtonPushed, true);
            app.SavecontrastButton.Text = 'Save contrast';
            app.ContrasttoallButton = uibutton(app.UIFigure, 'push');
            app.ContrasttoallButton.ButtonPushedFcn = createCallbackFcn(app, @ContrasttoallButtonPushed, true);
            app.ContrasttoallButton.Text = 'Contrast to all';
            app.PasteButton = uibutton(app.UIFigure, 'push');
            app.PasteButton.ButtonPushedFcn = createCallbackFcn(app, @PasteButtonPushed, true);
            app.PasteButton.Text = 'Paste';
            app.BilinearinterpCheckBox = uicheckbox(app.UIFigure);
            app.BilinearinterpCheckBox.ValueChangedFcn = createCallbackFcn(app, @BilinearinterpCheckBoxValueChanged, true);
            app.BilinearinterpCheckBox.Text = 'Bilinear interp.';
            app.MaxSlider = uislider(app.UIFigure);
            app.MaxSlider.Limits = [0 1];
            app.MaxSlider.MajorTicks = [];
            app.MaxSlider.MajorTickLabels = {''};
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MaxSliderValueChanged, true);
            app.MaxSlider.MinorTicks = [];
            app.MaxSlider.Value = 1;
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Value = 1;
            app.MinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MinSlider = uislider(app.UIFigure);
            app.MinSlider.Limits = [0 1];
            app.MinSlider.MajorTicks = [];
            app.MinSlider.MajorTickLabels = {''};
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinSliderValueChanged, true);
            app.MinSlider.MinorTicks = [];
            app.HeaderPanel = uipanel(app.UIFigure);
            app.AnalyzeLabel = uilabel(app.HeaderPanel);
            app.AnalyzeLabel.Text = 'Analyze Real';
            app.KeepChangesButton = uibutton(app.HeaderPanel, 'push');
            app.KeepChangesButton.ButtonPushedFcn = createCallbackFcn(app, @KeepChangesButtonPushed, true);
            app.KeepChangesButton.Text = 'Keep Changes';
            app.MousecontrolsCheckBox = uicheckbox(app.HeaderPanel);
            app.MousecontrolsCheckBox.Text = 'Mouse controls';
            app.MousecontrolsCheckBox.Value = true;
            app.TemperatureLabel = uilabel(app.HeaderPanel);
            app.TemperatureLabel.Visible = 'off';
            app.TemperatureLabel.Text = 'T = ';
            app.MagneticFieldLabel = uilabel(app.HeaderPanel);
            app.MagneticFieldLabel.Visible = 'off';
            app.MagneticFieldLabel.Text = 'H = ';
            app.TemperatureValue = uilabel(app.HeaderPanel);
            app.TemperatureValue.Visible = 'off';
            app.TemperatureValue.Text = '';
            app.MagneticFieldValue = uilabel(app.HeaderPanel);
            app.MagneticFieldValue.Visible = 'off';
            app.MagneticFieldValue.Text = '';
            app.InfoImage = uiimage(app.HeaderPanel);
            app.InfoImage.ImageSource = 'icon_info.png';
            app.LogScaleCheckBox = uicheckbox(app.UIFigure);
            app.LogScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogScaleCheckBoxValueChanged, true);
            app.LogScaleCheckBox.Text = 'LogScale';
            app.TopoButton = uibutton(app.UIFigure, 'push');
            app.TopoButton.ButtonPushedFcn = createCallbackFcn(app, @TopoButtonPushed, true);
            app.TopoButton.Text = 'Topo';
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEdit = uieditfield(app.UIFigure, 'numeric');
            app.AngleEdit.LowerLimitInclusive = 'off';
            app.AngleEdit.Limits = [-360 360];
            app.HexagonContextMenu = uicontextmenu(app.UIFigure);
            app.UTe2011Menu = uimenu(app.HexagonContextMenu);
            app.UTe2011Menu.MenuSelectedFcn = createCallbackFcn(app, @HexagonUTe2011MenuSelected, true);
            app.UTe2011Menu.Text = 'UTe2 (011)';
            % Assign app.HexagonContextMenu
            app.HexagonOnOffButton.ContextMenu = app.HexagonContextMenu;
            app.ClipboardVecMenu = uicontextmenu(app.UIFigure);
            app.CopyColorbarVecMenu = uimenu(app.ClipboardVecMenu);
            app.CopyColorbarVecMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyColorbarVecMenuSelected, true);
            app.CopyColorbarVecMenu.Text = 'Copy colorbar alone';
            app.CopyColorbarVecLimMenu = uimenu(app.ClipboardVecMenu);
            app.CopyColorbarVecLimMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyColorbarVecLimMenuSelected, true);
            app.CopyColorbarVecLimMenu.Text = 'Copy colobar with limits';
            % Assign app.ClipboardVecMenu
            app.CopyasvectorButton.ContextMenu = app.ClipboardVecMenu;
            app.ClipboardImgMenu = uicontextmenu(app.UIFigure);
            app.CopyColorbarImgMenu = uimenu(app.ClipboardImgMenu);
            app.CopyColorbarImgMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyColorbarImgMenuSelected, true);
            app.CopyColorbarImgMenu.Text = 'Copy colorbar alone';
            app.CopyColorbarImgLimMenu = uimenu(app.ClipboardImgMenu);
            app.CopyColorbarImgLimMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyColorbarImgLimMenuSelected, true);
            app.CopyColorbarImgLimMenu.Text = 'Copy colobar with limits';
            % Assign app.ClipboardImgMenu
            app.CopyasbitmapButton.ContextMenu = app.ClipboardImgMenu;
            app.SaveImgMenu = uicontextmenu(app.UIFigure);
            app.SaveRawImageMenu = uimenu(app.SaveImgMenu);
            app.SaveRawImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveRawImageMenuSelected, true);
            app.SaveRawImageMenu.Text = 'Save Raw Image';
            % Assign app.SaveImgMenu
            app.SaveFigureButton.ContextMenu = app.SaveImgMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analyze(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end