classdef mapsPreview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mapsPreviewFigure         matlab.ui.Figure
        FFTLockContrastCheckBox   matlab.ui.control.CheckBox
        FFTMaxEdit                matlab.ui.control.NumericEditField
        FFTMaxSlider              matlab.ui.control.Slider
        FFTMinEdit                matlab.ui.control.NumericEditField
        FFTMinSlider              matlab.ui.control.Slider
        FFTProfileButton          matlab.ui.control.Button
        LineOnOffFFTButton        matlab.ui.control.Button
        FFTColormapDropDown       matlab.ui.control.DropDown
        RealLockContrastCheckBox  matlab.ui.control.CheckBox
        RealMaxEdit               matlab.ui.control.NumericEditField
        RealMaxSlider             matlab.ui.control.Slider
        RealMinEdit               matlab.ui.control.NumericEditField
        RealMinSlider             matlab.ui.control.Slider
        RealColormapDropDown      matlab.ui.control.DropDown
        RealProfileButton         matlab.ui.control.Button
        LineOnOffRealButton       matlab.ui.control.Button
        meVLabel                  matlab.ui.control.Label
        ColormapDropDown_2Label   matlab.ui.control.Label
        ColormapDropDownLabel     matlab.ui.control.Label
        EnergySpinner             matlab.ui.control.Spinner
        EnergySpinnerLabel        matlab.ui.control.Label
        HeaderPanel               matlab.ui.container.Panel
        mapsPreviewLabel          matlab.ui.control.Label
        GenerateInfoStructButton  matlab.ui.control.Button
        MousecontrolsCheckBox     matlab.ui.control.CheckBox
        FFTAxes                   matlab.ui.control.UIAxes
        RealAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp % main app object
        k % map index
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, Struct)
            app.CallingApp = mainApp;
            
            app.CallingApp.Struct.ContrastReal = [];
            app.CallingApp.Struct.ContrastFFT = [];
            
            app.RealAxes.Tag = 'RealAxes';
            app.FFTAxes.Tag = 'FFTAxes';
            
            app.k = app.CallingApp.Struct.kInicial;
            app.EnergySpinner.Limits = [app.CallingApp.Struct.Energia(1), app.CallingApp.Struct.Energia(end)];
            app.EnergySpinner.Step = abs(app.CallingApp.Struct.Energia(2) - app.CallingApp.Struct.Energia(1));
            app.EnergySpinner.Value = app.CallingApp.Struct.Energia(app.k);

            switch Struct.Type
                case 'Conductance'
                    app.RealAxes.Title.String = 'Conductance Map';
                case 'Current'
                    app.RealAxes.Title.String = 'Current Map';
            end
            %Fix the slider limits to the clamp values in real space
            MaxCorteReal = app.CallingApp.Struct.MaxCorteConductancia;
            MinCorteReal = app.CallingApp.Struct.MinCorteConductancia;
            if isfinite(MaxCorteReal) || isfinite(MinCorteReal)
                [app.RealMinSlider.Limits,app.RealMaxSlider.Limits] = ...
                    deal([MinCorteReal MaxCorteReal]);
            else % in case the input values were invalid, we fix it to the data values
                NewMin = min(cellfun(@(M) min(M,[],'all'),app.CallingApp.Struct.MapasConductancia));
                NewMax = max(cellfun(@(M) max(M,[],'all'),app.CallingApp.Struct.MapasConductancia));
                [app.RealMinSlider.Limits,app.RealMaxSlider.Limits] = ...
                    deal([NewMin NewMax]);
            end
            
            %For the FFT, we use the absolute max/min values of the maps,
            %ignoring the k=0 datapoint that is always max
            [app.FFTMinSlider.Limits,app.FFTMaxSlider.Limits] = deal([0 ...
                max(cellfun(@(x) max(x(x~=max(x,[],"all")),[],"all"),...
                app.CallingApp.Struct.Transformadas))]);
            app.CallingApp.Struct = showMaps(app, app.CallingApp.Struct, app.k);
        end

        % Value changed function: EnergySpinner
        function EnergySpinnerValueChanged(app, event)
            [~, app.k] = min(abs(app.CallingApp.Struct.Energia - app.EnergySpinner.Value));
            app.EnergySpinner.Value = app.CallingApp.Struct.Energia(app.k);
            app.CallingApp.Struct = showMaps(app, app.CallingApp.Struct, app.k);
        end

        % Value changed function: RealMinSlider
        function RealMinSliderValueChanged(app, event)
            if app.RealMinSlider.Value > app.RealMaxSlider.Value
                app.RealMinSlider.Value = app.RealMaxSlider.Value-eps;
            end
            app.RealMinEdit.Value = app.RealMinSlider.Value;
            app.RealAxes.CLim(1) = app.RealMinSlider.Value;
        end

        % Value changed function: RealMaxSlider
        function RealMaxSliderValueChanged(app, event)
            if app.RealMaxSlider.Value < app.RealMinSlider.Value
                app.RealMaxSlider.Value = app.RealMinSlider.Value+eps;
            end
            app.RealMaxEdit.Value = app.RealMaxSlider.Value;
            app.RealAxes.CLim(2) = app.RealMaxSlider.Value;
        end

        % Value changed function: FFTMinSlider
        function FFTMinSliderValueChanged(app, event)
            if app.FFTMinSlider.Value > app.FFTMaxSlider.Value
                app.FFTMinSlider.Value = app.FFTMaxSlider.Value;
            end
            app.FFTMinEdit.Value = app.FFTMinSlider.Value;
            app.FFTAxes.CLim(1) = app.FFTMinSlider.Value;
        end

        % Value changed function: FFTMaxSlider
        function FFTMaxSliderValueChanged(app, event)
            if app.FFTMaxSlider.Value < app.FFTMinSlider.Value
                app.FFTMaxSlider.Value = app.FFTMinSlider.Value;
            end
            app.FFTMaxEdit.Value = app.FFTMaxSlider.Value;
            app.FFTAxes.CLim(2) = app.FFTMaxSlider.Value;
        end

        % Value changed function: RealColormapDropDown
        function RealColormapDropDownValueChanged(app, event)
            value = app.RealColormapDropDown.Value;
            app.RealAxes.Colormap = eval(value);
        end

        % Value changed function: FFTColormapDropDown
        function FFTColormapDropDownValueChanged(app, event)
            value = app.FFTColormapDropDown.Value;
            app.FFTAxes.Colormap = eval(value);
        end

        % Button pushed function: GenerateInfoStructButton
        function GenerateInfoStructButtonPushed(app, event)
            generateInfo(app, app.CallingApp.Struct)
            app.CallingApp.InfoStructOriginal = app.CallingApp.InfoStruct;
        end

        % Window button down function: mapsPreviewFigure
        function mapsPreviewFigureWindowButtonDown(app, event)
            if app.MousecontrolsCheckBox.Value
                Down_v2(app.mapsPreviewFigure)
            end
        end

        % Window button motion function: mapsPreviewFigure
        function mapsPreviewFigureWindowButtonMotion(app, event)
            if app.MousecontrolsCheckBox.Value
                CurrentPoint_v2(app.mapsPreviewFigure)
            end
        end

        % Window button up function: mapsPreviewFigure
        function mapsPreviewFigureWindowButtonUp(app, event)
            if app.MousecontrolsCheckBox.Value
                checkMapChange = app.EnergySpinner.Value;
                mapsClick(app, app.CallingApp.Struct)
            end

            if app.EnergySpinner.Value ~= checkMapChange
                app.EnergySpinnerValueChanged
            end
        end

        % Window scroll wheel function: mapsPreviewFigure
        function mapsPreviewFigureWindowScrollWheel(app, event)
%             verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            if verticalScrollCount < 0
                if app.EnergySpinner.Value ~= app.EnergySpinner.Limits(2)
                    app.EnergySpinner.Value = app.EnergySpinner.Value + (-1*verticalScrollCount)*app.EnergySpinner.Step;
                    app.EnergySpinnerValueChanged
                end
            else
                if app.EnergySpinner.Value ~= app.EnergySpinner.Limits(1)
                    app.EnergySpinner.Value = app.EnergySpinner.Value + (-1*verticalScrollCount)*app.EnergySpinner.Step;
                    app.EnergySpinnerValueChanged
                end
            end            
        end

        % Button pushed function: FFTProfileButton
        function FFTProfileButtonPushed(app, event)
            profileFFT(app.FFTAxes, app.CallingApp.Struct, app.k);
        end

        % Button pushed function: RealProfileButton
        function RealProfileButtonPushed(app, event)
            profileReal(app.RealAxes, app.CallingApp.Struct, app.k)
        end

        % Button pushed function: LineOnOffRealButton
        function LineOnOffRealButtonPushed(app, event)
            LX = (app.RealAxes.XLim(2)-app.RealAxes.XLim(1))/2;
            LY = (app.RealAxes.YLim(2)-app.RealAxes.YLim(1))/2;
            Xinicio = app.RealAxes.XLim(1) + LX;
            Yinicio = app.RealAxes.YLim(1) + LY;
            Xfinal = Xinicio + LX/4;
            Yfinal = Yinicio + LY/4;
            if strcmp(app.RealAxes.Children(1).Tag, 'lineProfile')
                app.RealAxes.UserData.lineProfilePosition = app.RealAxes.Children(1).Position;
                delete(app.RealAxes.Children(1))
            else
                if isfield(app.RealAxes.UserData, 'lineProfilePosition')
                    drawline(app.RealAxes,'Position', app.RealAxes.UserData.lineProfilePosition,'Tag', 'lineProfile','Color','red');
                else
                    drawline(app.RealAxes,'Position', [Xinicio Yinicio; Xfinal Yfinal],'Tag', 'lineProfile','Color','red');
                end               
            end
        end

        % Button pushed function: LineOnOffFFTButton
        function LineOnOffFFTButtonPushed(app, event)
            LX = (app.FFTAxes.XLim(2)-app.FFTAxes.XLim(1))/2;
            LY = (app.FFTAxes.YLim(2)-app.FFTAxes.YLim(1))/2;
            Xinicio = app.FFTAxes.XLim(1) + LX;
            Yinicio = app.FFTAxes.YLim(1) + LY;
            Xfinal = Xinicio + LX/4;
            Yfinal = Yinicio + LY/4;
            if strcmp(app.FFTAxes.Children(1).Tag, 'lineProfile')
                app.FFTAxes.UserData.lineProfilePosition = app.FFTAxes.Children(1).Position;
                delete(app.FFTAxes.Children(1))
            else
                if isfield(app.FFTAxes.UserData, 'lineProfilePosition')
                    drawline(app.FFTAxes,'Position', app.FFTAxes.UserData.lineProfilePosition,'Tag', 'lineProfile');
                else
                    drawline(app.FFTAxes,'Position', [Xinicio Yinicio; Xfinal Yfinal],'Tag', 'lineProfile');
                end               
            end
        end

        % Value changed function: RealMaxEdit
        function RealMaxEditValueChanged(app, event)
            value = app.RealMaxEdit.Value;
            app.RealMaxSlider.Value = value;
            app.RealMaxSliderValueChanged
        end

        % Value changed function: RealMinEdit
        function RealMinEditValueChanged(app, event)
            value = app.RealMinEdit.Value;
            app.RealMinSlider.Value = value;
            app.RealMinSliderValueChanged
        end

        % Value changed function: FFTMinEdit
        function FFTMinEditValueChanged(app, event)
            value = app.FFTMinEdit.Value;
            app.FFTMinSlider.Value = value;
            app.FFTMinSliderValueChanged
        end

        % Value changed function: FFTMaxEdit
        function FFTMaxEditValueChanged(app, event)
            value = app.FFTMaxEdit.Value;
            app.FFTMaxSlider.Value = value;
            app.FFTMaxSliderValueChanged
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mapsPreviewFigure = uifigure('Visible', 'off');
            app.mapsPreviewFigure.Name = 'UI Figure';
            app.mapsPreviewFigure.WindowButtonDownFcn = createCallbackFcn(app, @mapsPreviewFigureWindowButtonDown, true);
            app.mapsPreviewFigure.WindowButtonUpFcn = createCallbackFcn(app, @mapsPreviewFigureWindowButtonUp, true);
            app.mapsPreviewFigure.WindowButtonMotionFcn = createCallbackFcn(app, @mapsPreviewFigureWindowButtonMotion, true);
            app.mapsPreviewFigure.WindowScrollWheelFcn = createCallbackFcn(app, @mapsPreviewFigureWindowScrollWheel, true);
            app.RealAxes = uiaxes(app.mapsPreviewFigure);
            title(app.RealAxes, 'Conductance map')
            xlabel(app.RealAxes, 'Distance (nm)')
            ylabel(app.RealAxes, 'Distance (nm)')
            colormap(app.RealAxes, 'parula')
            app.FFTAxes = uiaxes(app.mapsPreviewFigure);
            title(app.FFTAxes, 'FFT')
            xlabel(app.FFTAxes, 'k (nm^{-1})')
            ylabel(app.FFTAxes, 'k (nm^{-1})')
            colormap(app.FFTAxes, 'parula')
            app.HeaderPanel = uipanel(app.mapsPreviewFigure);
            app.MousecontrolsCheckBox = uicheckbox(app.HeaderPanel);
            app.MousecontrolsCheckBox.Text = 'Mouse controls';
            app.MousecontrolsCheckBox.Value = true;
            app.GenerateInfoStructButton = uibutton(app.HeaderPanel, 'push');
            app.GenerateInfoStructButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateInfoStructButtonPushed, true);
            app.GenerateInfoStructButton.Text = 'Generate InfoStruct';
            app.mapsPreviewLabel = uilabel(app.HeaderPanel);
            app.mapsPreviewLabel.Text = 'mapsPreview';
            app.EnergySpinnerLabel = uilabel(app.mapsPreviewFigure);
            app.EnergySpinnerLabel.Text = 'Energy';
            app.EnergySpinner = uispinner(app.mapsPreviewFigure);
            app.EnergySpinner.ValueChangedFcn = createCallbackFcn(app, @EnergySpinnerValueChanged, true);
            app.ColormapDropDownLabel = uilabel(app.mapsPreviewFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown_2Label = uilabel(app.mapsPreviewFigure);
            app.ColormapDropDown_2Label.Text = 'Colormap';
            app.meVLabel = uilabel(app.mapsPreviewFigure);
            app.meVLabel.Text = 'meV';
            app.LineOnOffRealButton = uibutton(app.mapsPreviewFigure, 'push');
            app.LineOnOffRealButton.ButtonPushedFcn = createCallbackFcn(app, @LineOnOffRealButtonPushed, true);
            app.LineOnOffRealButton.Text = 'Line On/Off';
            app.RealProfileButton = uibutton(app.mapsPreviewFigure, 'push');
            app.RealProfileButton.ButtonPushedFcn = createCallbackFcn(app, @RealProfileButtonPushed, true);
            app.RealProfileButton.Text = 'Profile';
            app.RealColormapDropDown = uidropdown(app.mapsPreviewFigure);
            app.RealColormapDropDown.Items = {'parula', 'viridis', 'plasma', 'magma', 'inferno', 'bone', 'gray'};
            app.RealColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @RealColormapDropDownValueChanged, true);
            app.RealColormapDropDown.Value = 'viridis';
            app.RealMinSlider = uislider(app.mapsPreviewFigure);
            app.RealMinSlider.MajorTicks = [];
            app.RealMinSlider.MajorTickLabels = {''};
            app.RealMinSlider.ValueChangedFcn = createCallbackFcn(app, @RealMinSliderValueChanged, true);
            app.RealMinSlider.MinorTicks = [];
            app.RealMinEdit = uieditfield(app.mapsPreviewFigure, 'numeric');
            app.RealMinEdit.ValueChangedFcn = createCallbackFcn(app, @RealMinEditValueChanged, true);
            app.RealMaxSlider = uislider(app.mapsPreviewFigure);
            app.RealMaxSlider.MajorTicks = [];
            app.RealMaxSlider.MajorTickLabels = {''};
            app.RealMaxSlider.ValueChangedFcn = createCallbackFcn(app, @RealMaxSliderValueChanged, true);
            app.RealMaxSlider.MinorTicks = [];
            app.RealMaxSlider.Value = 100;
            app.RealMaxEdit = uieditfield(app.mapsPreviewFigure, 'numeric');
            app.RealMaxEdit.ValueChangedFcn = createCallbackFcn(app, @RealMaxEditValueChanged, true);
            app.RealLockContrastCheckBox = uicheckbox(app.mapsPreviewFigure);
            app.RealLockContrastCheckBox.Text = 'Lock contrast';
            app.FFTColormapDropDown = uidropdown(app.mapsPreviewFigure);
            app.FFTColormapDropDown.Items = {'parula', 'viridis', 'plasma', 'magma', 'inferno', 'bone', 'gray'};
            app.FFTColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @FFTColormapDropDownValueChanged, true);
            app.FFTColormapDropDown.Value = 'viridis';
            app.LineOnOffFFTButton = uibutton(app.mapsPreviewFigure, 'push');
            app.LineOnOffFFTButton.ButtonPushedFcn = createCallbackFcn(app, @LineOnOffFFTButtonPushed, true);
            app.LineOnOffFFTButton.Text = 'Line On/Off';
            app.FFTProfileButton = uibutton(app.mapsPreviewFigure, 'push');
            app.FFTProfileButton.ButtonPushedFcn = createCallbackFcn(app, @FFTProfileButtonPushed, true);
            app.FFTProfileButton.Text = 'Profile';
            app.FFTMinSlider = uislider(app.mapsPreviewFigure);
            app.FFTMinSlider.MajorTicks = [];
            app.FFTMinSlider.MajorTickLabels = {''};
            app.FFTMinSlider.ValueChangedFcn = createCallbackFcn(app, @FFTMinSliderValueChanged, true);
            app.FFTMinSlider.MinorTicks = [];
            app.FFTMinEdit = uieditfield(app.mapsPreviewFigure, 'numeric');
            app.FFTMinEdit.ValueChangedFcn = createCallbackFcn(app, @FFTMinEditValueChanged, true);
            app.FFTMaxSlider = uislider(app.mapsPreviewFigure);
            app.FFTMaxSlider.MajorTicks = [];
            app.FFTMaxSlider.MajorTickLabels = {''};
            app.FFTMaxSlider.ValueChangedFcn = createCallbackFcn(app, @FFTMaxSliderValueChanged, true);
            app.FFTMaxSlider.MinorTicks = [];
            app.FFTMaxSlider.Value = 100;
            app.FFTMaxEdit = uieditfield(app.mapsPreviewFigure, 'numeric');
            app.FFTMaxEdit.ValueChangedFcn = createCallbackFcn(app, @FFTMaxEditValueChanged, true);
            app.FFTLockContrastCheckBox = uicheckbox(app.mapsPreviewFigure);
            app.FFTLockContrastCheckBox.Text = 'Lock contrast';
            % Show the figure after all components are created
            app.mapsPreviewFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mapsPreview(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mapsPreviewFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mapsPreviewFigure)
        end
    end
end