classdef EnergySym < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnergySymmetryUIFigure       matlab.ui.Figure
        MinEditField_2Label          matlab.ui.control.Label
        OperationButtonGroup         matlab.ui.container.ButtonGroup
        DifferenceButton             matlab.ui.control.RadioButton
        DivisionButton               matlab.ui.control.RadioButton
        BogolAngleButton             matlab.ui.control.RadioButton
        MinDiffEditField             matlab.ui.control.NumericEditField
        MaxDiffEditField             matlab.ui.control.NumericEditField
        MinDiffSlider                matlab.ui.control.Slider
        MaxEditField_2Label          matlab.ui.control.Label
        MaxDiffSlider                matlab.ui.control.Slider
        ReverseColormapCheckBox      matlab.ui.control.CheckBox
        ColormapDropDown             matlab.ui.control.DropDown
        y2EditField                  matlab.ui.control.NumericEditField
        y2EditFieldLabel             matlab.ui.control.Label
        y1EditField                  matlab.ui.control.NumericEditField
        y1EditFieldLabel             matlab.ui.control.Label
        x2EditField                  matlab.ui.control.NumericEditField
        x2EditFieldLabel             matlab.ui.control.Label
        x1EditField                  matlab.ui.control.NumericEditField
        x1EditFieldLabel             matlab.ui.control.Label
        ColorbarCheckBox             matlab.ui.control.CheckBox
        SombreritoPanel              matlab.ui.container.Panel
        YnmLabel                     matlab.ui.control.Label
        XnmLabel                     matlab.ui.control.Label
        AmplitudeEditField           matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel      matlab.ui.control.Label
        PositionLabel                matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        SigmaEditField               matlab.ui.control.NumericEditField
        SigmaEditFieldLabel          matlab.ui.control.Label
        OKButton                     matlab.ui.control.Button
        MagneticFieldValue           matlab.ui.control.Label
        MinSlider                    matlab.ui.control.Slider
        MaxSlider                    matlab.ui.control.Slider
        TemperatureValue             matlab.ui.control.Label
        MagneticFieldLabel           matlab.ui.control.Label
        TemperatureLabel             matlab.ui.control.Label
        VideoButton                  matlab.ui.control.Button
        ContrasttoallButton          matlab.ui.control.Button
        SavecontrastButton           matlab.ui.control.Button
        meVLabel                     matlab.ui.control.Label
        EnergySpinner                matlab.ui.control.Spinner
        KeepChangesButton            matlab.ui.control.Button
        EnablemousecontrolsCheckBox  matlab.ui.control.CheckBox
        EnergySymmetryLabel          matlab.ui.control.Label
        MinEditField                 matlab.ui.control.NumericEditField
        MinEditFieldLabel            matlab.ui.control.Label
        MaxEditField                 matlab.ui.control.NumericEditField
        MaxEditFieldLabel            matlab.ui.control.Label
        RealAxes                     matlab.ui.control.UIAxes
        ResultAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp % main App object

        Resta % Result maps with substracted conductance maps
        ReverseColormap % Logical that controls the flip of the colormap
        Colorbar % Colorbar of the images
        ColorbarRestas % Colorbar of Difference Map
        k % Index in the energy array/Current energy map
        kResta = 1; % Index in the Restas array/Restas energy map
        EnergyOffset = 0;
        CurrentAxes % Axis tag where the user clicks
        Axes        % Last clicked axes
        OperationButton   % Operation displayed in result Axes

% Minimum and maximun values of matrices
        maxResta
        minResta
        maxDivision
        minDivision
        maxAngle
        minAngle
    end
    
    methods (Access = public)
             
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;

            % Initialize the UI from scratch
            %figure(app.EnergySymmetryUIFigure);

            % If the file contains Struct with useful information, it is displayed
            if isfield(app.CallingApp.Struct,'Campo')
                app.MagneticFieldLabel.Visible = true;
                app.MagneticFieldValue.Visible = true;
                app.MagneticFieldValue.Text = [num2str(app.CallingApp.Struct.Campo), ' T'];
            else
                app.MagneticFieldLabel.Visible = false;
                app.MagneticFieldValue.Visible = false;
            end
            if isfield(app.CallingApp.Struct,'Temperatura')
                app.TemperatureLabel.Visible = true;
                app.TemperatureValue.Visible = true;
                app.TemperatureValue.Text = [num2str(app.CallingApp.Struct.Temperatura), ' K'];
            else
                app.TemperatureLabel.Visible = false;
                app.TemperatureValue.Visible = false;
            end

            % If the file does not contain Restas..., add them.
            if ~all(isfield(app.CallingApp.InfoStruct,...
              {'Restas','RestasFFT','ContrastRestasReal','ContrastRestasFFT',...
              'Division','DivisionFFT','ConstrastDivisionReal','ContrastDivisionFFT'}))
                 
                app.CallingApp.InfoStruct = energyDiff(app, app.CallingApp.InfoStruct, app.EnergyOffset);
                app.CallingApp.InfoData = app.CallingApp.InfoStruct;
                app.CallingApp.InfoStructAnalisis = app.CallingApp.InfoStruct;
            end
            % Initialize Operation with selected one in blqApp  
            % Save selected option with index for future uses
            app.OperationButton = find(app.CallingApp.OperationButtonGroup.SelectedObject...
                == app.CallingApp.OperationButtonGroup.Children);
            app.OperationButtonGroup.SelectedObject = app.OperationButtonGroup.Children(app.OperationButton);

            % Initialize EnergySpinner
            [~, app.k] = min(abs(app.CallingApp.InfoStruct.Energia));
            app.EnergySpinner.Limits = [app.CallingApp.InfoStruct.Energia(1), app.CallingApp.InfoStruct.Energia(end)];
            app.EnergySpinner.Step = abs(app.CallingApp.InfoStruct.Energia(2) - app.CallingApp.InfoStruct.Energia(1));
            app.EnergySpinner.Value = app.CallingApp.InfoStruct.Energia(app.k);

            % Create plots. (First one from InfoStruct)
            initCell(app, app.CallingApp.InfoStruct.MapasConductancia,app.CallingApp.InfoStruct.ContrastReal, true)
            [app.CallingApp.InfoData] = showCell(app, app.CallingApp.InfoStruct.MapasConductancia, app.CallingApp.InfoStruct, app.k, app.RealAxes);
            [app.CallingApp.InfoData] = showCell(app, app.CallingApp.InfoData.Restas, app.CallingApp.InfoData, app.kResta, app.ResultAxes);
            [app.RealAxes.Colormap,...
             app.ResultAxes.Colormap] = deal(app.CallingApp.InfoStruct.Colormap);
            % Transfer data to all InfoStructs
            app.CallingApp.InfoStruct = app.CallingApp.InfoData;
            app.CallingApp.InfoStructAnalisis = app.CallingApp.InfoData;
            app.CallingApp.InfoStructAnalisisCopy = app.CallingApp.InfoStructAnalisis;

            % Correct Restas Limits in contrast
            app.maxResta = max(cellfun(@(x) max(x,[],"all"),...
                app.CallingApp.InfoData.Restas));
            app.maxDivision = max(cellfun(@(x) max(x,[],"all"),...
                app.CallingApp.InfoData.Division));
            app.maxAngle = max(cellfun(@(x) max(x,[],"all"),...
                app.CallingApp.InfoData.BogolAngle));
            app.minResta = min(cellfun(@(x) min(x,[],"all"),...
                app.CallingApp.InfoData.Restas));
            app.minDivision = min(cellfun(@(x) min(x,[],"all"),...
                app.CallingApp.InfoData.Division));
            app.minAngle = min(cellfun(@(x) min(x,[],"all"),...
                app.CallingApp.InfoData.BogolAngle));

            % Use as Contrast Limits the extremes of current operation
            switch app.OperationButton
                case 1 %Difference
                    [app.MinDiffSlider.Limits, app.MaxDiffSlider.Limits]=deal([app.minResta, app.maxResta]);
                case 2 % Division
                    try % Just in case there is a division by 0
                        [app.MinDiffSlider.Limits, app.MaxDiffSlider.Limits]=deal([app.minDivision, app.maxDivision]);
                    catch ME
                        app.maxDivision = 100;
                        rethrow(ME);
                    end
                case 3 % Bogoliubov Angle
                    [app.MinDiffSlider.Limits, app.MaxDiffSlider.Limits]=deal([app.minAngle, app.maxAngle]);
            end
            % Assign values
            [app.MinDiffSlider.Value, app.MinDiffEditField.Value] = ...
                deal(app.MinDiffSlider.Limits(1));
            [app.MaxDiffSlider.Value, app.MaxDiffEditField.Value] = ...
                deal(app.MaxDiffSlider.Limits(2)); 
            
            app.MaxDiffSliderValueChanged;
            app.MinDiffSliderValueChanged;
        end

        % Value changed function: ColorbarCheckBox
        function ColorbarCheckBoxValueChanged(app, event)
            cb_on = app.ColorbarCheckBox.Value;
            cb = colorbar(app.RealAxes);
            valueMin = app.MinDiffSlider.Value;
            valueMax = app.MaxDiffSlider.Value;

            set(cb(1), 'Limits',[app.MinSlider.Value app.MaxSlider.Value],...
                'YTick', [app.MinSlider.Value app.MaxSlider.Value],'TickLength', 0)
            app.Colorbar = cb(1);

            %if app.enableResultControl
                cb(2) = colorbar(app.ResultAxes);
                set(cb(2), 'Limits',[app.MinDiffSlider.Value app.MaxDiffSlider.Value],...
                'YTick', [app.MinDiffSlider.Value app.MaxDiffSlider.Value],'TickLength', 0)
                app.ColorbarRestas = cb(2);
            %end
            
            if ~cb_on
                delete([cb])
                app.Colorbar = [];
                app.ColorbarRestas = [];
            end
        end

        % Value changed function: MinSlider
        function MinSliderValueChanged(app, event)
            if app.MinSlider.Value >= app.MaxSlider.Value
                app.MinSlider.Value = app.MaxSlider.Value-eps;
            end
            app.MinEditField.Value = app.MinSlider.Value;
            app.RealAxes.CLim(1) = app.MinSlider.Value;
            %app.ResultAxes.CLim(1) = app.MinSlider.Value; YA NO LO QUIERO
            if app.ColorbarCheckBox.Value
                set(app.Colorbar, 'Limits',[app.MinSlider.Value app.MaxSlider.Value],...
                'YTick', [app.MinSlider.Value app.MaxSlider.Value],'TickLength', 0)
            end
            
        end

        % Value changed function: MaxSlider
        function MaxSliderValueChanged(app, event)
            if app.MaxSlider.Value <= app.MinSlider.Value
                app.MaxSlider.Value = app.MinSlider.Value+eps;
            end
            app.MaxEditField.Value = app.MaxSlider.Value;
            app.RealAxes.CLim(2) = app.MaxSlider.Value;
            %app.ResultAxes.CLim(2) = app.MaxSlider.Value; YA NO LO QUIERO
            if app.ColorbarCheckBox.Value
                set(app.Colorbar, 'Limits',[app.MinSlider.Value app.MaxSlider.Value],...
                'YTick', [app.MinSlider.Value app.MaxSlider.Value],'TickLength', 0)
            end
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            value = app.MinEditField.Value;
            app.MinSlider.Value = value;
            app.MinSliderValueChanged;
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            value = app.MaxEditField.Value;
            app.MaxSlider.Value = value;
            app.MaxSliderValueChanged;
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            % Obtain flipped colormap matrix or the usual one
            if app.ReverseColormap 
                colormap = flipud(eval(value));
            else
                colormap = eval(value);
            end    
            % Assign the colormap to all these fields.
%             [app.RealAxes.Colormap,...
             [app.ResultAxes.Colormap] = deal(colormap);
%              app.CallingApp.InfoData.Colormap,...
%              app.CallingApp.InfoStruct.Colormap,...
%              app.CallingApp.InfoStructAnalisis.Colormap,...
%              app.CallingApp.InfoStructAnalisisCopy.Colormap] ...
%              = deal(colormap);
        end

        % Value changed function: ReverseColormapCheckBox
        function ReverseColormapCheckBoxValueChanged(app, event)
            value = app.ReverseColormapCheckBox.Value;
            app.ReverseColormap = value;
            app.ColormapDropDownValueChanged;
        end

        % Value changed function: EnergySpinner
        function EnergySpinnerValueChanged(app, event)
            [~, app.k] = min(abs(app.CallingApp.InfoStruct.Energia - app.EnergySpinner.Value));
            app.EnergySpinner.Value = app.CallingApp.InfoStruct.Energia(app.k);
            % We change index in Difference Map accordingly
            app.kResta = abs(app.k - ceil(0.5*length(app.CallingApp.InfoStruct.Energia))) + 1;

            [app.CallingApp.InfoData] = showCell(app, app.CallingApp.InfoData.MapasConductancia, app.CallingApp.InfoData, app.k, app.RealAxes);
            
            % Plot Map according to selected operation
            switch app.OperationButton
                case 1 % Difference
                    [app.CallingApp.InfoData] = showCell(app, app.CallingApp.InfoData.Restas, app.CallingApp.InfoData, app.kResta, app.ResultAxes);
                case 2  % Division
                    [app.CallingApp.InfoData] = showCell(app, app.CallingApp.InfoData.Division, app.CallingApp.InfoData, app.kResta, app.ResultAxes);
                case 3  % Bogoliubov Angle
                    [app.CallingApp.InfoData] = showCell(app, app.CallingApp.InfoData.BogolAngle, app.CallingApp.InfoData, app.kResta, app.ResultAxes);
            end
                %[app.CallingApp.InfoStruct] = showVortexMap(app,  app.CallingApp.InfoStruct, app.CallingApp.InfoStruct.Restas,app.kResta, app.ResultAxes);

        end

        % Window scroll wheel function: EnergySymmetryUIFigure
        function EnergySymmetryUIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            if verticalScrollCount < 0
                if app.EnergySpinner.Value ~= app.EnergySpinner.Limits(2)
                    app.EnergySpinner.Value = app.EnergySpinner.Value + (-1*verticalScrollCount)*app.EnergySpinner.Step;
                    app.EnergySpinnerValueChanged;
                end
            else
                if app.EnergySpinner.Value ~= app.EnergySpinner.Limits(1)
                    app.EnergySpinner.Value = app.EnergySpinner.Value + (-1*verticalScrollCount)*app.EnergySpinner.Step;
                    app.EnergySpinnerValueChanged;
                end
            end
        end

        % Window button down function: EnergySymmetryUIFigure
        function EnergySymmetryUIFigureWindowButtonDown(app, event)
              if app.EnablemousecontrolsCheckBox.Value
% 
%                  % Determine in which/if axes you have clicked
                 [~,In] = chooseAxes_v2(app.EnergySymmetryUIFigure);

                % If inside any axes, store their tag in CurrentAxes
                 Down_v2(app.EnergySymmetryUIFigure);

                 if isfield(app.EnergySymmetryUIFigure.UserData,'Axes') && In
                    app.CurrentAxes = app.EnergySymmetryUIFigure.UserData.Axes.Tag;
                    app.Axes = app.EnergySymmetryUIFigure.UserData.Axes;
                 end
             end
        end

        % Window button up function: EnergySymmetryUIFigure
        function EnergySymmetryUIFigureWindowButtonUp(app, event)
             if app.EnablemousecontrolsCheckBox.Value
                 checkMapChange = app.EnergySpinner.Value;
                 mapsClickVortexSym(app, app.CallingApp.InfoData);
%                


%           Synchronize change in both axes after changing any of them
%           Also save new limits in InfoData
            if ~isempty(app.Axes) && ~isequal([app.Axes.UserData.XLimDown;app.Axes.UserData.YLimDown],...
                [app.Axes.XLim;app.Axes.YLim])
                
                 switch app.CurrentAxes
                    case 'RealAxes'
                        app.ResultAxes.XLim = app.RealAxes.XLim;
                        app.ResultAxes.YLim = app.RealAxes.YLim;

                        app.CallingApp.InfoData.XLimReal = app.RealAxes.XLim;
                        app.CallingApp.InfoData.YLimReal = app.RealAxes.YLim;
     

                    case 'DiffAxes'
                        app.RealAxes.XLim = app.ResultAxes.XLim;
                        app.RealAxes.YLim = app.ResultAxes.YLim;

                        app.CallingApp.InfoData.XLimReal = app.ResultAxes.XLim;
                        app.CallingApp.InfoData.YLimReal = app.ResultAxes.YLim;
                 end
            end
%           Remove selected axes when releasing button               
                app.CurrentAxes = '';
                      
%          % Change energy to 0mV if not already there.
                 if app.EnergySpinner.Value ~= checkMapChange
                     app.EnergySpinnerValueChanged;
                 end
             end
        end

        % Window button motion function: EnergySymmetryUIFigure
        function EnergySymmetryUIFigureWindowButtonMotion(app, event)
             if app.EnablemousecontrolsCheckBox.Value
                 CurrentPoint_v2(app.EnergySymmetryUIFigure);
             end
        end

        % Key press function: EnergySymmetryUIFigure
        function EnergySymmetryUIFigureKeyPress(app, event)
            key = event.Key;
            modifier = event.Modifier;
            if strcmp(key,'t')
                disp(app.ResultAxes.UserData)
            end
              if strcmp(key,'r')
                disp(app.RealAxes.UserData)
            end
            % Copy as vector Result axes
            if ismember('control', modifier) && strcmp(key, 'c')
                set(app.Axes,'visible','off')
                copygraphics(app.Axes,"ContentType","vector")
                set(app.Axes,'visible','on')
            end
        end

        % Button pushed function: KeepChangesButton
        function KeepChangesButtonPushed(app, event)
            % Saves changes in Restas Map to InfoStructAnalisis       

            app.CallingApp.InfoStructAnalisis = app.CallingApp.InfoData;
            app.CallingApp.InfoStructAnalisis.Restas = app.CallingApp.InfoStructAnalisisCopy.Restas;
            app.CallingApp.InfoStructAnalisis.RestasFFT = app.CallingApp.InfoStructAnalisisCopy.RestasFFT;
            app.CallingApp.InfoStructAnalisisCopy = app.CallingApp.InfoStructAnalisis;
        end

        % Value changed function: MaxDiffSlider
        function MaxDiffSliderValueChanged(app, event)
            valueMax = app.MaxDiffSlider.Value;
            valueMin = app.MinDiffSlider.Value;

            % Check that limits are sorted
            if valueMax < valueMin
                valueMax = valueMin + eps;
                app.MaxDiffSlider.Value = valueMax;
            end
            % Place new contrast value everywhere
            app.MaxDiffEditField.Value = valueMax;
            app.ResultAxes.CLim(2) = valueMax;
            if app.ColorbarCheckBox.Value
                set(app.ColorbarRestas, 'Limits',[valueMin, valueMax],...
                'YTick', [valueMin, valueMax],'TickLength', 0)
            end

            
        end

        % Value changed function: MinDiffSlider
        function MinDiffSliderValueChanged(app, event)
            valueMin = app.MinDiffSlider.Value;
            valueMax = app.MaxDiffSlider.Value;
% Check that limits are sorted
            if valueMin > valueMax
                valueMin = valueMax - eps;
                app.MinDiffSlider.Value = valueMin;
             
            end
            % Place new contrast value everywhere
            app.MinDiffEditField.Value = valueMin;
            app.ResultAxes.CLim(1) = valueMin;

            if app.ColorbarCheckBox.Value
                set(app.ColorbarRestas, 'Limits',[valueMin valueMax],...
                'YTick', [valueMin valueMax],'TickLength', 0)
            end
        end

        % Value changed function: MaxDiffEditField
        function MaxDiffEditFieldValueChanged(app, event)
            value = app.MaxDiffEditField.Value;
            app.MaxDiffSlider.Value = value;
            app.MaxDiffSliderValueChanged;
        end

        % Value changed function: MinDiffEditField
        function MinDiffEditFieldValueChanged(app, event)
            value = app.MinDiffEditField.Value;
            app.MinDiffSlider.Value = value;
            app.MinDiffSliderValueChanged;            
        end

        % Button pushed function: ContrasttoallButton
        function ContrasttoallButtonPushed(app, event)
            switch app.OperationButton
                case 1 % Difference
                    app.CallingApp.InfoData.ContrastRestasReal = ...
                        [app.ResultAxes.CLim]'.*ones(size(app.CallingApp.InfoData.ContrastRestasReal));
                case 2 % Division
                    app.CallingApp.InfoData.ContrastDivisionReal = ...
                        [app.ResultAxes.CLim]'.*ones(size(app.CallingApp.InfoData.ContrastDivisionReal));
                case 3  % Bogoliubov Angle
                    app.CallingApp.InfoData.ContrastBogolAngleReal = ...
                        [app.ResultAxes.CLim]'.*ones(size(app.CallingApp.InfoData.ContrastBogolAngleReal));
            end
            app.CallingApp.InfoData.ContrastReal = ...
                [app.RealAxes.CLim]'.*ones(size(app.CallingApp.InfoData.ContrastReal));
            
        end

        % Button pushed function: SavecontrastButton
        function SavecontrastButtonPushed(app, event)
            switch app.OperationButton
                case 1
                    app.CallingApp.InfoData.ContrastRestasReal(:,app.kResta) = app.ResultAxes.CLim';
                case 2
                    app.CallingApp.InfoData.ContrastDivisionReal(:,app.kResta) = app.ResultAxes.CLim';
                case 3 % Bogoliubov Angle
                    app.CallingApp.InfoData.ContrastBogolAngleReal(:,app.kResta) = app.ResultAxes.CLim';
            end

            app.CallingApp.InfoData.ContrastReal(:,app.k) = app.RealAxes.CLim';
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % MOVER EL GORDO DE LA FUNCION A UN .M
            pixSize = size(app.CallingApp.InfoStruct.DistanciaFilas, 2);% Image pixel size
            nmSize = max(app.CallingApp.InfoStruct.DistanciaFilas); % Image size in nanometers

            % POR QUE NO USAR DISTANCIACOLUMNAS??
            x=linspace(0,nmSize,pixSize);
            y=x;
            %[X,Y] = meshgrid(x,y); % Intentar usar esto y ahorrarse bucles for?
            n=nmSize/pixSize;

            center = n*[app.XEditField.Value, app.YEditField.Value]; % Vortex center
            set([app.XnmLabel, app.YnmLabel], 'Visible', 'on')
            app.XnmLabel.Text = num2str(round(center(1),1));
            app.YnmLabel.Text = num2str(round(center(2),1));


            amplitude = app.AmplitudeEditField.Value;
            sigma = app.SigmaEditField.Value;
            NormFactor = -1/(sigma*sqrt(2*pi)); % Normalization Factor
            G1 = zeros(pixSize);

            for i=1:pixSize % Generate sombrero with 2D gaussian
                for j=1:pixSize
                    r=sqrt((center(1)-x(j))^2+(center(2)-y(i))^2);
                    G1(i,j)=NormFactor*exp(-(r^2)/(2*sigma^2));
                end
            end
            
            % Substract Sombrero to our Difference map
            app.CallingApp.InfoData.Restas{app.kResta} = app.CallingApp.InfoStruct.Restas{app.kResta} - amplitude*G1;

            % Plot the new Difference map whithout Sombrero
            [app.CallingApp.InfoData] = showCell(app, app.CallingApp.InfoData.Restas, app.CallingApp.InfoData, app.kResta, app.ResultAxes);
        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
            msgbox('Work in progress.','Under maintainment','warn')
        end

        % Selection changed function: OperationButtonGroup
        function OperationButtonGroupSelectionChanged(app, event)
            selectedButton = app.OperationButtonGroup.SelectedObject;
            
            % Take selectedButton index in group as variable?
            app.OperationButton = find(selectedButton == app.OperationButtonGroup.Children);

            switch app.OperationButton
                case 1 %Difference
                    [app.MinDiffSlider.Limits, app.MaxDiffSlider.Limits]=deal([app.minResta, app.maxResta]);
                case 2
                    try % Just in case there is a division by 0
                        [app.MinDiffSlider.Limits, app.MaxDiffSlider.Limits]=deal([app.minDivision, app.maxDivision]);
                    catch ME
                        app.maxDivision = 100;
                        rethrow(ME);
                    end
                case 3  % Bogoliubov Angle
                    [app.MinDiffSlider.Limits, app.MaxDiffSlider.Limits]=deal([app.minAngle, app.maxAngle]);
            end

            app.EnergySpinnerValueChanged;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EnergySymmetryUIFigure = uifigure('Visible', 'off');
            app.EnergySymmetryUIFigure.Name = 'Energy Symmetry';
            app.EnergySymmetryUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @EnergySymmetryUIFigureWindowButtonDown, true);
            app.EnergySymmetryUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @EnergySymmetryUIFigureWindowButtonUp, true);
            app.EnergySymmetryUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @EnergySymmetryUIFigureWindowButtonMotion, true);
            app.EnergySymmetryUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @EnergySymmetryUIFigureWindowScrollWheel, true);
            app.EnergySymmetryUIFigure.KeyPressFcn = createCallbackFcn(app, @EnergySymmetryUIFigureKeyPress, true);
            app.ResultAxes = uiaxes(app.EnergySymmetryUIFigure);
            title(app.ResultAxes, 'Energy Difference')
            xlabel(app.ResultAxes, 'X')
            ylabel(app.ResultAxes, 'Y')
            zlabel(app.ResultAxes, 'Z')
            app.ResultAxes.Tag = 'DiffAxes';
            app.RealAxes = uiaxes(app.EnergySymmetryUIFigure);
            title(app.RealAxes, 'Original Image')
            xlabel(app.RealAxes, 'X')
            ylabel(app.RealAxes, 'Y')
            zlabel(app.RealAxes, 'Z')
            app.RealAxes.Tag = 'RealAxes';
            app.MaxEditFieldLabel = uilabel(app.EnergySymmetryUIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.EnergySymmetryUIFigure, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Value = 100;
            app.MinEditFieldLabel = uilabel(app.EnergySymmetryUIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.EnergySymmetryUIFigure, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.EnergySymmetryLabel = uilabel(app.EnergySymmetryUIFigure);
            app.EnergySymmetryLabel.Text = 'Energy Symmetry';
            app.EnablemousecontrolsCheckBox = uicheckbox(app.EnergySymmetryUIFigure);
            app.EnablemousecontrolsCheckBox.Text = 'Enable mouse controls';
            app.EnablemousecontrolsCheckBox.Value = true;
            app.KeepChangesButton = uibutton(app.EnergySymmetryUIFigure, 'push');
            app.KeepChangesButton.ButtonPushedFcn = createCallbackFcn(app, @KeepChangesButtonPushed, true);
            app.KeepChangesButton.Text = 'Keep Changes';
            app.EnergySpinner = uispinner(app.EnergySymmetryUIFigure);
            app.EnergySpinner.ValueChangedFcn = createCallbackFcn(app, @EnergySpinnerValueChanged, true);
            app.meVLabel = uilabel(app.EnergySymmetryUIFigure);
            app.meVLabel.Text = 'meV';
            app.SavecontrastButton = uibutton(app.EnergySymmetryUIFigure, 'push');
            app.SavecontrastButton.ButtonPushedFcn = createCallbackFcn(app, @SavecontrastButtonPushed, true);
            app.SavecontrastButton.Text = 'Save contrast';
            app.ContrasttoallButton = uibutton(app.EnergySymmetryUIFigure, 'push');
            app.ContrasttoallButton.ButtonPushedFcn = createCallbackFcn(app, @ContrasttoallButtonPushed, true);
            app.ContrasttoallButton.Text = 'Contrast to all';
            app.VideoButton = uibutton(app.EnergySymmetryUIFigure, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Enable = 'off';
            app.VideoButton.Text = 'Video';
            app.TemperatureLabel = uilabel(app.EnergySymmetryUIFigure);
            app.TemperatureLabel.Visible = 'off';
            app.TemperatureLabel.Text = 'T = ';
            app.MagneticFieldLabel = uilabel(app.EnergySymmetryUIFigure);
            app.MagneticFieldLabel.Visible = 'off';
            app.MagneticFieldLabel.Text = 'H = ';
            app.TemperatureValue = uilabel(app.EnergySymmetryUIFigure);
            app.TemperatureValue.Visible = 'off';
            app.TemperatureValue.Text = '';
            app.MaxSlider = uislider(app.EnergySymmetryUIFigure);
            app.MaxSlider.MajorTicks = [];
            app.MaxSlider.MajorTickLabels = {''};
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MaxSliderValueChanged, true);
            app.MaxSlider.MinorTicks = [];
            app.MaxSlider.Value = 100;
            app.MinSlider = uislider(app.EnergySymmetryUIFigure);
            app.MinSlider.MajorTicks = [];
            app.MinSlider.MajorTickLabels = {''};
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinSliderValueChanged, true);
            app.MinSlider.MinorTicks = [];
            app.MagneticFieldValue = uilabel(app.EnergySymmetryUIFigure);
            app.MagneticFieldValue.Visible = 'off';
            app.MagneticFieldValue.Text = '';
            app.SombreritoPanel = uipanel(app.EnergySymmetryUIFigure);
            app.SombreritoPanel.Title = 'Sombrerito';
            app.OKButton = uibutton(app.SombreritoPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SigmaEditFieldLabel = uilabel(app.SombreritoPanel);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.SombreritoPanel, 'numeric');
            app.XEditFieldLabel = uilabel(app.SombreritoPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.SombreritoPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.SombreritoPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.SombreritoPanel, 'numeric');
            app.PositionLabel = uilabel(app.SombreritoPanel);
            app.PositionLabel.Text = 'Position';
            app.AmplitudeEditFieldLabel = uilabel(app.SombreritoPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SombreritoPanel, 'numeric');
            app.XnmLabel = uilabel(app.SombreritoPanel);
            app.XnmLabel.Visible = 'off';
            app.XnmLabel.Text = 'X nm';
            app.YnmLabel = uilabel(app.SombreritoPanel);
            app.YnmLabel.Visible = 'off';
            app.YnmLabel.Text = 'Y nm';
            app.ColorbarCheckBox = uicheckbox(app.EnergySymmetryUIFigure);
            app.ColorbarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorbarCheckBoxValueChanged, true);
            app.ColorbarCheckBox.Text = 'Colorbar';
            app.x1EditFieldLabel = uilabel(app.EnergySymmetryUIFigure);
            app.x1EditFieldLabel.Visible = 'off';
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.EnergySymmetryUIFigure, 'numeric');
            app.x1EditField.Visible = 'off';
            app.x1EditField.Value = 1;
            app.x2EditFieldLabel = uilabel(app.EnergySymmetryUIFigure);
            app.x2EditFieldLabel.Visible = 'off';
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.EnergySymmetryUIFigure, 'numeric');
            app.x2EditField.Visible = 'off';
            app.x2EditField.Value = 128;
            app.y1EditFieldLabel = uilabel(app.EnergySymmetryUIFigure);
            app.y1EditFieldLabel.Visible = 'off';
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.EnergySymmetryUIFigure, 'numeric');
            app.y1EditField.Visible = 'off';
            app.y1EditField.Value = 1;
            app.y2EditFieldLabel = uilabel(app.EnergySymmetryUIFigure);
            app.y2EditFieldLabel.Visible = 'off';
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.EnergySymmetryUIFigure, 'numeric');
            app.y2EditField.Visible = 'off';
            app.y2EditField.Value = 128;
            app.ColormapDropDown = uidropdown(app.EnergySymmetryUIFigure);
            app.ColormapDropDown.Items = {'parula', 'afmhot', 'autumn', 'binary', 'bone', 'brg', 'bwr', 'cividis', 'cool', 'coolwarm', 'copper', 'cubehelix', 'flag', 'gist_earth', 'gist_gray', 'gist_heat', 'gist_ncar', 'gist_rainbow', 'gist_stern', 'gist_yarg', 'gnuplot', 'gnuplot2', 'gray', 'hot', 'hsv', 'inferno', 'jet', 'magma', 'nipy_spectral', 'ocean', 'pink', 'plasma', 'prism', 'rainbow', 'seismic', 'spring', 'summer', 'tab10', 'tab20', 'tab20b', 'tab20c', 'terrain', 'turbo', 'viridis', 'winter', 'Accent', 'Blues', 'BrBG', 'BuGn', 'BuPu', 'CMRmap', 'Dark2', 'GnBu', 'Greens', 'Greys', 'OrRd', 'Oranges', 'PRGn', 'Paired', 'Pastel1', 'Pastel2', 'PiYG', 'PuBu', 'PuBuGn', 'PuOr', 'PuRd', 'Purples', 'RdBu', 'RdGy', 'RdPu', 'RdYlBu', 'RdYlGn', 'Reds', 'Set1', 'Set2', 'Set3', 'Spectral', 'Wistia', 'YlGn', 'YlGnBu', 'YlOrBr', 'YlOrRd', 'holaradiola'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Enable = 'off';
            app.ColormapDropDown.Visible = 'off';
            app.ColormapDropDown.Value = 'viridis';
            app.ReverseColormapCheckBox = uicheckbox(app.EnergySymmetryUIFigure);
            app.ReverseColormapCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseColormapCheckBoxValueChanged, true);
            app.ReverseColormapCheckBox.Enable = 'off';
            app.ReverseColormapCheckBox.Visible = 'off';
            app.ReverseColormapCheckBox.Text = 'Reverse';
            app.MaxDiffSlider = uislider(app.EnergySymmetryUIFigure);
            app.MaxDiffSlider.Limits = [-0.5 0.5];
            app.MaxDiffSlider.MajorTicks = [];
            app.MaxDiffSlider.ValueChangedFcn = createCallbackFcn(app, @MaxDiffSliderValueChanged, true);
            app.MaxDiffSlider.MinorTicks = [];
            app.MaxDiffSlider.Tag = 'Symmetry';
            app.MaxDiffSlider.Value = 0.5;
            app.MaxEditField_2Label = uilabel(app.EnergySymmetryUIFigure);
            app.MaxEditField_2Label.Text = 'Max';
            app.MinDiffSlider = uislider(app.EnergySymmetryUIFigure);
            app.MinDiffSlider.Limits = [-0.5 0.5];
            app.MinDiffSlider.MajorTicks = [];
            app.MinDiffSlider.ValueChangedFcn = createCallbackFcn(app, @MinDiffSliderValueChanged, true);
            app.MinDiffSlider.MinorTicks = [];
            app.MinDiffSlider.Tag = 'Symmetry';
            app.MinDiffSlider.Value = -0.5;
            app.MaxDiffEditField = uieditfield(app.EnergySymmetryUIFigure, 'numeric');
            app.MaxDiffEditField.ValueChangedFcn = createCallbackFcn(app, @MaxDiffEditFieldValueChanged, true);
            app.MaxDiffEditField.Tag = 'Symmetry';
            app.MaxDiffEditField.Value = 0.5;
            app.MinDiffEditField = uieditfield(app.EnergySymmetryUIFigure, 'numeric');
            app.MinDiffEditField.ValueChangedFcn = createCallbackFcn(app, @MinDiffEditFieldValueChanged, true);
            app.MinDiffEditField.Tag = 'Symmetry';
            app.MinDiffEditField.Value = -0.5;
            app.OperationButtonGroup = uibuttongroup(app.EnergySymmetryUIFigure);
            app.OperationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OperationButtonGroupSelectionChanged, true);
            app.OperationButtonGroup.Title = 'Operation';
            app.OperationButtonGroup.Tag = 'Symmetry';
            app.BogolAngleButton = uiradiobutton(app.OperationButtonGroup);
            app.BogolAngleButton.Tooltip = {'Divides maps with opposite bias. Shifts by 0.1 before dividing.'};
            app.BogolAngleButton.Text = 'Bogol. Angle';
            app.DivisionButton = uiradiobutton(app.OperationButtonGroup);
            app.DivisionButton.Tooltip = {'Divides maps with opposite bias. Shifts by 0.1 before dividing.'};
            app.DivisionButton.Text = 'Division';
            app.DifferenceButton = uiradiobutton(app.OperationButtonGroup);
            app.DifferenceButton.Text = 'Difference';
            app.DifferenceButton.Value = true;
            app.MinEditField_2Label = uilabel(app.EnergySymmetryUIFigure);
            app.MinEditField_2Label.Text = 'Min';
            % Show the figure after all components are created
            app.EnergySymmetryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnergySym(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnergySymmetryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnergySymmetryUIFigure)
        end
    end
end