classdef blqReader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        blqReaderUIFigure             matlab.ui.Figure
        TitlePanel                    matlab.ui.container.Panel
        blqReaderLabel                matlab.ui.control.Label
        InitialpointSpinner           matlab.ui.control.Spinner
        InitialpointSpinnerLabel      matlab.ui.control.Label
        CurvestoshowEditField         matlab.ui.control.NumericEditField
        CurvestoshowLabel             matlab.ui.control.Label
        ChangesamplecurvesButton      matlab.ui.control.Button
        ButtonsPanel                  matlab.ui.container.Panel
        AutoOffsetButton              matlab.ui.control.Button
        DerivativepointsLabel         matlab.ui.control.Label
        ScalevoltagebyEditFieldLabel  matlab.ui.control.Label
        VoltageScaleFactor            matlab.ui.control.NumericEditField
        OffsetvoltageEditField        matlab.ui.control.NumericEditField
        DerivativepointsSpinner       matlab.ui.control.Spinner
        OffsetvoltageLabel            matlab.ui.control.Label
        toEditFieldLabel              matlab.ui.control.Label
        toEditField                   matlab.ui.control.NumericEditField
        fromEditField                 matlab.ui.control.NumericEditField
        fromEditFieldLabel            matlab.ui.control.Label
        AnalyzeimagesButton           matlab.ui.control.Button
        CalculateButton               matlab.ui.control.Button
        fromEditFieldLabel_4          matlab.ui.control.Label
        fromEditFieldLabel_3          matlab.ui.control.Label
        fromEditFieldLabel_2          matlab.ui.control.Label
        NormButtonGroup               matlab.ui.container.ButtonGroup
        FeenstraNormButton            matlab.ui.control.RadioButton
        NotnormalizedButton           matlab.ui.control.RadioButton
        NormalizeSingleButton         matlab.ui.control.RadioButton
        NormalizeMirrorButton         matlab.ui.control.RadioButton
        LoadblqButton                 matlab.ui.control.Button
        AxesPanel                     matlab.ui.container.Panel
        CurrentAxes                   matlab.ui.control.UIAxes
        ConductanceAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp % main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
        end

        % Button pushed function: LoadblqButton
        function LoadblqButtonPushed(app, event)
            [Struct, MatrizCorriente, Voltaje] = loadblq(app, app.InitialpointSpinner.Value);
            if ~isequal(Struct,0)
            app.CallingApp.Struct = Struct;
            app.CallingApp.MatrizCorriente = MatrizCorriente;
            app.CallingApp.Voltaje = Voltaje;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            [app.CallingApp.Struct] = calculateblq(app, app.CallingApp.Struct, app.CallingApp.Voltaje, app.CallingApp.MatrizCorriente);
        end

        % Button pushed function: ChangesamplecurvesButton
        function ChangesamplecurvesButtonPushed(app, event)
            [app.CallingApp.Struct] = changeCurves(app, app.CallingApp.Struct);
            CalculateButtonPushed(app)
        end

        % Button pushed function: AnalyzeimagesButton
        function AnalyzeimagesButtonPushed(app, event)
            % Replace MatrizCorriente with the original one, in case it was
            % already replaced with MatrizCorrienteCortada
            app.CallingApp.Struct.MatrizCorriente = app.CallingApp.MatrizCorriente;

            output = analyzeImages(app.CallingApp.Struct);
            if ~isequal(output,0)
                app.CallingApp.Struct = output;
                maps = num2str(length(app.CallingApp.Struct.Energia));
                uiwait(msgbox([maps ' maps succesfully generated'], 'You are almost there', 'help'))
                app.CallingApp.mapsPreviewApp = mapsPreview(app.CallingApp, app.CallingApp.Struct);
            end
        end

        % Value changed function: DerivativepointsSpinner
        function DerivativepointsSpinnerValueChanged(app, event)
            CalculateButtonPushed(app)
        end

        % Selection changed function: NormButtonGroup
        function NormButtonGroupSelectionChanged(app, event)
%             selectedButton = app.NormButtonGroup.SelectedObject;
% %             disp(selectedButton.Text)
%             switch selectedButton.Text 
%                 case 'Feenstra Normalization'
% %                     disp('We now execute the function that gives us the control window')
% %                     msgbox('This feature is not yet finished, please be patient')
% %                     [app.CallingApp.Struct] = Feenstra_adjust(app, app.CallingApp.Struct, app.CallingApp.Voltaje, app.CallingApp.MatrizCorriente);
%                 otherwise
% %                     disp('Typical')
%             end
%             disp(app.FeenstraNormButton.Value)
            if app.FeenstraNormButton.Value
                [app.CallingApp.Struct] = Feenstra_adjust(app, app.CallingApp.Struct, app.CallingApp.Voltaje, app.CallingApp.MatrizCorriente);

            end
        end

        % Button pushed function: AutoOffsetButton
        function AutoOffsetButtonPushed(app, event)
            % Calculate the offset, add the voltage value to the offset
            % dialog and place a temporary vertical line on the current
            % plot (and maybe also in the conductance)
            app.OffsetvoltageEditField.Value = -autoOffset(app, app.CallingApp.Voltaje, app.CallingApp.MatrizCorriente);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.blqReaderUIFigure = uifigure('Visible', 'off');
            colormap(app.blqReaderUIFigure, 'parula');
            app.blqReaderUIFigure.Name = 'blqReader';
            app.AxesPanel = uipanel(app.blqReaderUIFigure);
            app.ConductanceAxes = uiaxes(app.AxesPanel);
            title(app.ConductanceAxes, 'dI/dV')
            xlabel(app.ConductanceAxes, 'Bias Voltage (mV)')
            ylabel(app.ConductanceAxes, 'Conductance (\muS)')
            app.CurrentAxes = uiaxes(app.AxesPanel);
            title(app.CurrentAxes, 'IV')
            xlabel(app.CurrentAxes, 'Bias Voltage (mV)')
            ylabel(app.CurrentAxes, 'Tunneling current (nA)')
            app.ButtonsPanel = uipanel(app.blqReaderUIFigure);
            app.LoadblqButton = uibutton(app.ButtonsPanel, 'push');
            app.LoadblqButton.ButtonPushedFcn = createCallbackFcn(app, @LoadblqButtonPushed, true);
            app.LoadblqButton.Text = 'Load blq';
            app.NormButtonGroup = uibuttongroup(app.ButtonsPanel);
            app.NormButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormButtonGroupSelectionChanged, true);
            app.NormalizeMirrorButton = uiradiobutton(app.NormButtonGroup);
            app.NormalizeMirrorButton.Tooltip = {'Normalizes using the given range for negative and positive voltages'};
            app.NormalizeMirrorButton.Text = 'Normalize symmetric';
            app.NormalizeMirrorButton.Value = true;
            app.NormalizeSingleButton = uiradiobutton(app.NormButtonGroup);
            app.NormalizeSingleButton.Tooltip = {'Normalizes using the given range only for positve or negative voltages'};
            app.NormalizeSingleButton.Text = 'Normalize one side';
            app.NotnormalizedButton = uiradiobutton(app.NormButtonGroup);
            app.NotnormalizedButton.Text = 'Not normalized';
            app.FeenstraNormButton = uiradiobutton(app.NormButtonGroup);
            app.FeenstraNormButton.Enable = 'off';
            app.FeenstraNormButton.Visible = 'off';
            app.FeenstraNormButton.Text = 'Feenstra Normalization';
            app.fromEditFieldLabel_2 = uilabel(app.ButtonsPanel);
            app.fromEditFieldLabel_2.Text = 'mV';
            app.fromEditFieldLabel_3 = uilabel(app.ButtonsPanel);
            app.fromEditFieldLabel_3.Text = 'mV';
            app.fromEditFieldLabel_4 = uilabel(app.ButtonsPanel);
            app.fromEditFieldLabel_4.Text = 'mV';
            app.CalculateButton = uibutton(app.ButtonsPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AnalyzeimagesButton = uibutton(app.ButtonsPanel, 'push');
            app.AnalyzeimagesButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeimagesButtonPushed, true);
            app.AnalyzeimagesButton.Text = {'Analyze'; 'images'};
            app.fromEditFieldLabel = uilabel(app.ButtonsPanel);
            app.fromEditFieldLabel.Text = 'from';
            app.fromEditField = uieditfield(app.ButtonsPanel, 'numeric');
            app.toEditField = uieditfield(app.ButtonsPanel, 'numeric');
            app.toEditFieldLabel = uilabel(app.ButtonsPanel);
            app.toEditFieldLabel.Text = 'to';
            app.OffsetvoltageLabel = uilabel(app.ButtonsPanel);
            app.OffsetvoltageLabel.Text = 'Offset voltage:';
            app.DerivativepointsSpinner = uispinner(app.ButtonsPanel);
            app.DerivativepointsSpinner.Limits = [1 Inf];
            app.DerivativepointsSpinner.ValueChangedFcn = createCallbackFcn(app, @DerivativepointsSpinnerValueChanged, true);
            app.DerivativepointsSpinner.Value = 1;
            app.OffsetvoltageEditField = uieditfield(app.ButtonsPanel, 'numeric');
            app.VoltageScaleFactor = uieditfield(app.ButtonsPanel, 'numeric');
            app.VoltageScaleFactor.Value = 1;
            app.ScalevoltagebyEditFieldLabel = uilabel(app.ButtonsPanel);
            app.ScalevoltagebyEditFieldLabel.Text = 'Scale voltage by';
            app.DerivativepointsLabel = uilabel(app.ButtonsPanel);
            app.DerivativepointsLabel.Text = 'Derivative points:';
            app.AutoOffsetButton = uibutton(app.ButtonsPanel, 'push');
            app.AutoOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @AutoOffsetButtonPushed, true);
            app.AutoOffsetButton.Text = 'Auto Offset';
            app.TitlePanel = uipanel(app.blqReaderUIFigure);
            app.ChangesamplecurvesButton = uibutton(app.TitlePanel, 'push');
            app.ChangesamplecurvesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangesamplecurvesButtonPushed, true);
            app.ChangesamplecurvesButton.Text = 'Change sample curves';
            app.CurvestoshowLabel = uilabel(app.TitlePanel);
            app.CurvestoshowLabel.Text = 'Curves to show:';
            app.CurvestoshowEditField = uieditfield(app.TitlePanel, 'numeric');
            app.CurvestoshowEditField.RoundFractionalValues = 'on';
            app.CurvestoshowEditField.Value = 1;
            app.InitialpointSpinnerLabel = uilabel(app.TitlePanel);
            app.InitialpointSpinnerLabel.Text = 'Initial point';
            app.InitialpointSpinner = uispinner(app.TitlePanel);
            app.InitialpointSpinner.Limits = [1 Inf];
            app.InitialpointSpinner.Value = 1;
            app.blqReaderLabel = uilabel(app.TitlePanel);
            app.blqReaderLabel.Text = 'blqReader';
            % Show the figure after all components are created
            app.blqReaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blqReader(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.blqReaderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.blqReaderUIFigure)
        end
    end
end