classdef blqApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        blqAnalysisUIFigure             matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        InfoStructtoworkspaceMenu       matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        RecalculateFFTMenu              matlab.ui.container.Menu
        ReinterpolateMapsMenu           matlab.ui.container.Menu
        CellMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OldMenu                         matlab.ui.container.Menu
        SaveformatMenu                  matlab.ui.container.Menu
        jpegMenu                        matlab.ui.container.Menu
        pngMenu                         matlab.ui.container.Menu
        svgMenu                         matlab.ui.container.Menu
        FilteringMenu                   matlab.ui.container.Menu
        ApplyfilterstocurvesMenu        matlab.ui.container.Menu
        CompressionMenu                 matlab.ui.container.Menu
        EnableCompression               matlab.ui.container.Menu
        MagneticFieldValue              matlab.ui.control.Label
        TemperatureValue                matlab.ui.control.Label
        MagneticFieldLabel              matlab.ui.control.Label
        TemperatureLabel                matlab.ui.control.Label
        blqAnalysisQPIStudyLabel        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        OpenTab                         matlab.ui.container.Tab
        ShowInfoBtn                     matlab.ui.control.Button
        ClearInfostructButton           matlab.ui.control.Button
        CurrentblqName                  matlab.ui.control.Label
        CurrentblqLabel                 matlab.ui.control.Label
        CurrentInfoStructName           matlab.ui.control.Label
        CurrentInfoStructLabel          matlab.ui.control.Label
        GeneratemapsButton              matlab.ui.control.Button
        OnlySquareCheckBox              matlab.ui.control.CheckBox
        RestoreButton                   matlab.ui.control.Button
        CutInfoStructButton             matlab.ui.control.Button
        y2EditField                     matlab.ui.control.NumericEditField
        y2EditFieldLabel                matlab.ui.control.Label
        y1EditField                     matlab.ui.control.NumericEditField
        y1EditFieldLabel                matlab.ui.control.Label
        x2EditField                     matlab.ui.control.NumericEditField
        x2EditFieldLabel                matlab.ui.control.Label
        x1EditField                     matlab.ui.control.NumericEditField
        x1EditFieldLabel                matlab.ui.control.Label
        LoadInfoStructfromworkspaceButton  matlab.ui.control.Button
        LoadInfoStructfromfileButton    matlab.ui.control.Button
        SaveInfoStructButton            matlab.ui.control.Button
        NewAppButton                    matlab.ui.control.Button
        AnalyzeRealTab                  matlab.ui.container.Tab
        TukeyAlphaField                 matlab.ui.control.NumericEditField
        TukeyWindowBtn                  matlab.ui.control.Button
        LoadProfileBtn                  matlab.ui.control.Button
        SecondDerivSpin                 matlab.ui.control.Spinner
        SecondDerivButton               matlab.ui.control.Button
        SigmaRealLbl                    matlab.ui.control.Label
        SubstractAvgCurveButton         matlab.ui.control.Button
        NormalizeButton                 matlab.ui.control.Button
        ShiftColormapRealCheckBox       matlab.ui.control.CheckBox
        ColormapRealDropDownNewCategory  matlab.ui.control.DropDown
        SaveAllRealDropDown             matlab.ui.control.DropDown
        AngleCorrelationButton          matlab.ui.control.Button
        HistogramButton                 matlab.ui.control.Button
        ReverseColormapRealCheckBox     matlab.ui.control.CheckBox
        SquareSpinner                   matlab.ui.control.Spinner
        SquareFilterButton              matlab.ui.control.Button
        PlaneButton                     matlab.ui.control.Button
        FlattenDropDown                 matlab.ui.control.DropDown
        FlattenButton                   matlab.ui.control.Button
        SaveallRealButton               matlab.ui.control.Button
        ColormapRealDropDownNew         matlab.ui.control.DropDown
        KeepChangesNewRealButton        matlab.ui.control.Button
        GaussianFilterNewButtonReal     matlab.ui.control.Button
        RotateNewButtonReal             matlab.ui.control.Button
        VisualizeRealButton             matlab.ui.control.Button
        RealMapsButton                  matlab.ui.control.Button
        LoadContrastRealButton          matlab.ui.control.Button
        SaveContrastRealButton          matlab.ui.control.Button
        MeanCurrentButton               matlab.ui.control.Button
        EditGaussianFilterReal          matlab.ui.control.NumericEditField
        EditRotateAngleReal             matlab.ui.control.NumericEditField
        MeanConductanceButton           matlab.ui.control.Button
        AnalyzeFFTTab                   matlab.ui.container.Tab
        InterpRatioSpin                 matlab.ui.control.Spinner
        InterpolateMapButton            matlab.ui.control.Button
        LoadProfileBtn_2                matlab.ui.control.Button
        SigmaCoreLbl                    matlab.ui.control.Label
        SigmaFFTLbl                     matlab.ui.control.Label
        KeepChangesNewFFTButton         matlab.ui.control.Button
        CutasviewButton                 matlab.ui.control.Button
        qanisotropyEditField            matlab.ui.control.NumericEditField
        qanisotropyButton               matlab.ui.control.Button
        RadialprofileButton             matlab.ui.control.Button
        EditProfileAngle                matlab.ui.control.NumericEditField
        AngleprofileButton              matlab.ui.control.Button
        DiagonalprofileButton           matlab.ui.control.Button
        HorizontalprofileButton         matlab.ui.control.Button
        VerticalprofileButton           matlab.ui.control.Button
        LoadContrastFFTButton           matlab.ui.control.Button
        SaveContrastFFTButton           matlab.ui.control.Button
        SaveAllFFTDropDown              matlab.ui.control.DropDown
        SaveallFFTButton                matlab.ui.control.Button
        AdjustZeroButton                matlab.ui.control.Button
        SigmaCoreEditField              matlab.ui.control.NumericEditField
        GaussiancoresubstractionButton  matlab.ui.control.Button
        SquareSpinnerFFT                matlab.ui.control.Spinner
        SquareFilterFFTButton           matlab.ui.control.Button
        EditGaussianFilterFFT           matlab.ui.control.NumericEditField
        GaussianFilterNewButtonFFT      matlab.ui.control.Button
        SymmetryDropDown                matlab.ui.control.DropDown
        SymmetrizeButton                matlab.ui.control.Button
        RotateMinus90Btn                matlab.ui.control.Button
        RotatePlus90Btn                 matlab.ui.control.Button
        EditRotateAngleFFT              matlab.ui.control.NumericEditField
        RotateNewButtonFFT              matlab.ui.control.Button
        RmCentralLineDropDown           matlab.ui.control.DropDown
        RemoveCentralLineButton         matlab.ui.control.Button
        ShiftColormapFFTCheckBox        matlab.ui.control.CheckBox
        ReverseColormapFFTCheckBox      matlab.ui.control.CheckBox
        VisualizeFFTButton              matlab.ui.control.Button
        ColormapFFTDropDown             matlab.ui.control.DropDown
        ColormapFFTDropDownCategory     matlab.ui.control.DropDown
        FFTMapsButton                   matlab.ui.control.Button
        WarpCorrectionTab               matlab.ui.container.Tab
        AngleShapeSlider                matlab.ui.control.Slider
        AngleSliderLabel                matlab.ui.control.Label
        WarpCorrectionRealBtn           matlab.ui.control.Button
        YShearwarpEditField_2Label      matlab.ui.control.Label
        YXRatioReal                     matlab.ui.control.NumericEditField
        YShearwarpReal                  matlab.ui.control.NumericEditField
        YXRatioEditField_2Label         matlab.ui.control.Label
        XShearwarpReal                  matlab.ui.control.NumericEditField
        XShearwarpEditField_2Label      matlab.ui.control.Label
        RealCorrectionTitle             matlab.ui.control.Label
        CorrectionRotBtn                matlab.ui.control.Button
        CorrectionBtn                   matlab.ui.control.Button
        RescaleButton                   matlab.ui.control.Button
        ScaleFactor                     matlab.ui.control.NumericEditField
        ScalebyEditFieldLabel           matlab.ui.control.Label
        FFTWarpCorrectionButton         matlab.ui.control.Button
        YShearwarpEditFieldLabel        matlab.ui.control.Label
        YXRatioEditField                matlab.ui.control.NumericEditField
        YShearwarpEditField             matlab.ui.control.NumericEditField
        YXRatioEditFieldLabel           matlab.ui.control.Label
        XShearwarpEditField             matlab.ui.control.NumericEditField
        XShearwarpEditFieldLabel        matlab.ui.control.Label
        FFTCorrectionTitle              matlab.ui.control.Label
        GapMappingTab                   matlab.ui.container.Tab
        MaxDerivativeEditField          matlab.ui.control.NumericEditField
        MaxDerivativeEditFieldLabel     matlab.ui.control.Label
        GoButton                        matlab.ui.control.Button
        ContrastMaxEditField            matlab.ui.control.NumericEditField
        ContrastMaxEditFieldLabel       matlab.ui.control.Label
        ContrastMinEditField            matlab.ui.control.NumericEditField
        ContrastMinEditFieldLabel       matlab.ui.control.Label
        GaussianfilterEditField         matlab.ui.control.NumericEditField
        GaussianfilterEditFieldLabel    matlab.ui.control.Label
        CalculateGapMapButton           matlab.ui.control.Button
        ThresholdratioEditField         matlab.ui.control.NumericEditField
        ThresholdratioEditFieldLabel    matlab.ui.control.Label
        NumDerivEditField               matlab.ui.control.NumericEditField
        NumDerivEditFieldLabel          matlab.ui.control.Label
        RangePeaksmVEditField           matlab.ui.control.NumericEditField
        RangePeaksmVEditFieldLabel      matlab.ui.control.Label
        SweepdirectionLabel             matlab.ui.control.Label
        SweepDirection                  matlab.ui.container.ButtonGroup
        YButton                         matlab.ui.control.RadioButton
        XButton                         matlab.ui.control.RadioButton
        MoreFunctionsTab                matlab.ui.container.Tab
        SigmaTopoLbl                    matlab.ui.control.Label
        GaussianFilterTopoBtn           matlab.ui.control.Button
        EditGaussianFilterTopo          matlab.ui.control.NumericEditField
        RemoveLinesDirection            matlab.ui.control.DropDown
        RemovePointsTopo                matlab.ui.control.Button
        RemoveLinesInput                matlab.ui.control.EditField
        RemoveLinesTopo                 matlab.ui.control.Button
        TopoLabel                       matlab.ui.control.Label
        Topo2Workspace                  matlab.ui.control.Button
        ParabolaTopoBtn                 matlab.ui.control.Button
        PlaneTopoBtn                    matlab.ui.control.Button
        FlattenTopoMode                 matlab.ui.control.DropDown
        FlattenTopoDirection            matlab.ui.control.DropDown
        FlattenTopoBtn                  matlab.ui.control.Button
        MedianWindow                    matlab.ui.control.Spinner
        WindowLabel                     matlab.ui.control.Label
        MedianFilter                    matlab.ui.control.Button
        SigmaEditField                  matlab.ui.control.NumericEditField
        SigmaEditFieldLabel             matlab.ui.control.Label
        PopcornButton                   matlab.ui.control.Button
        AveragescatteringButton         matlab.ui.control.Button
        SymmetryTab                     matlab.ui.container.Tab
        OperationButtonGroup            matlab.ui.container.ButtonGroup
        DifferenceButton                matlab.ui.control.RadioButton
        DivisionButton                  matlab.ui.control.RadioButton
        BogolAngleButton                matlab.ui.control.RadioButton
        ShiftColormapRestasCheckBox     matlab.ui.control.CheckBox
        ColormapRestasDropDownCategory  matlab.ui.control.DropDown
        ReverseColormapRestasCheckBox   matlab.ui.control.CheckBox
        ColormapRestasDropDown          matlab.ui.control.DropDown
        LoadContrastRestasButton        matlab.ui.control.Button
        SaveContrastRestasButton        matlab.ui.control.Button
        EnergySymmetryButton            matlab.ui.control.Button
    end


    properties (Access = public)
        Property % Description
        % Variables de la aplicación
        InfoStruct
        InfoStructOriginal
        InfoStructAnalisis
        InfoStructAnalisisCopy
        InfoData 
        
        fourierImage
        realImage
        
        ReverseColormapReal
        ReverseColormapFFT
        ShiftColormapReal
        ShiftColormapFFT
        
        Symmetry
        New
        SaveType
        
        V
        H
        
        FlattenFlag
        
        % Variables coming from blqReaderApp
        blqReaderApp % App used to open blq file 
        Struct
        MatrizCorriente
        Voltaje
        %Variables coming from mapsPreviewApp
        mapsPreviewApp % App used to show generated maps
        visualizeAppReal  % App used to visualize Real space analysis
        visualizeAppFFT % App used to visualize FFT analysis
        energySymmetry % App used to visualize energy symmetry.

        colormap_list %Table containing all colormap info
        FFT_ContextMenu % Context Menu for FFT Maps button
        FFTswitch   % Selection of which FFT map to open
    end
    
    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Add program folders to Matlab path
            addpath(genpath(fileparts(mfilename('fullpath'))));

            % Load Colormaps
            colormap_listload=load ('colormap_list.mat'); 
            colormap_listload=colormap_listload.colormap_list; %matlab loades the table as an infostruct
            app.colormap_list=colormap_listload;
            app.colormap_list=sortrows(app.colormap_list,{'Prefix','Name'},{'ascend' 'ascend'});

            % Set FFT Context Menu
            app.FFT_ContextMenu = uicontextmenu(app.blqAnalysisUIFigure);
            m1 = uimenu(app.FFT_ContextMenu, 'Label', 'FFT Conductance');

            m2 = uimenu(app.FFT_ContextMenu, 'Label', 'FFT Difference');

            m3 = uimenu(app.FFT_ContextMenu, 'Label', 'FFT Division');

            m4 = uimenu(app.FFT_ContextMenu, 'Label', 'FFT Bogol. Angle');

            % Assign Context Menu to FFT map button
            app.FFTMapsButton.ContextMenu = app.FFT_ContextMenu;

%             genpath('Icons');
%             addpath(genpath('Icons'));         

            app.Symmetry = app.SymmetryDropDown.Value;
            app.AngleSliderLabel.Text = sprintf('Angle: %5.2f º',app.AngleShapeSlider.Value);
            app.SaveType = 2;
            app.New = true;
            
            app.V = true;
            app.H = false;
            
            app.FlattenFlag = [1 0];
            
            app.ReverseColormapReal = false;
            app.ReverseColormapFFT = false;
            app.ShiftColormapReal = false;
            app.ShiftColormapFFT = false;
        end

        % Button pushed function: NewAppButton
        function NewAppButtonPushed(app, event)
            app.blqReaderApp = blqReader(app);
        end

        % Button pushed function: SaveInfoStructButton
        function SaveInfoStructButtonPushed(app, event)
            saveInfo(app, app.Struct)
        end

        % Button pushed function: LoadInfoStructfromfileButton
        function LoadInfoStructfromfileButtonPushed(app, event)
            [File,Path] = uigetfile('*.mat');
            if ~isequal(File,0)
            [Info, Settings] = openInfo(File,Path);
            else
                return
            end
            if ~isempty(Info)
                [app.InfoStruct, app.InfoStructOriginal] = deal(Info);
                app.Struct = Settings;
                app.Struct(1).SaveFolder = Path;
                
                if isfield(app.Struct,'Campo')
                    app.MagneticFieldLabel.Visible = true;
                    app.MagneticFieldValue.Visible = true;
                    app.MagneticFieldValue.Text = [num2str(app.Struct.Campo), ' T'];
                else
                    app.MagneticFieldLabel.Visible = false;
                    app.MagneticFieldValue.Visible = false;
                end
                if isfield(app.Struct,'Temperatura')
                    app.TemperatureLabel.Visible = true;
                    app.TemperatureValue.Visible = true;
                    app.TemperatureValue.Text = [num2str(app.Struct.Temperatura), ' K'];
                else
                    app.TemperatureLabel.Visible = false;
                    app.TemperatureValue.Visible = false;
                end
                if isfield(app.Struct,'FileName')
                    app.CurrentblqLabel.Visible = true;
                    app.CurrentblqName.Visible = true;
                    app.CurrentblqName.Text = app.Struct.FileName;
                else
                    app.CurrentblqLabel.Visible = false;
                    app.CurrentblqName.Visible = false;
                end
                figure(app.blqAnalysisUIFigure)
                msgbox('InfoStruct succesfully loaded.','You are amazing','help')

            else
                figure(app.blqAnalysisUIFigure)
                errordlg('The selected file does not include valid data.', 'Wrong input data')
            end
        end

        % Button pushed function: KeepChangesNewRealButton
        function KeepChangesNewRealButtonPushed(app, event)
            app.InfoStructAnalisis = app.InfoData;
            app.InfoStructAnalisis.MapasConductancia = app.InfoStructAnalisisCopy.MapasConductancia;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
        end

        % Callback function
        function RemoveCentralLineButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = RemoveCentralLine(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis, app.V, app.H);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Button pushed function: MeanConductanceButton
        function MeanConductanceButtonPushed(app, event)
            meanConductance(app.InfoStruct)
        end

        % Button pushed function: VerticalprofileButton
        function VerticalprofileButtonPushed(app, event)
            % verticalProfile(app.InfoStructAnalisis)
            switch app.SymmetryDropDown.Value
                case {'C3','C6'}
                    LatticeType = 'hexagonal';
                case {'C4'}
                    LatticeType = 'square';
                otherwise
                    LatticeType = 'square';
            end
            verticalProfileV2(app.InfoStructAnalisis,Lattice=LatticeType)
        end

        % Button pushed function: HorizontalprofileButton
        function HorizontalprofileButtonPushed(app, event)
            % horizontalProfile(app.InfoStructAnalisis)
            switch app.SymmetryDropDown.Value
                case {'C3','C6'}
                    LatticeType = 'hexagonal';
                case {'C4'}
                    LatticeType = 'square';
                otherwise
                    LatticeType = 'square';
            end
            horizontalProfileV2(app.InfoStructAnalisis,Lattice=LatticeType)
        end

        % Button pushed function: DiagonalprofileButton
        function DiagonalprofileButtonPushed(app, event)
            diagonalProfile(app.InfoStructAnalisis)
        end

        % Button pushed function: ClearInfostructButton
        function ClearInfostructButtonPushed(app, event)
            [app.InfoStruct,app.InfoStructOriginal, app.Struct, app.InfoData,...
                app.InfoStructAnalisis,app.InfoStructAnalisisCopy] = deal([]);
            app.MagneticFieldValue.Text = '';
            app.MagneticFieldLabel.Visible = false;
            app.MagneticFieldValue.Visible = false;
            app.TemperatureValue.Text = '';
            app.TemperatureLabel.Visible = false;
            app.TemperatureValue.Visible = false;
            app.CurrentblqName.Text = '';
            app.CurrentblqLabel.Visible = false;
            app.CurrentblqName.Visible = false;
            figure(app.blqAnalysisUIFigure)
        end

        % Menu selected function: InfoStructtoworkspaceMenu
        function InfoStructtoworkspaceMenuSelected(app, event)
            assignin('base','InfoStruct',app.InfoStruct)
            assignin('base','InfoStructOriginal',app.InfoStructOriginal)
            assignin('base','InfoStructAnalisis',app.InfoStructAnalisis)
            assignin('base','InfoStructAnalisisCopy',app.InfoStructAnalisisCopy)
            assignin('base','InfoData',app.InfoData)
            assignin('base','Struct',app.Struct)
%             assignin('base','fourierImage',app.fourierImage)
        end

        % Value changed function: SymmetryDropDown
        function SymmetryDropDownValueChanged(app, event)
            app.Symmetry = app.SymmetryDropDown.Value;
            switch app.Symmetry
                case {'C3','C6'}
                    msgbox( 'One Bragg peak has to align with the vertical axis!', 'Friendly reminder', 'help')
            end

        end

        % Button pushed function: RealMapsButton
        function RealMapsButtonPushed(app, event)
            if isempty(app.InfoStruct)
                disp('No data has been provided')
                return
            end
            app.InfoStruct.XLimReal = [min(app.InfoStruct.DistanciaColumnas) max(app.InfoStruct.DistanciaColumnas)];
            app.InfoStruct.YLimReal = [min(app.InfoStruct.DistanciaFilas) max(app.InfoStruct.DistanciaFilas)];
            
            app.visualizeAppReal = analyze(app,'Real');
            app.visualizeAppReal.Cell = app.InfoStruct.MapasConductancia;
            app.visualizeAppReal.Axes.Colormap = app.InfoStruct.Colormap;
            initCell(app.visualizeAppReal, app.InfoStruct.MapasConductancia,app.InfoStruct.ContrastReal, true)
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStruct.MapasConductancia, app.InfoStruct, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
            
%             Ratio = (app.visualizeAppReal.Axes.XLim(2) - app.visualizeAppReal.Axes.XLim(1))/(app.visualizeAppReal.Axes.YLim(2) - app.visualizeAppReal.Axes.YLim(1));
%             app.visualizeAppReal.Axes.DataAspectRatio = [1,Ratio,1];
%             app.visualizeAppReal.Axes.PlotBoxAspectRatio = [1,Ratio,1];
            
            app.InfoStruct = app.InfoData;
            app.InfoStructAnalisis = app.InfoData;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
        end

        % Value changed function: ColormapRealDropDownNew
        function ColormapRealDropDownValueChanged(app, event)
            value = app.ColormapRealDropDownNew.Value;

            switch app.colormap_list.Prefix{strcmp(app.colormap_list.Name,value)}
                case 'CET'
                    colorout=colorcet(value);
                case 'sci'
                    colorout=crameri(value);
                case 'oce'
                    colorout=cmocean(value);
                otherwise
                    colorout=eval(value);
            end
            % Obtain shifted colormap matrix for cyclic colormaps
            % if (app.ShiftColormapReal && app.ShiftColormapRealCheckBox.Enable)
            if app.ShiftColormapRealCheckBox.Value
                colorout = circshift(colorout,size(colorout,1)/2);
            end
            % Obtain flipped colormap matrix or the usual one
            if app.ReverseColormapReal
                colorout = flipud(colorout);
            end

%             %Turn the labels visible
%             app.RealColormapLabel.Visible = true;
%             app.RealColormapValue.Visible = true;
%             app.RealColormapValue.Text = value;
%             app.FFTColormapLabel.Visible = true;
%             app.FFTColormapValue.Visible = true;
%             app.FFTColormapValue.Text = value;

            % Assign the colormap to all these fields.
            [app.visualizeAppReal.Axes.Colormap,...
                app.InfoData.Colormap,...
                app.InfoStruct.Colormap,...
                app.InfoStructAnalisis.Colormap,...
                app.InfoStructAnalisisCopy.Colormap] ...
                = deal(colorout);
            [app.visualizeAppReal.ColormapValue.Text,...
                app.InfoData.ColormapName,...
                app.InfoStruct.ColormapName,...
                app.InfoStructAnalisis.ColormapName,...
                app.InfoStructAnalisisCopy.ColormapName] = deal(value);
            app.visualizeAppReal.ColormapLabel.Visible = true;
            app.visualizeAppReal.ColormapValue.Visible = true;
        end

        % Button pushed function: VisualizeRealButton
        function VisualizeRealButtonPushed(app, event)
            app.visualizeAppReal.Cell = app.InfoStructAnalisis.MapasConductancia;
            initCell(app.visualizeAppReal, app.InfoStructAnalisis.MapasConductancia,app.InfoStruct.ContrastReal, true)
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStruct.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Button pushed function: MeanCurrentButton
        function MeanCurrentButtonPushed(app, event)
            meanIV(app.InfoStruct)
        end

        % Button pushed function: SaveContrastFFTButton
        function SaveContrastFFTButtonPushed(app, event)
            assignin('base','ContrastFFT',app.InfoStructAnalisis.ContrastFFT)
        end

        % Button pushed function: LoadContrastFFTButton
        function LoadContrastFFTButtonPushed(app, event)
            Contrast = evalin('base','ContrastFFT');
            app.fourierImage.UserData.ContrastFFT = Contrast;
            app.InfoStruct.ContrastFFT = Contrast;
            app.InfoStructAnalisis.ContrastFFT = Contrast;
            app.InfoStructAnalisisCopy.ContrastFFT = Contrast;
        end

        % Button pushed function: SaveContrastRealButton
        function SaveContrastRealButtonPushed(app, event)
            assignin('base','ContrastReal',app.InfoStructAnalisis.ContrastReal)
        end

        % Button pushed function: LoadContrastRealButton
        function LoadContrastRealButtonPushed(app, event)
            Contrast = evalin('base','ContrastReal');
            app.realImage.UserData.ContrastReal = Contrast;
            app.InfoStruct.ContrastReal = Contrast;
            app.InfoStructAnalisis.ContrastReal = Contrast;
            app.InfoStructAnalisisCopy.ContrastReal = Contrast;
        end

        % Menu selected function: RecalculateFFTMenu
        function RecalculateFFTMenuSelected(app, event)
            app.InfoStructAnalisis = rmfield(app.InfoStructAnalisis,'Transformadas');
            app.InfoStructAnalisisCopy = rmfield(app.InfoStructAnalisisCopy,'Transformadas');
            app.InfoStruct.Transformadas = cellfun(@fft2d,app.InfoStructAnalisis.MapasConductancia, 'UniformOutput', false);
            app.InfoStruct.Fase = cellfun(@fft2dphase,app.InfoStructAnalisis.MapasConductancia, 'UniformOutput', false);

            % Recalculate also distances in FFT space, just in case
            [Rows,Cols] = size(app.InfoStruct.MapasConductancia{1});

            app.InfoStruct.DistanciaFourierColumnas = (1/app.InfoStruct.TamanhoRealColumnas)*(1:1:Cols);
            app.InfoStruct.DistanciaFourierFilas = (1/app.InfoStruct.TamanhoRealFilas)*(1:1:Rows);
            app.InfoStruct.DistanciaFourierColumnas = app.InfoStruct.DistanciaFourierColumnas - ...
                app.InfoStruct.DistanciaFourierColumnas(floor(end/2)+1);
            app.InfoStruct.DistanciaFourierFilas = app.InfoStruct.DistanciaFourierFilas - ...
                app.InfoStruct.DistanciaFourierFilas(floor(end/2)+1);

            msgbox('FFT maps recalculated.',"Don't give up",'help')
            clear Cols Rows
        end

        % Menu selected function: ReinterpolateMapsMenu
        function ReinterpolateMapsMenuSelected(app, event)
            out = reCalculateMaps(app.InfoStruct);
            if ~isempty(fieldnames(out))
                % Replicate into other Struct because I dont know which matter
                [app.InfoData,app.InfoStructAnalisis,app.InfoStructAnalisis,...
                    app.InfoStructAnalisisCopy, app.InfoStruct] = deal(out);
                % Calculate FFT for new maps
            app.RecalculateFFTMenuSelected;
            end

        end

        % Button pushed function: AveragescatteringButton
        function AveragescatteringButtonPushed(app, event)
            averageScattering(app.InfoStructAnalisis)
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            app.NewMenu.Checked = 1;
            app.OldMenu.Checked = 0;
                        
            app.New = true;
        end

        % Menu selected function: OldMenu
        function OldMenuSelected(app, event)
            app.NewMenu.Checked = 0;
            app.OldMenu.Checked = 1;
                        
            app.New = false;
        end

        % Button pushed function: CutasviewButton
        function CutasviewButtonPushed(app, event)
            app.InfoStructAnalisisCopy = cutMatrix(app.InfoStructAnalisis);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Close request function: blqAnalysisUIFigure
        function blqAnalysisUIFigureCloseRequest(app, event)
            delete(app.mapsPreviewApp)
            delete(app.blqReaderApp)
            delete(app.visualizeAppReal) %it only closes the last window, but the previous ones stay open
            delete(app.visualizeAppFFT)
            delete(app.energySymmetry)
            delete(app)
        end

        % Button pushed function: LoadInfoStructfromworkspaceButton
        function LoadInfoStructfromworkspaceButtonPushed(app, event)
%             app.InfoStruct = readInfo();
%             app.InfoStructOriginal = app.InfoStruct;
%             msgbox('InfoStruct succesfully loaded.','You are amazing','help')
            
            [Info,Settings] = readInfo();
            if ~isempty(Info)
                [app.InfoStruct, app.InfoStructOriginal] = deal(Info);
                app.Struct = Settings;
                if isfield(app.Struct,'Campo')
                    app.MagneticFieldLabel.Visible = true;
                    app.MagneticFieldValue.Visible = true;
                    app.MagneticFieldValue.Text = [num2str(app.Struct.Campo), ' T'];
                else
                    app.MagneticFieldLabel.Visible = false;
                    app.MagneticFieldValue.Visible = false;
                end
                if isfield(app.Struct,'Temperatura')
                    app.TemperatureLabel.Visible = true;
                    app.TemperatureValue.Visible = true;
                    app.TemperatureValue.Text = [num2str(app.Struct.Temperatura), ' K'];
                else
                    app.TemperatureLabel.Visible = false;
                    app.TemperatureValue.Visible = false;
                end
                if isfield(app.Struct,'FileName')
                    app.CurrentblqLabel.Visible = true;
                    app.CurrentblqName.Visible = true;
                    app.CurrentblqName.Text = app.Struct.FileName;
                else
                    app.CurrentblqLabel.Visible = false;
                    app.CurrentblqName.Visible = false;
                end
%                 if isfield(app.InfoStruct,'ColormapName')
%                     app.RealColormapLabel.Visible = true;
%                     app.RealColormapValue.Visible = true;
%                     app.RealColormapValue.Text = app.InfoStruct.ColormapName;
%                     app.FFTColormapLabel.Visible = true;
%                     app.FFTColormapValue.Visible = true;
%                     app.FFTColormapValue.Text = app.InfoStruct.ColormapName;
%                 else
%                     app.RealColormapLabel.Visible = false;
%                     app.RealColormapValue.Visible = false;
%                     app.FFTColormapLabel.Visible = false;
%                     app.FFTColormapValue.Visible = false;
%                 end
%                 app.InfoStruct.Colormap = eval(app.ColormapFFTDropDown.Value);
                figure(app.blqAnalysisUIFigure)
                msgbox('InfoStruct succesfully loaded.','You are amazing','help')
            else
                figure(app.blqAnalysisUIFigure)
                errordlg('The variables do not include valid data.', 'Wrong input data')
            end
        end

        % Button pushed function: CutInfoStructButton
        function CutInfoStructButtonPushed(app, event)
            x1 = app.x1EditField.Value;
            x2 = app.x2EditField.Value;
            y1 = app.y1EditField.Value;
            y2 = app.y2EditField.Value;
            %Check for boundaries first
            if x2<=x1 || y2<=y1
                warndlg('Index 2 must be greater than index 1. Please check your inputs.','Don''t make this difficult')
                return
            elseif x2>numel(app.InfoStructOriginal.DistanciaColumnas) || ...
                    y2>numel(app.InfoStructOriginal.DistanciaFilas)
                warndlg('The indexes exceed the size of the matrix.','Try again')
                return
            end
%         else
%                 app.InfoStruct = RecorteQPI(x1,x2,y1,y2,app.InfoStructOriginal);
%                 msgbox('InfoStruct succesfully cut.','Don''t stop now.','help');
%         end
            
%             app.InfoStruct = RecorteQPI(x1,x2,y1,y2,app.InfoStructOriginal);
%             msgbox('InfoStruct succesfully cut.','Don''t stop now.','help');
%             if abs(x2-x1) ~= abs(y2-y1)
% %                 app.InfoStruct = RecorteQPI(x1,x2,y1,y2,app.InfoStructOriginal);
%                 warndlg('The map selected is not square. Some functions might now work as expected','Don''t make this difficult')
%             else
% %                 app.InfoStruct = RecorteQPI(x1,x2,y1,y2,app.InfoStructOriginal);
%                 msgbox('InfoStruct succesfully cut.','Don''t stop now.','help')
%             end
            if app.OnlySquareCheckBox.Value && (x2-x1)~=(y2-y1)
                warndlg('The map selected is not square.','Don''t make this difficult')
                return
            end

            app.InfoStruct = RecorteQPI(x1,x2,y1,y2,app.InfoStructOriginal);
            %We also have to update the Struct!!
            [app.Struct.Filas,app.Struct.Columnas] = size(app.InfoStruct.MapasConductancia{1});
            msgbox('InfoStruct succesfully cut.','Don''t stop now.','help');

        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            app.InfoStruct = app.InfoStructOriginal;
            %We also have to update the Struct!!
            [app.Struct.Filas,app.Struct.Columnas] = size(app.InfoStruct.MapasConductancia{1});
        end

        % Button pushed function: RotateNewButtonReal
        function RotateNewButtonRealPushed(app, event)
            tic
            app.InfoStructAnalisisCopy.MapasConductancia = RotateMatrixv2(app.InfoStructAnalisis.MapasConductancia, app.EditRotateAngleReal.Value);
            if app.ApplyfilterstocurvesMenu.Checked 
            app.InfoStructAnalisisCopy.MatrizCorriente = maps2curves( RotateMatrixv2(curves2maps(app.InfoStructAnalisis.MatrizCorriente,app.InfoStructAnalisis),...
                app.EditRotateAngleReal.Value), app.InfoStructAnalisis );
            app.InfoStructAnalisisCopy.MatrizNormalizada = maps2curves( RotateMatrixv2(curves2maps(app.InfoStructAnalisis.MatrizNormalizada,app.InfoStructAnalisis),...
                app.EditRotateAngleReal.Value), app.InfoStructAnalisis );
            end
            toc
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
%             initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia,app.InfoStruct.ContrastReal, true)
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Button pushed function: KeepChangesNewFFTButton
        function KeepChangesNewFFTButtonPushed(app, event)
            app.InfoStructAnalisis = app.InfoData;
            app.InfoStructAnalisis.Transformadas = app.InfoStructAnalisisCopy.Transformadas;
            if isfield(app.InfoStructAnalisis,'Fase')
                app.InfoStructAnalisis.Fase = app.InfoStructAnalisisCopy.Fase;
            end
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
        end

        % Button pushed function: GaussianFilterNewButtonReal
        function GaussianFilterRealButtonPushed(app, event)
            tic
            % if app.EditGaussianFilterReal.Value > 0
            %     app.InfoStructAnalisisCopy.MapasConductancia = GaussSmooth(app.InfoStructAnalisis.MapasConductancia, app.EditGaussianFilterReal.Value, app.InfoStructAnalisis);
            %     if app.ApplyfilterstocurvesMenu.Checked 
            %         app.InfoStructAnalisisCopy.MatrizCorriente = maps2curves( GaussSmooth(curves2maps(app.InfoStructAnalisis.MatrizCorriente,app.InfoStructAnalisis),...
            %             app.EditGaussianFilterReal.Value, app.InfoStructAnalisis), app.InfoStructAnalisis );
            %         app.InfoStructAnalisisCopy.MatrizNormalizada = maps2curves( GaussSmooth(curves2maps(app.InfoStructAnalisis.MatrizNormalizada,app.InfoStructAnalisis),...
            %             app.EditGaussianFilterReal.Value, app.InfoStructAnalisis), app.InfoStructAnalisis );
            %     end
            % end
            app.InfoStructAnalisisCopy.MapasConductancia = ...
                GaussSmoothV2(app.InfoStructAnalisis.MapasConductancia,...
                app.InfoStructAnalisis.DistanciaFilas,...
                app.InfoStructAnalisis.DistanciaColumnas,...
                app.EditGaussianFilterReal.Value);
            if app.ApplyfilterstocurvesMenu.Checked
                    app.InfoStructAnalisisCopy.MatrizCorriente = maps2curves(...
                            GaussSmoothV2( curves2maps(app.InfoStructAnalisis.MatrizCorriente,app.InfoStructAnalisis),...
                            app.InfoStructAnalisis.DistanciaFilas,...
                            app.InfoStructAnalisis.DistanciaColumnas,...
                            app.EditGaussianFilterReal.Value), ...
                        app.InfoStructAnalisis);
                    app.InfoStructAnalisisCopy.MatrizNormalizada = maps2curves(...
                            GaussSmoothV2( curves2maps(app.InfoStructAnalisis.MatrizNormalizada,app.InfoStructAnalisis),...
                            app.InfoStructAnalisis.DistanciaFilas,...
                            app.InfoStructAnalisis.DistanciaColumnas,...
                            app.EditGaussianFilterReal.Value), ...
                        app.InfoStructAnalisis);
                
            end
            toc
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
%             initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia,app.InfoStruct.ContrastReal, true)
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Button pushed function: FFTMapsButton
        function FFTMapsButtonPushed(app, event)
            if isempty(app.InfoStruct)
                disp('No data has been provided')
                return
            end
            app.InfoStruct.XLimFFT = [min(app.InfoStruct.DistanciaFourierColumnas) max(app.InfoStruct.DistanciaFourierColumnas)];
            app.InfoStruct.YLimFFT = [min(app.InfoStruct.DistanciaFourierFilas) max(app.InfoStruct.DistanciaFourierFilas)];
            
            app.visualizeAppFFT = analyze(app,'FFT');
            app.visualizeAppFFT.Cell = app.InfoStruct.Transformadas;
            app.visualizeAppFFT.Axes.Colormap = app.InfoStruct.Colormap;
            [app.visualizeAppFFT.MinSlider.Limits,...
                app.visualizeAppFFT.MaxSlider.Limits]= deal(app.InfoStruct.ContrastFFT(:,1));
            initCell(app.visualizeAppFFT, app.InfoStruct.Transformadas,app.InfoStruct.ContrastFFT, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStruct.Transformadas, app.InfoStruct, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
            
%             Ratio = (app.visualizeAppFFT.Axes.XLim(2) - app.visualizeAppFFT.Axes.XLim(1))/(app.visualizeAppFFT.Axes.YLim(2) - app.visualizeAppFFT.Axes.YLim(1));
%             app.visualizeAppFFT.Axes.DataAspectRatio = [1,Ratio,1];
%                 
            app.InfoStruct = app.InfoData;
            app.InfoStructAnalisis = app.InfoData;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
        end

        % Value changed function: ColormapFFTDropDown
        function ColormapFFTDropDownValueChanged(app, event)
            value = app.ColormapFFTDropDown.Value;
            switch app.colormap_list.Prefix{strcmp(app.colormap_list.Name,value)}
                case 'CET'
                    colorout=colorcet(value);
                case 'sci'
                    colorout=crameri(value);
                case 'oce'
                    colorout=cmocean(value);
                otherwise
                    colorout=eval(value);
            end
            % Obtain shifted colormap matrix for cyclic colormaps
            % if (app.ShiftColormapFFT && app.ShiftColormapFFTCheckBox.Enable)
            if app.ShiftColormapFFTCheckBox.Value
                colorout = circshift(colorout,size(colorout,1)/2);
            end
            % Obtain flipped colormap matrix or the usual one
            if app.ReverseColormapFFT
                colorout = flipud(colorout);
            end

%             %Turn the labels visible
%             app.RealColormapLabel.Visible = true;
%             app.RealColormapValue.Visible = true;
%             app.RealColormapValue.Text = value;
%             app.FFTColormapLabel.Visible = true;
%             app.FFTColormapValue.Visible = true;
%             app.FFTColormapValue.Text = value;

            % Assign the colormap to all these fields.
            [app.visualizeAppFFT.Axes.Colormap,...
                app.InfoData.Colormap,...
                app.InfoStruct.Colormap,...
                app.InfoStructAnalisis.Colormap,...
                app.InfoStructAnalisisCopy.Colormap] ...
                = deal(colorout);
            [app.visualizeAppFFT.ColormapValue.Text,...
                app.InfoData.ColormapName,...
                app.InfoStruct.ColormapName,...
                app.InfoStructAnalisis.ColormapName,...
                app.InfoStructAnalisisCopy.ColormapName] = deal(value);
            app.visualizeAppFFT.ColormapValue.Visible = true;
            app.visualizeAppFFT.ColormapLabel.Visible = true;
        end

        % Button pushed function: VisualizeFFTButton
        function VisualizeFFTButtonPushed(app, event)
            app.visualizeAppFFT.Cell = app.InfoStructAnalisis.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisis.Transformadas,app.InfoStruct.ContrastFFT, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: RotateNewButtonFFT
        function RotateNewButtonFFTPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = RotateMatrixFFT(app.InfoStructAnalisis.Transformadas, app.EditRotateAngleFFT.Value);
            if isfield(app.InfoStructAnalisis,'Fase')
                app.InfoStructAnalisisCopy.Fase = RotateMatrixFFT(app.InfoStructAnalisis.Fase, app.EditRotateAngleFFT.Value);
            end
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,app.InfoStruct.ContrastFFT, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: RemoveCentralLineButton
        function RemoveCentralLineButtonPushed2(app, event)
            app.InfoStructAnalisisCopy.Transformadas = RemoveCentralLine(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis, app.V, app.H);
            if isfield(app.InfoStructAnalisis,'Fase')
                app.InfoStructAnalisisCopy.Fase = RemoveCentralLine(app.InfoStructAnalisis.Fase, app.InfoStructAnalisis, app.V, app.H);
            end
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,app.InfoStruct.ContrastFFT, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: SymmetrizeButton
        function SymmetrizeButtonPushed(app, event)
            disp(app.Symmetry)
            if ~app.New
                app.InfoStructAnalisisCopy.Transformadas = ...
                    simetrizarFFT_Automatico(app.InfoStructAnalisis.Transformadas, 0, app.Symmetry,app.New);
            else
                app.InfoStructAnalisisCopy.Transformadas = ...
                    simetrizarFFTv2(app.InfoStructAnalisis.Transformadas, app.Symmetry);
%                 %Esto no esta bien, no deberiamos aplicarselo tambien a
%                 la fase
%                 if isfield(app.InfoStructAnalisis,'Fase')
%                     app.InfoStructAnalisisCopy.Fase = ...
%                         simetrizarFFTv2(app.InfoStructAnalisis.Fase, app.Symmetry);
%                 end
            end
            
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,app.InfoStruct.ContrastFFT, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: GaussianFilterNewButtonFFT
        function GaussianFilterFFTButtonPushed(app, event)
            
            
            % app.InfoStructAnalisisCopy.Transformadas = ...
            %     GaussSmooth(app.InfoStructAnalisis.Transformadas, app.EditGaussianFilterFFT.Value, app.InfoStructAnalisis);
            % if isfield(app.InfoStructAnalisis,'Fase')
            %     app.InfoStructAnalisisCopy.Fase = ...
            %         GaussSmooth(app.InfoStructAnalisis.Fase, app.EditGaussianFilterFFT.Value, app.InfoStructAnalisis);
            % end
            app.InfoStructAnalisisCopy.Transformadas = ...
                GaussSmoothV2(app.InfoStructAnalisis.Transformadas,...
                app.InfoStructAnalisis.DistanciaFourierFilas,...
                app.InfoStructAnalisis.DistanciaFourierColumnas,...
                app.EditGaussianFilterFFT.Value);
            if isfield(app.InfoStructAnalisis,'Fase')
                app.InfoStructAnalisisCopy.Fase = ...
                    GaussSmoothV2(app.InfoStructAnalisis.Fase,...
                    app.InfoStructAnalisis.DistanciaFourierFilas,...
                    app.InfoStructAnalisis.DistanciaFourierColumnas,...
                    app.EditGaussianFilterFFT.Value);
            end
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,app.InfoStruct.ContrastFFT, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: AdjustZeroButton
        function AdjustZeroButtonPushed(app, event)
            [app.InfoStructAnalisisCopy.Transformadas,  app.InfoStructAnalisisCopy] = adjustZero(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis);
            
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,app.InfoStruct.ContrastFFT, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: SaveallFFTButton
        function SaveallFFTButtonPushed(app, event)
            Path = uigetdir;
            if Path ~= 0 
                exportCellMaps(app.InfoStructAnalisis.Transformadas,...
                    app.InfoStructAnalisis,app.visualizeAppFFT.isReal,...
                    app.SaveType,Path,Prefix='FFT_')
                if app.SaveType == 3
                    msgbox('Image file succesfully saved.','Follow your dreams','help')
                elseif app.SaveType == 4
                    msgbox('Video file succesfully saved.','Follow your dreams','help')
                elseif app.SaveType == 5
                    msgbox('GIF file succesfully saved.','Follow your dreams','help')
                elseif app.SaveType < 3
                    msgbox([num2str(length(app.InfoStruct.Energia)),...
                        ' png files succesfully saved.'],'Follow your dreams','help')
                end
            end
        end

        % Button pushed function: SaveallRealButton
        function SaveallRealButtonPushed(app, event)
            Path = uigetdir;
            if Path ~= 0 
                exportCellMaps(app.InfoStructAnalisis.MapasConductancia,...
                    app.InfoStructAnalisis,app.visualizeAppReal.isReal,...
                    app.SaveType,Path,Prefix='Real_')
                if app.SaveType == 3
                    msgbox('Image file succesfully saved.','Follow your dreams','help')
                elseif app.SaveType == 4
                    msgbox('Video file succesfully saved.','Follow your dreams','help')
                elseif app.SaveType == 5
                    msgbox('GIF file succesfully saved.','Follow your dreams','help')
                elseif app.SaveType < 3
                    msgbox([num2str(length(app.InfoStruct.Energia)),...
                        ' png files succesfully saved.'],'Follow your dreams','help')
                end
            end
        end

        % Value changed function: FlattenDropDown
        function FlattenDropDownValueChanged(app, event)
            value = app.FlattenDropDown.Value;
            
            if strcmp(value,'Horizontal')
                app.FlattenFlag = [1 0];
            elseif strcmp(value,'Vertical')
                app.FlattenFlag = [0 1];
            elseif strcmp(value,'Both')
                app.FlattenFlag = [1 1];
            end
        end

        % Button pushed function: FlattenButton
        function FlattenButtonPushed(app, event)
            app.InfoStructAnalisisCopy.MapasConductancia = FlattenCell(app.InfoStructAnalisis.MapasConductancia, app.FlattenFlag, app.InfoStructAnalisis);


            if app.ApplyfilterstocurvesMenu.Checked
                % app.InfoStructAnalisisCopy.MatrizCorriente = maps2curves( popcorn(curves2maps(app.InfoStructAnalisis.MatrizCorriente,app.InfoStructAnalisis),...
                %     app.SigmaEditField.Value, app.InfoStructAnalisis), app.InfoStructAnalisis );
                % app.InfoStructAnalisisCopy.MatrizNormalizada = maps2curves( popcorn(curves2maps(app.InfoStructAnalisis.MatrizNormalizada,app.InfoStructAnalisis),...
                %     app.SigmaEditField.Value, app.InfoStructAnalisis), app.InfoStructAnalisis );
                app.InfoStructAnalisisCopy.MatrizNormalizada = maps2curves(...
                    FlattenCell( curves2maps(app.InfoStructAnalisis.MatrizNormalizada,app.InfoStructAnalisis),...
                    app.FlattenFlag, app.InfoStructAnalisis),app.InfoStructAnalisis);

            end

            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
            initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia,app.InfoStruct.ContrastReal, true)
            app.visualizeAppReal.k
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Button pushed function: CalculateGapMapButton
        function CalculateGapMapButtonPushed(app, event)
            app.InfoStruct = gapMap(app.InfoStructAnalisis,app.RangePeaksmVEditField.Value,app.ThresholdratioEditField.Value,app.NumDerivEditField.Value,app.MaxDerivativeEditField.Value);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            if app.GaussianfilterEditField.Value>0
                MapaGap = imgaussfilt(app.InfoStruct.MapaGap,app.GaussianfilterEditField.Value);
            else
                MapaGap = app.InfoStruct.MapaGap;
            end
            
            fig = figure;
            imagesc(app.InfoStruct.DistanciaColumnas,app.InfoStruct.DistanciaFilas,MapaGap);
            fig.Children.YDir = 'normal';
            fig.Children.CLim = [app.ContrastMinEditField.Value app.ContrastMaxEditField.Value];
            % colormap jet
            % axis square
            fig.Children.DataAspectRatio = [1 1 1];
            
            assignin("base",'MapaGap', MapaGap)
        end

        % Button pushed function: FFTWarpCorrectionButton
        function WarpCorrectionFFTButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = WarpMatrixv2(app.InfoStructAnalisis.Transformadas, app.XShearwarpEditField.Value, app.YShearwarpEditField.Value, app.YXRatioEditField.Value, app.InfoStructAnalisis);
            
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,app.InfoStruct.ContrastFFT, false)
            
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);

        end

        % Button pushed function: PopcornButton
        function PopcornButtonPushed(app, event)
            tic
            app.InfoStructAnalisisCopy.MapasConductancia = popcorn(app.InfoStructAnalisis.MapasConductancia, app.SigmaEditField.Value, app.InfoStructAnalisis);
            if app.ApplyfilterstocurvesMenu.Checked
                app.InfoStructAnalisisCopy.MatrizCorriente = maps2curves( popcorn(curves2maps(app.InfoStructAnalisis.MatrizCorriente,app.InfoStructAnalisis),...
                    app.SigmaEditField.Value, app.InfoStructAnalisis), app.InfoStructAnalisis );
                app.InfoStructAnalisisCopy.MatrizNormalizada = maps2curves( popcorn(curves2maps(app.InfoStructAnalisis.MatrizNormalizada,app.InfoStructAnalisis),...
                    app.SigmaEditField.Value, app.InfoStructAnalisis), app.InfoStructAnalisis );
            end
            toc
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
%             initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia,app.InfoStruct.ContrastReal, true)
            app.visualizeAppReal.k
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Button pushed function: GeneratemapsButton
        function GeneratemapsButtonPushed(app, event)
            %Solo funciona si acabamos de generar el struct, pero no si lo
            %volvemos a cargar desde un archivo previo
            if isfield(app.Struct,'SaveFolder')
                app.Struct.MatrizCorriente = app.MatrizCorriente;
                [app.Struct] = analyzeImages(app.Struct);
                maps = num2str(length(app.Struct.Energia));
                uiwait(msgbox([maps ' maps succesfully generated'], 'You are almost there', 'help'))
                app.mapsPreviewApp = mapsPreview(app, app.Struct);
            else
                uiwait(msgbox('Maps cannot be generated from a previously saved file','This is going nowhere','warn'))
            end
        end

        % Button pushed function: PlaneButton
        function PlaneButtonPushed(app, event)
            app.InfoStructAnalisisCopy.MapasConductancia = planeCell(app.InfoStructAnalisis.MapasConductancia, app.InfoStructAnalisis);
            
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
            initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia,app.InfoStruct.ContrastReal, true)
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Button pushed function: SquareFilterButton
        function SquareFilterButtonPushed(app, event)
            tic
            app.InfoStructAnalisisCopy.MapasConductancia = squareFilter(app.InfoStructAnalisis.MapasConductancia, app.InfoStructAnalisis, app.SquareSpinner.Value);
            if app.ApplyfilterstocurvesMenu.Checked 
                app.InfoStructAnalisisCopy.MatrizCorriente = maps2curves( squareFilter(curves2maps(app.InfoStructAnalisis.MatrizCorriente,app.InfoStructAnalisis),...
                    app.InfoStructAnalisis, app.SquareSpinner.Value), app.InfoStructAnalisis );
                app.InfoStructAnalisisCopy.MatrizNormalizada = maps2curves( squareFilter(curves2maps(app.InfoStructAnalisis.MatrizNormalizada,app.InfoStructAnalisis),...
                    app.InfoStructAnalisis, app.SquareSpinner.Value), app.InfoStructAnalisis );
            end
            toc
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
%             initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia,app.InfoStruct.ContrastReal, true)
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Button pushed function: SquareFilterFFTButton
        function SquareFilterFFTButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = squareFilter(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis, app.SquareSpinnerFFT.Value);
            if isfield(app.InfoStructAnalisis,'Fase')
                app.InfoStructAnalisisCopy.Fase = ...
                    squareFilter(app.InfoStructAnalisis.Fase, app.InfoStructAnalisis, app.SquareSpinnerFFT.Value);
            end
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,app.InfoStruct.ContrastFFT, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: GaussiancoresubstractionButton
        function GaussiancoresubstractionButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = ...
                gaussCoreSubstractionV2(app.InfoStructAnalisis.Transformadas,...
                app.InfoStructAnalisis.DistanciaFourierFilas,...
                app.InfoStructAnalisis.DistanciaFourierColumnas,...
                app.SigmaCoreEditField.Value);
            %We don't need to apply it to the fase data in this case
%             if isfield(app.InfoStructAnalisis,'Fase')
%                 app.InfoStructAnalisisCopy.Fase = ...
%                     gaussCoreSubstraction(app.InfoStructAnalisis.Fase, app.SigmaCoreEditField.Value);
%             end

            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,app.InfoStruct.ContrastFFT, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Value changed function: ReverseColormapFFTCheckBox
        function ReverseColormapFFTCheckBoxValueChanged(app, event)
            value = app.ReverseColormapFFTCheckBox.Value;
            
            app.ReverseColormapFFT = value;
            app.ColormapFFTDropDownValueChanged;
        end

        % Value changed function: ReverseColormapRealCheckBox
        function ReverseColormapRealCheckBoxValueChanged(app, event)
            value = app.ReverseColormapRealCheckBox.Value;
            
            app.ReverseColormapReal = value;
            app.ColormapRealDropDownValueChanged;
        end

        % Button pushed function: qanisotropyButton
        function qanisotropyButtonPushed(app, event)
            qanisotropy(app.InfoStructAnalisis,app.qanisotropyEditField.Value)  
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            histogramMap(app.InfoStructAnalisis,app.visualizeAppReal.k)
        end

        % Button pushed function: AngleCorrelationButton
        function AngleCorrelationButtonPushed(app, event)
%             isinstalled = which('autocorr_stat');
%             if ~isempty(which('autocorr_stat')) && ~isempty(which('average_ACF'))
            %We now default to the included autocorr_builtin using the
            % function corr2 included in matlab. It's faster than autocorr_stat
            % we still depend on the external function average_ACF at the
            % moment
            if ~isempty(which('average_ACF'))
                angleCorrelationv2(app.InfoStructAnalisis,app.visualizeAppReal.k)
            else
                warning(['Using old built-in method. This method is significantly slower\n',...
                'Make sure <a href="https://github.com/LowTemperaturesUAM/ACF-MatCL">ACF-MatCL</a> is installed and included in Matlab''s Path'],'')
                angleCorrelation(app.InfoStructAnalisis,app.visualizeAppReal.k)
            end
        end

        % Value changed function: SaveAllFFTDropDown
        function SaveAllFFTDropDownValueChanged(app, event)
            app.SaveType = str2double (app.SaveAllFFTDropDown.Value);
            app.SaveAllRealDropDown.Value = app.SaveAllFFTDropDown.Value;            
        end

        % Value changed function: SaveAllRealDropDown
        function SaveAllRealDropDownValueChanged(app, event)
            app.SaveType = str2double (app.SaveAllRealDropDown.Value);
            app.SaveAllFFTDropDown.Value = app.SaveAllRealDropDown.Value;
        end

        % Value changed function: ColormapRealDropDownNewCategory
        function ColormapRealDropDownNewCategoryValueChanged(app, event)
            value = app.ColormapRealDropDownNewCategory.Value;
            switch value
                case 'Most Used'
                    app.ColormapRealDropDownNew.Items={'parula','viridis','plasma','magma','inferno','bone','gray'};
                    app.ColormapRealDropDownNew.ItemsData={'parula','viridis','plasma','magma','inferno','bone','gray'};
                case 'Sequential'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Sequential);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Sequential);
                case 'Diverging'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Diverging);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Diverging);
                case 'Cyclic'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Cyclic);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Cyclic);
                case 'Misc'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Misc);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Misc);
                case 'Categorical'
                    msgbox('This maps are meant to be used for discrete values of data','Use carefully','warn')
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Categorical);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Categorical);
                case 'Uniform Sequential'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Sequential);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Sequential);
                case 'Uniform Diverging'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Diverging);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Diverging);
                case 'Uniform Cyclic'
                    app.ColormapRealDropDownNew.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Cyclic);
                    app.ColormapRealDropDownNew.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Cyclic);
            end
            switch app.ColormapRealDropDownNewCategory.Value
                case {'Cyclic','Uniform Cyclic'}
                    app.ShiftColormapRealCheckBox.Visible = true;
                    app.ShiftColormapRealCheckBox.Enable = true;
                otherwise
                    app.ShiftColormapRealCheckBox.Visible = false;
                    app.ShiftColormapRealCheckBox.Enable = false;
            end
            app.ColormapRealDropDownValueChanged;
        end

        % Value changed function: ShiftColormapRealCheckBox
        function ShiftColormapRealCheckBoxValueChanged(app, event)
            % value = app.ShiftColormapRealCheckBox.Value;
            
            % app.ShiftColormapReal = value;
            app.ColormapRealDropDownValueChanged;
        end

        % Value changed function: ShiftColormapFFTCheckBox
        function ShiftColormapFFTCheckBoxValueChanged(app, event)
            % value = app.ShiftColormapFFTCheckBox.Value;
            
            % app.ShiftColormapFFT = value;
            app.ColormapFFTDropDownValueChanged;
        end

        % Value changed function: ColormapFFTDropDownCategory
        function ColormapFFTDropDownCategoryValueChanged(app, event)
            value = app.ColormapFFTDropDownCategory.Value;
             switch value 
                case 'Most Used'
                    app.ColormapFFTDropDown.Items={'parula','viridis','plasma','magma','inferno','bone','gray'};
                    app.ColormapFFTDropDown.ItemsData={'parula','viridis','plasma','magma','inferno','bone','gray'};
                case 'Sequential'
                    app.ColormapFFTDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Sequential);
                    app.ColormapFFTDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Sequential);
                case 'Diverging'
                    app.ColormapFFTDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Diverging);
                    app.ColormapFFTDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Diverging);
                case 'Cyclic'
                    app.ColormapFFTDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Cyclic);
                    app.ColormapFFTDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Cyclic);
                case 'Misc'
                    app.ColormapFFTDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Misc);
                    app.ColormapFFTDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Misc);
                case 'Categorical'
                    msgbox('This maps are meant to be used for discrete values of data','Use carefully','warn')
                    app.ColormapFFTDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Categorical);
                    app.ColormapFFTDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Categorical);
                case 'Uniform Sequential'
                    app.ColormapFFTDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Sequential);
                    app.ColormapFFTDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Sequential);
                case 'Uniform Diverging'
                    app.ColormapFFTDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Diverging);
                    app.ColormapFFTDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Diverging);
                case 'Uniform Cyclic'
                    app.ColormapFFTDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Cyclic);
                    app.ColormapFFTDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Cyclic);
            end 
            switch app.ColormapFFTDropDownCategory.Value
                case {'Cyclic','Uniform Cyclic'}
                    app.ShiftColormapFFTCheckBox.Visible = true;
                    app.ShiftColormapFFTCheckBox.Enable = true;
                otherwise
                    app.ShiftColormapFFTCheckBox.Visible = false;
                    app.ShiftColormapFFTCheckBox.Enable = false;
                    % app.ShiftColormapFFT = False;
            end

            app.ColormapFFTDropDownValueChanged;
        end

        % Button pushed function: EnergySymmetryButton
        function EnergySymmetryButtonPushed(app, event)
            app.energySymmetry = EnergySym(app);

        end

        % Value changed function: ColormapRestasDropDown
        function ColormapRestasDropDownValueChanged(app, event)
            
%             app.RealAxes.Colormap,...
%              app.ResultAxes.Colormap,...

             value = app.ColormapRestasDropDown.Value;

            switch app.colormap_list.Prefix{strcmp(app.colormap_list.Name,value)}
                case 'CET'
                    colorout=colorcet(value);
                case 'sci'
                    colorout=crameri(value);
                case 'oce'
                    colorout=cmocean(value);
                otherwise
                    colorout=eval(value);
            end
            % Obtain shifted colormap matrix for cyclic colormaps
%             if (app.ShiftColormapReal & app.ShiftColormapRealCheckBox.Enable)
%                 colorout = circshift(colorout,size(colorout,1)/2);
%             end
            % Obtain flipped colormap matrix or the usual one
            if app.ReverseColormapRestasCheckBox.Value
                colorout = flipud(colorout);
            end

            % Assign the colormap to all these fields.
            [app.energySymmetry.ResultAxes.Colormap]...
                = deal(colorout);
        end

        % Value changed function: ColormapRestasDropDownCategory
        function ColormapRestasDropDownCategoryValueChanged(app, event)
            value = app.ColormapRestasDropDownCategory.Value;
            switch value
                case 'Most Used'
                    app.ColormapRestasDropDown.Items={'parula','viridis','plasma','magma','inferno','bone','gray'};
                    app.ColormapRestasDropDown.ItemsData={'parula','viridis','plasma','magma','inferno','bone','gray'};
                case 'Sequential'
                    app.ColormapRestasDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Sequential);
                    app.ColormapRestasDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Sequential);
                case 'Diverging'
                    app.ColormapRestasDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Diverging);
                    app.ColormapRestasDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Diverging);
                case 'Cyclic'
                    app.ColormapRestasDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Cyclic);
                    app.ColormapRestasDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Cyclic);
                case 'Misc'
                    app.ColormapRestasDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Misc);
                    app.ColormapRestasDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Misc);
                case 'Categorical'
                    msgbox('This maps are meant to be used for discrete values of data','Use carefully','warn')
                    app.ColormapRestasDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Categorical);
                    app.ColormapRestasDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Categorical);
                case 'Uniform Sequential'
                    app.ColormapRestasDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Sequential);
                    app.ColormapRestasDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Sequential);
                case 'Uniform Diverging'
                    app.ColormapRestasDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Diverging);
                    app.ColormapRestasDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Diverging);
                case 'Uniform Cyclic'
                    app.ColormapRestasDropDown.Items=app.colormap_list.DisplayName(app.colormap_list.Uniform & app.colormap_list.Cyclic);
                    app.ColormapRestasDropDown.ItemsData=app.colormap_list.Name(app.colormap_list.Uniform & app.colormap_list.Cyclic);
            end
%             switch app.ColormapRestasDropDownCategory.Value
%                 case {'Cyclic','Uniform Cyclic'}
%                     app.ShiftColormapRealCheckBox.Visible = true;
%                     app.ShiftColormapRealCheckBox.Enable = true;
%                 otherwise
%                     app.ShiftColormapRealCheckBox.Visible = false;
%                     app.ShiftColormapRealCheckBox.Enable = false;
%                     % app.ShiftColormapReal = False;
%             end
            app.ColormapRestasDropDownValueChanged;
        end

        % Value changed function: ReverseColormapRestasCheckBox
        function ReverseColormapRestasCheckBoxValueChanged(app, event)
            app.ColormapRestasDropDownValueChanged;
        end

        % Menu selected function: ApplyfilterstocurvesMenu
        function ApplyfilterstocurvesMenuSelected(app, event)
                app.ApplyfilterstocurvesMenu.Checked = ~app.ApplyfilterstocurvesMenu.Checked;
        end

        % Button pushed function: RotatePlus90Btn
        function RotatePlus90BtnButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = RotateMatrixFFT(app.InfoStructAnalisis.Transformadas,90);
            if isfield(app.InfoStructAnalisis,'Fase')
                app.InfoStructAnalisisCopy.Fase = RotateMatrixFFT(app.InfoStructAnalisis.Fase,90);
            end
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,app.InfoStruct.ContrastFFT, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: RotateMinus90Btn
        function RotateMinus90BtnButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = RotateMatrixFFT(app.InfoStructAnalisis.Transformadas,-90);
            if isfield(app.InfoStructAnalisis,'Fase')
                app.InfoStructAnalisisCopy.Fase = RotateMatrixFFT(app.InfoStructAnalisis.Fase, -90);
            end
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,app.InfoStruct.ContrastFFT, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);      
        end

        % Value changed function: RmCentralLineDropDown
        function RmCentralLineDropDownValueChanged(app, event)
            value = app.RmCentralLineDropDown.Value;
            switch value
                case 'Vertical'
                    app.V = true;
                    app.H = false;
                case 'Horizontal'
                    app.V = false;
                    app.H = true;
                case 'Both'
                    app.V = true;
                    app.H = true;
            end
        end

        % Button pushed function: RescaleButton
        function RescaleButtonPushed(app, event)
            scale = app.ScaleFactor.Value;
            app.InfoStructAnalisisCopy.DistanciaFourierColumnas = ...
                scale * app.InfoStructAnalisisCopy.DistanciaFourierColumnas;
            app.InfoStructAnalisisCopy.DistanciaFourierFilas = ...
                scale * app.InfoStructAnalisisCopy.DistanciaFourierFilas;
            app.InfoStructAnalisisCopy.XLimFFT = ...
                scale * app.InfoStructAnalisisCopy.XLimFFT;
            app.InfoStructAnalisisCopy.YLimFFT = ...
                scale * app.InfoStructAnalisisCopy.YLimFFT;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,app.InfoStruct.ContrastFFT, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
        end

        % Button pushed function: MedianFilter
        function MedianFilterPushed(app, event)
            tic
            app.InfoStructAnalisisCopy.MapasConductancia = MedianCell(app.InfoStructAnalisis.MapasConductancia,app.MedianWindow.Value);
            if app.ApplyfilterstocurvesMenu.Checked
                app.InfoStructAnalisisCopy.MatrizCorriente = maps2curves(...
                    MedianCell(curves2maps(app.InfoStructAnalisis.MatrizCorriente,app.InfoStructAnalisis),...
                    app.MedianWindow.Value),...
                    app.InfoStructAnalisis );
                app.InfoStructAnalisisCopy.MatrizNormalizada = maps2curves(...
                    MedianCell(curves2maps(app.InfoStructAnalisis.MatrizNormalizada,app.InfoStructAnalisis),...
                    app.MedianWindow.Value),...
                    app.InfoStructAnalisis );
            end
            toc
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
%             initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia,app.InfoStruct.ContrastReal, true)
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);
        end

        % Value changed function: OnlySquareCheckBox
        function OnlySquareCheckBoxValueChanged(app, event)
            value = app.OnlySquareCheckBox.Value;
            if ~value
                answer = questdlg(sprintf(['Are you sure you want to allow rectangular matrices?\n' ...
                    'Some functions might not return the correct result']),...
                    'Rectangular matrices','Yes','Cancel','Cancel');
                switch answer
                    case {'Cancel',''}
                        %Revert the checkbox to enable
                        app.OnlySquareCheckBox.Value = true;
                end
            end
        end

        % Button pushed function: WarpCorrectionRealBtn
        function WarpCorrectionRealButtonPushed(app, event)
            app.InfoStructAnalisisCopy.MapasConductancia = ...
                WarpRealMatrix(app.InfoStructAnalisis.MapasConductancia,...
                app.XShearwarpReal.Value,app.YShearwarpReal.Value,...
                app.YXRatioReal.Value,app.InfoStructAnalisis);
            %Apply the warping to the curves as well
            app.InfoStructAnalisisCopy.MatrizCorriente = maps2curves(...
                WarpRealMatrix(...
                curves2maps(app.InfoStructAnalisis.MatrizCorriente,...
                app.InfoStructAnalisis),...
                app.XShearwarpReal.Value, app.YShearwarpReal.Value,...
                app.YXRatioReal.Value, app.InfoStructAnalisis),...
                app.InfoStructAnalisis);

            app.InfoStructAnalisisCopy.MatrizNormalizada = maps2curves(...
                WarpRealMatrix(...
                curves2maps(app.InfoStructAnalisis.MatrizNormalizada,...
                app.InfoStructAnalisis),...
                app.XShearwarpReal.Value, app.YShearwarpReal.Value,...
                app.YXRatioReal.Value, app.InfoStructAnalisis),...
                app.InfoStructAnalisis);

            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
%             initCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia,app.InfoStruct.ContrastReal, true)
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);

        end

        % Menu selected function: EnableCompression
        function EnableCompressionMenuSelected(app, event)
            app.EnableCompression.Checked = ~app.EnableCompression.Checked; 
        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            app.InfoStructAnalisisCopy.MapasConductancia = normalizeCell(app.InfoStructAnalisis.MapasConductancia);
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);

        end

        % Button pushed function: SubstractAvgCurveButton
        function SubstractAvgCurveButtonPushed(app, event)
            %% we could additionally apply a gaussian filter before substracting
            app.InfoStructAnalisisCopy.MapasConductancia = ...
                cellfun(@(x) x - mean(x,"all"),app.InfoStructAnalisis.MapasConductancia,UniformOutput=false);
            % We should recalculate the limits, as the maps are now
            % centered at zero.
            if app.ApplyfilterstocurvesMenu.Checked
                app.InfoStructAnalisisCopy.MatrizCorriente = ...
                    app.InfoStructAnalisis.MatrizCorriente - ...
                    mean(app.InfoStructAnalisis.MatrizCorriente,2,"omitnan");
                app.InfoStructAnalisisCopy.MatrizNormalizada = ...
                    app.InfoStructAnalisis.MatrizNormalizada - ...
                    mean(app.InfoStructAnalisis.MatrizNormalizada,2,"omitnan");
            end
            [app.InfoData] = showCell(app.visualizeAppReal, app.InfoStructAnalisisCopy.MapasConductancia, app.InfoStructAnalisisCopy, app.visualizeAppReal.k, app.visualizeAppReal.Axes);

        end

        % Button pushed function: AngleprofileButton
        function AngleprofileButtonPushed(app, event)
            switch app.SymmetryDropDown.Value
                case {'C3','C6'}
                    LatticeType = 'hexagonal';
                case {'C4'}
                    LatticeType = 'square';
                otherwise
                    LatticeType = 'square';
            end
            angleProfile(app.InfoStructAnalisis,app.EditProfileAngle.Value,...
                Lattice=LatticeType);
        end

        % Button pushed function: SecondDerivButton
        function SecondDerivButtonPushed(app, event)
            secondDeriv(app.SecondDerivSpin.Value,app.InfoStructAnalisis)
        end

        % Button pushed function: FlattenTopoBtn
        function FlattenTopoBtnButtonPushed(app, event)
            if isfield(app.InfoStruct,'Topo')
                switch app.FlattenTopoMode.Value
                    case 'Offset'
                        FlatTopo = FlattenMask(app.InfoStructAnalisis.Topo,...
                            direction = app.FlattenTopoDirection.Value,...
                            KeepOffset=false);
                    case 'Line'
                        FlatTopo = FlattenLinear(app.InfoStructAnalisis.Topo,...
                            direction = app.FlattenTopoDirection.Value,...
                            KeepOffset=false);
                    case 'Parabola'
                        FlatTopo = FlattenParabola(app.InfoStructAnalisis.Topo,...
                            direction = app.FlattenTopoDirection.Value,...
                            KeepOffset=false);
                end
                FlatTopo = FlatTopo - min(FlatTopo,[],'all');
                app.InfoData.Topo = FlatTopo;
            end
        end

        % Button pushed function: PlaneTopoBtn
        function PlaneTopoBtnButtonPushed(app, event)
            if isfield(app.InfoStruct,'Topo')
                FlatTopo = planeMask(app.InfoStructAnalisis.Topo,...
                    KeepOffset=false);
                FlatTopo = FlatTopo - min(FlatTopo,[],'all');
                app.InfoData.Topo = FlatTopo;
            end
        end

        % Button pushed function: ParabolaTopoBtn
        function ParabolaTopoBtnButtonPushed(app, event)
            if isfield(app.InfoStruct,'Topo')
                FlatTopo = parabolaMask(app.InfoStructAnalisis.Topo,...
                    KeepOffset=false);
                FlatTopo = FlatTopo - min(FlatTopo,[],'all');
                app.InfoData.Topo = FlatTopo;
            end
        end

        % Button pushed function: RemoveLinesTopo
        function RemoveLinesTopoButtonPushed(app, event)
            %convert the input to number an validate it
            [input,isvalid] = str2num(app.RemoveLinesInput.Value,Evaluation="restricted");
            if isfield(app.InfoStruct,'Topo') && isvalid
                if isvector(input) %check it is a one dimensional array
                    %convert to index
                    lines = round(input);
                    %remove duplicates
                    lines = unique(lines);
                    %reshape as column vector
                    lines = reshape(lines,[1,numel(lines)]);
                    switch app.RemoveLinesDirection.Value
                        case 'Rows'
                            %Check if the indexes are all within the matrix
                            %bounds
                            Topo = app.InfoStructAnalisis.Topo;
                            if ~all(lines>0 & lines<=size(Topo,1))
                                errordlg('The provided row indexes are out of bounds for the image')
                                return
                            end
                            CleanTopo = removeBadLine(Topo,...
                                lines,axis = "rows");
                        case 'Columns'
                            Topo = app.InfoStructAnalisis.Topo;
                            if ~all(lines>0 & lines<=size(Topo,2))
                                errordlg('The provided column indexes are out of bounds for the image')
                                return
                            end
                            CleanTopo = removeBadLine(Topo,...
                                lines,axis = "columns");
                    end
                    app.InfoData.Topo = CleanTopo;
                end
            end
        end

        % Button pushed function: Topo2Workspace
        function Topo2WorkspaceButtonPushed(app, event)
            if isfield(app.InfoStruct,'Topo')
                assignin('base','Topo',app.InfoStructAnalisis.Topo)
            end
        end

        % Button pushed function: GaussianFilterTopoBtn
        function GaussianFilterTopoBtnButtonPushed(app, event)
            app.InfoData.Topo = ...
                GaussSmoothV2(app.InfoStructAnalisis.Topo,...
                app.InfoStructAnalisis.DistanciaFilas,...
                app.InfoStructAnalisis.DistanciaColumnas,...
                app.EditGaussianFilterTopo.Value);
        end

        % Button pushed function: LoadProfileBtn
        function LoadProfileBtnButtonPushed(app, event)
            %Get the previous location of the line profile from the variable on
            %the workspace lineProfilePosition and place the current line there
            
            %If no line exist, update the known previous position in the axis
            %data, and exit 
            try %Look for the variable on the workspace
                prevPosition= evalin('base','lineProfilePosition');
            catch
                disp(['No valid variable was found in the workspace. ' ...
                    'Please, make sure the variable lineProfilePosition is present'])
                return
            end
            %Verify that the variable has the expected structure [Xstart Ystart; Xfinal Yfinal]
            if any(size(prevPosition) ~= [2,2],"all")
                error(['The data contained in the variable lineProfilePosition ' ...
                    'does not contain valid data. Make sure the variable is a 2x2 array ' ...
                    'with the values [Xstart Ystart; Xfinal Yfinal]'])
            end
            LineObj = findobj(app.visualizeAppReal.Axes,'Tag','lineProfile');
            if isempty(LineObj)
                app.visualizeAppReal.Axes.UserData.lineProfilePosition = prevPosition;
                return
            else
                LineObj.Position = prevPosition;
                app.visualizeAppReal.Axes.UserData.lineProfilePosition = prevPosition;
            end
        end

        % Button pushed function: LoadProfileBtn_2
        function LoadProfileBtn_2ButtonPushed(app, event)
                        %Get the previous location of the line profile from the variable on
            %the workspace lineProfilePosition and place the current line there
            
            %If no line exist, update the known previous position in the axis
            %data, and exit 
            try %Look for the variable on the workspace
                prevPosition= evalin('base','lineProfilePosition');
            catch
                disp(['No valid variable was found in the workspace. ' ...
                    'Please, make sure the variable lineProfilePosition is present'])
                return
            end
            %Verify that the variable has the expected structure [Xstart Ystart; Xfinal Yfinal]
            if any(size(prevPosition) ~= [2,2],"all")
                error(['The data contained in the variable lineProfilePosition ' ...
                    'does not contain valid data. Make sure the variable is a 2x2 array ' ...
                    'with the values [Xstart Ystart; Xfinal Yfinal]'])
            end
            LineObj = findobj(app.visualizeAppFFT.Axes,'Tag','lineProfile');
            if isempty(LineObj)
                app.visualizeAppFFT.Axes.UserData.lineProfilePosition = prevPosition;
                return
            else
                LineObj.Position = prevPosition;
                app.visualizeAppFFT.Axes.UserData.lineProfilePosition = prevPosition;
            end
        end

        % Button pushed function: ShowInfoBtn
        function ShowInfoBtnPushed(app, event)
            if ~isempty(app.Struct)
                Pos = app.blqAnalysisUIFigure.Position;
                center = [Pos(1), Pos(2)] + [Pos(3), Pos(4)]/2;
                readInfoStructParameters(app.InfoStruct,app.Struct,Position=center)
            end
        end

        % Button pushed function: InterpolateMapButton
        function InterpolateMapButtonPushed(app, event)
            ratio = app.InterpRatioSpin.Value;
            NewInfo = interpolateFFT(app.InfoStructAnalisis,ratio);
            [app.InfoStructAnalisis,app.InfoStructAnalisisCopy]=deal(NewInfo);
             app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            % app.InfoStructAnalisisCopy = NewInfo;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,app.InfoStruct.ContrastFFT, false)
            [app.InfoData] = showCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy, app.visualizeAppFFT.k, app.visualizeAppFFT.Axes);
            

        end

        % Button pushed function: TukeyWindowBtn
        function TukeyWindowBtnButtonPushed(app, event)
            app.InfoStructAnalisisCopy.MapasConductancia = ...
                mapWindowing(app.InfoStructAnalisis.MapasConductancia,app.TukeyAlphaField.Value);
            app.visualizeAppReal.Cell = app.InfoStructAnalisisCopy.MapasConductancia;
            [app.InfoData] = showCell(app.visualizeAppReal, ...
                app.InfoStructAnalisisCopy.MapasConductancia,...
                app.InfoStructAnalisisCopy, app.visualizeAppReal.k,...
                app.visualizeAppReal.Axes);
        end

        % Button pushed function: CorrectionBtn
        function CorrectionBtnButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = ...
                warpRotateFFT(app.InfoStructAnalisis.Transformadas,...
                app.XShearwarpEditField.Value, app.YShearwarpEditField.Value,...
                app.YXRatioEditField.Value,app.EditRotateAngleFFT.Value,...
                app.InfoStructAnalisis);
            
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;


            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,...
                app.InfoStruct.ContrastFFT, false)
            
            [app.InfoData] = showCell(app.visualizeAppFFT, ...
                app.InfoStructAnalisisCopy.Transformadas,...
                app.InfoStructAnalisisCopy, app.visualizeAppFFT.k,...
                app.visualizeAppFFT.Axes);

        end

        % Button pushed function: CorrectionRotBtn
        function CorrectionRotBtnButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = ...
            warpRotateFFT(app.InfoStructAnalisis.Transformadas,...
                app.XShearwarpEditField.Value, app.YShearwarpEditField.Value,...
                app.YXRatioEditField.Value,app.EditRotateAngleFFT.Value,...
                app.InfoStructAnalisis,RevertRotation = true);
            
            app.visualizeAppFFT.Cell = app.InfoStructAnalisisCopy.Transformadas;
            initCell(app.visualizeAppFFT, app.InfoStructAnalisisCopy.Transformadas,...
                app.InfoStruct.ContrastFFT, false)
            
            [app.InfoData] = showCell(app.visualizeAppFFT, ...
                app.InfoStructAnalisisCopy.Transformadas,...
                app.InfoStructAnalisisCopy, app.visualizeAppFFT.k,...
                app.visualizeAppFFT.Axes);

        end

        % Value changing function: AngleShapeSlider
        function AngleShapeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AngleSliderLabel.Text = sprintf('Angle: %5.2f º',changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.blqAnalysisUIFigure = uifigure('Visible', 'off');
            app.blqAnalysisUIFigure.Name = 'blqAnalysis';
            app.blqAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @blqAnalysisUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.blqAnalysisUIFigure);
            app.FileMenu.Text = 'File';
            app.InfoStructtoworkspaceMenu = uimenu(app.FileMenu);
            app.InfoStructtoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoStructtoworkspaceMenuSelected, true);
            app.InfoStructtoworkspaceMenu.Text = 'InfoStruct to workspace';
            app.EditMenu = uimenu(app.blqAnalysisUIFigure);
            app.EditMenu.Text = 'Edit';
            app.RecalculateFFTMenu = uimenu(app.EditMenu);
            app.RecalculateFFTMenu.MenuSelectedFcn = createCallbackFcn(app, @RecalculateFFTMenuSelected, true);
            app.RecalculateFFTMenu.Text = 'Recalculate FFT';
            app.ReinterpolateMapsMenu = uimenu(app.EditMenu);
            app.ReinterpolateMapsMenu.MenuSelectedFcn = createCallbackFcn(app, @ReinterpolateMapsMenuSelected, true);
            app.ReinterpolateMapsMenu.Text = 'Reinterpolate Maps';
            app.CellMenu = uimenu(app.blqAnalysisUIFigure);
            app.CellMenu.Text = 'Cell';
            app.NewMenu = uimenu(app.CellMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Checked = 'on';
            app.NewMenu.Text = 'New';
            app.OldMenu = uimenu(app.CellMenu);
            app.OldMenu.MenuSelectedFcn = createCallbackFcn(app, @OldMenuSelected, true);
            app.OldMenu.Text = 'Old';
            app.SaveformatMenu = uimenu(app.blqAnalysisUIFigure);
            app.SaveformatMenu.Visible = 'off';
            app.SaveformatMenu.Enable = 'off';
            app.SaveformatMenu.Text = 'Save format';
            app.jpegMenu = uimenu(app.SaveformatMenu);
            app.jpegMenu.Text = 'jpeg';
            app.pngMenu = uimenu(app.SaveformatMenu);
            app.pngMenu.Checked = 'on';
            app.pngMenu.Text = 'png';
            app.svgMenu = uimenu(app.SaveformatMenu);
            app.svgMenu.Text = 'svg';
            app.FilteringMenu = uimenu(app.blqAnalysisUIFigure);
            app.FilteringMenu.Text = 'Filtering';
            app.ApplyfilterstocurvesMenu = uimenu(app.FilteringMenu);
            app.ApplyfilterstocurvesMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyfilterstocurvesMenuSelected, true);
            app.ApplyfilterstocurvesMenu.Text = 'Apply filters to curves';
            app.CompressionMenu = uimenu(app.blqAnalysisUIFigure);
            app.CompressionMenu.Tooltip = {'Enables/disables compression when exporting InfoStructs. Enabling compression leads to smaller output files, at the expense of much longer loading times'};
            app.CompressionMenu.Text = 'Compression';
            app.EnableCompression = uimenu(app.CompressionMenu);
            app.EnableCompression.MenuSelectedFcn = createCallbackFcn(app, @EnableCompressionMenuSelected, true);
            app.EnableCompression.Text = 'Enable Infostruct compression';
            app.TabGroup = uitabgroup(app.blqAnalysisUIFigure);
            app.OpenTab = uitab(app.TabGroup);
            app.OpenTab.Title = 'Open';
            app.NewAppButton = uibutton(app.OpenTab, 'push');
            app.NewAppButton.ButtonPushedFcn = createCallbackFcn(app, @NewAppButtonPushed, true);
            app.NewAppButton.Text = 'Open blqReader';
            app.SaveInfoStructButton = uibutton(app.OpenTab, 'push');
            app.SaveInfoStructButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInfoStructButtonPushed, true);
            app.SaveInfoStructButton.Text = 'Save InfoStruct';
            app.LoadInfoStructfromfileButton = uibutton(app.OpenTab, 'push');
            app.LoadInfoStructfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInfoStructfromfileButtonPushed, true);
            app.LoadInfoStructfromfileButton.Text = 'Load InfoStruct from file';
            app.LoadInfoStructfromworkspaceButton = uibutton(app.OpenTab, 'push');
            app.LoadInfoStructfromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInfoStructfromworkspaceButtonPushed, true);
            app.LoadInfoStructfromworkspaceButton.Text = 'Load InfoStruct from workspace';
            app.x1EditFieldLabel = uilabel(app.OpenTab);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.OpenTab, 'numeric');
            app.x1EditField.Value = 1;
            app.x2EditFieldLabel = uilabel(app.OpenTab);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.OpenTab, 'numeric');
            app.x2EditField.Value = 128;
            app.y1EditFieldLabel = uilabel(app.OpenTab);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.OpenTab, 'numeric');
            app.y1EditField.Value = 1;
            app.y2EditFieldLabel = uilabel(app.OpenTab);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.OpenTab, 'numeric');
            app.y2EditField.Value = 128;
            app.CutInfoStructButton = uibutton(app.OpenTab, 'push');
            app.CutInfoStructButton.ButtonPushedFcn = createCallbackFcn(app, @CutInfoStructButtonPushed, true);
            app.CutInfoStructButton.Text = 'Cut InfoStruct';
            app.RestoreButton = uibutton(app.OpenTab, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore';
            app.OnlySquareCheckBox = uicheckbox(app.OpenTab);
            app.OnlySquareCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnlySquareCheckBoxValueChanged, true);
            app.OnlySquareCheckBox.Tooltip = {''};
            app.OnlySquareCheckBox.Text = 'Square Matrix';
            app.OnlySquareCheckBox.Value = true;
            app.GeneratemapsButton = uibutton(app.OpenTab, 'push');
            app.GeneratemapsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratemapsButtonPushed, true);
            app.GeneratemapsButton.Enable = 'off';
            app.GeneratemapsButton.Visible = 'off';
            app.GeneratemapsButton.Text = 'Generate maps';
            app.CurrentInfoStructLabel = uilabel(app.OpenTab);
            app.CurrentInfoStructLabel.Enable = 'off';
            app.CurrentInfoStructLabel.Visible = 'off';
            app.CurrentInfoStructLabel.Text = 'Current InfoStruct :';
            app.CurrentInfoStructName = uilabel(app.OpenTab);
            app.CurrentInfoStructName.Enable = 'off';
            app.CurrentInfoStructName.Visible = 'off';
            app.CurrentInfoStructName.Text = '';
            app.CurrentblqLabel = uilabel(app.OpenTab);
            app.CurrentblqLabel.Visible = 'off';
            app.CurrentblqLabel.Text = 'Current blq :';
            app.CurrentblqName = uilabel(app.OpenTab);
            app.CurrentblqName.Text = '';
            app.ClearInfostructButton = uibutton(app.OpenTab, 'push');
            app.ClearInfostructButton.ButtonPushedFcn = createCallbackFcn(app, @ClearInfostructButtonPushed, true);
            app.ClearInfostructButton.Text = 'Clear Infostruct';
            app.ShowInfoBtn = uibutton(app.OpenTab, 'push');
            app.ShowInfoBtn.ButtonPushedFcn = createCallbackFcn(app, @ShowInfoBtnPushed, true);
            app.ShowInfoBtn.Text = 'Show Parameters';
            app.AnalyzeRealTab = uitab(app.TabGroup);
            app.AnalyzeRealTab.Tooltip = {''};
            app.AnalyzeRealTab.Title = 'Analyze Real';
            app.MeanConductanceButton = uibutton(app.AnalyzeRealTab, 'push');
            app.MeanConductanceButton.ButtonPushedFcn = createCallbackFcn(app, @MeanConductanceButtonPushed, true);
            app.MeanConductanceButton.Text = 'Mean Conductance';
            app.EditRotateAngleReal = uieditfield(app.AnalyzeRealTab, 'numeric');
            app.EditRotateAngleReal.ValueDisplayFormat = '%11.4g º';
            app.EditGaussianFilterReal = uieditfield(app.AnalyzeRealTab, 'numeric');
            app.EditGaussianFilterReal.LowerLimitInclusive = 'off';
            app.EditGaussianFilterReal.Limits = [0 Inf];
            app.EditGaussianFilterReal.ValueDisplayFormat = '%11.4g nm';
            app.EditGaussianFilterReal.Value = 1;
            app.MeanCurrentButton = uibutton(app.AnalyzeRealTab, 'push');
            app.MeanCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @MeanCurrentButtonPushed, true);
            app.MeanCurrentButton.Text = 'Mean Current';
            app.SaveContrastRealButton = uibutton(app.AnalyzeRealTab, 'push');
            app.SaveContrastRealButton.ButtonPushedFcn = createCallbackFcn(app, @SaveContrastRealButtonPushed, true);
            app.SaveContrastRealButton.Text = 'Save Contrast';
            app.LoadContrastRealButton = uibutton(app.AnalyzeRealTab, 'push');
            app.LoadContrastRealButton.ButtonPushedFcn = createCallbackFcn(app, @LoadContrastRealButtonPushed, true);
            app.LoadContrastRealButton.Text = 'Load Contrast';
            app.RealMapsButton = uibutton(app.AnalyzeRealTab, 'push');
            app.RealMapsButton.ButtonPushedFcn = createCallbackFcn(app, @RealMapsButtonPushed, true);
            app.RealMapsButton.Text = 'Real Maps';
            app.VisualizeRealButton = uibutton(app.AnalyzeRealTab, 'push');
            app.VisualizeRealButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeRealButtonPushed, true);
            app.VisualizeRealButton.Text = 'Visualize Real';
            app.RotateNewButtonReal = uibutton(app.AnalyzeRealTab, 'push');
            app.RotateNewButtonReal.ButtonPushedFcn = createCallbackFcn(app, @RotateNewButtonRealPushed, true);
            app.RotateNewButtonReal.Text = 'Rotate';
            app.GaussianFilterNewButtonReal = uibutton(app.AnalyzeRealTab, 'push');
            app.GaussianFilterNewButtonReal.ButtonPushedFcn = createCallbackFcn(app, @GaussianFilterRealButtonPushed, true);
            app.GaussianFilterNewButtonReal.Text = 'Gaussian Filter';
            app.KeepChangesNewRealButton = uibutton(app.AnalyzeRealTab, 'push');
            app.KeepChangesNewRealButton.ButtonPushedFcn = createCallbackFcn(app, @KeepChangesNewRealButtonPushed, true);
            app.KeepChangesNewRealButton.Text = 'Keep Changes';
            app.ColormapRealDropDownNew = uidropdown(app.AnalyzeRealTab);
            app.ColormapRealDropDownNew.Items = {'parula', 'viridis', 'plasma', 'magma', 'inferno', 'gray', 'bone'};
            app.ColormapRealDropDownNew.ValueChangedFcn = createCallbackFcn(app, @ColormapRealDropDownValueChanged, true);
            app.ColormapRealDropDownNew.Value = 'parula';
            app.SaveallRealButton = uibutton(app.AnalyzeRealTab, 'push');
            app.SaveallRealButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallRealButtonPushed, true);
            app.SaveallRealButton.Text = 'Save all Real';
            app.FlattenButton = uibutton(app.AnalyzeRealTab, 'push');
            app.FlattenButton.ButtonPushedFcn = createCallbackFcn(app, @FlattenButtonPushed, true);
            app.FlattenButton.Text = 'Flatten';
            app.FlattenDropDown = uidropdown(app.AnalyzeRealTab);
            app.FlattenDropDown.Items = {'Horizontal', 'Vertical', 'Both'};
            app.FlattenDropDown.ValueChangedFcn = createCallbackFcn(app, @FlattenDropDownValueChanged, true);
            app.FlattenDropDown.Value = 'Horizontal';
            app.PlaneButton = uibutton(app.AnalyzeRealTab, 'push');
            app.PlaneButton.ButtonPushedFcn = createCallbackFcn(app, @PlaneButtonPushed, true);
            app.PlaneButton.Text = 'Plane';
            app.SquareFilterButton = uibutton(app.AnalyzeRealTab, 'push');
            app.SquareFilterButton.ButtonPushedFcn = createCallbackFcn(app, @SquareFilterButtonPushed, true);
            app.SquareFilterButton.Text = 'Square Filter';
            app.SquareSpinner = uispinner(app.AnalyzeRealTab);
            app.SquareSpinner.Limits = [1 Inf];
            app.SquareSpinner.Value = 1;
            app.ReverseColormapRealCheckBox = uicheckbox(app.AnalyzeRealTab);
            app.ReverseColormapRealCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseColormapRealCheckBoxValueChanged, true);
            app.ReverseColormapRealCheckBox.Text = 'Reverse';
            app.HistogramButton = uibutton(app.AnalyzeRealTab, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.AngleCorrelationButton = uibutton(app.AnalyzeRealTab, 'push');
            app.AngleCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @AngleCorrelationButtonPushed, true);
            app.AngleCorrelationButton.Text = 'Angle Correlation';
            app.SaveAllRealDropDown = uidropdown(app.AnalyzeRealTab);
            app.SaveAllRealDropDown.Items = {'Title and axes', 'Title only', 'Map only', 'Tiled Image', 'Video', 'GIF'};
            app.SaveAllRealDropDown.ItemsData = {'2', '1', '0', '3', '4', '5'};
            app.SaveAllRealDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveAllRealDropDownValueChanged, true);
            app.SaveAllRealDropDown.Value = '2';
            app.ColormapRealDropDownNewCategory = uidropdown(app.AnalyzeRealTab);
            app.ColormapRealDropDownNewCategory.Items = {'Most Used', 'Uniform Sequential', 'Uniform Diverging', 'Uniform Cyclic', 'Sequential', 'Diverging', 'Cyclic', 'Misc', 'Categorical'};
            app.ColormapRealDropDownNewCategory.ValueChangedFcn = createCallbackFcn(app, @ColormapRealDropDownNewCategoryValueChanged, true);
            app.ColormapRealDropDownNewCategory.Value = 'Most Used';
            app.ShiftColormapRealCheckBox = uicheckbox(app.AnalyzeRealTab);
            app.ShiftColormapRealCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShiftColormapRealCheckBoxValueChanged, true);
            app.ShiftColormapRealCheckBox.Enable = 'off';
            app.ShiftColormapRealCheckBox.Visible = 'off';
            app.ShiftColormapRealCheckBox.Text = 'Shift';
            app.NormalizeButton = uibutton(app.AnalyzeRealTab, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Tooltip = {'Divides each map by the mean'};
            app.NormalizeButton.Text = 'Normalize';
            app.SubstractAvgCurveButton = uibutton(app.AnalyzeRealTab, 'push');
            app.SubstractAvgCurveButton.ButtonPushedFcn = createCallbackFcn(app, @SubstractAvgCurveButtonPushed, true);
            app.SubstractAvgCurveButton.Text = 'Substract Avg Curve';
            app.SigmaRealLbl = uilabel(app.AnalyzeRealTab);
            app.SigmaRealLbl.Text = 'σ = ';
            app.SecondDerivButton = uibutton(app.AnalyzeRealTab, 'push');
            app.SecondDerivButton.ButtonPushedFcn = createCallbackFcn(app, @SecondDerivButtonPushed, true);
            app.SecondDerivButton.Text = 'Second Deriv';
            app.SecondDerivSpin = uispinner(app.AnalyzeRealTab);
            app.SecondDerivSpin.Limits = [1 Inf];
            app.SecondDerivSpin.RoundFractionalValues = 'on';
            app.SecondDerivSpin.Value = 1;
            app.LoadProfileBtn = uibutton(app.AnalyzeRealTab, 'push');
            app.LoadProfileBtn.ButtonPushedFcn = createCallbackFcn(app, @LoadProfileBtnButtonPushed, true);
            app.LoadProfileBtn.Text = 'Load Profile Location';
            app.TukeyWindowBtn = uibutton(app.AnalyzeRealTab, 'push');
            app.TukeyWindowBtn.ButtonPushedFcn = createCallbackFcn(app, @TukeyWindowBtnButtonPushed, true);
            app.TukeyWindowBtn.Tooltip = {'Applies a Tukey window. Remove the fraction of points of the edges alpha to reduce artifacts in FFTs'};
            app.TukeyWindowBtn.Text = 'Tukey Window';
            app.TukeyAlphaField = uieditfield(app.AnalyzeRealTab, 'numeric');
            app.TukeyAlphaField.Limits = [0 1];
            app.TukeyAlphaField.ValueDisplayFormat = '%11.4g  α';
            app.TukeyAlphaField.Value = 0.1;
            app.AnalyzeFFTTab = uitab(app.TabGroup);
            app.AnalyzeFFTTab.Title = 'Analyze FFT';
            app.FFTMapsButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.FFTMapsButton.ButtonPushedFcn = createCallbackFcn(app, @FFTMapsButtonPushed, true);
            app.FFTMapsButton.Text = 'FFT Maps';
            app.ColormapFFTDropDownCategory = uidropdown(app.AnalyzeFFTTab);
            app.ColormapFFTDropDownCategory.Items = {'Most Used', 'Uniform Sequential', 'Uniform Diverging', 'Uniform Cyclic', 'Sequential', 'Diverging', 'Cyclic', 'Misc', 'Categorical'};
            app.ColormapFFTDropDownCategory.ValueChangedFcn = createCallbackFcn(app, @ColormapFFTDropDownCategoryValueChanged, true);
            app.ColormapFFTDropDownCategory.Value = 'Most Used';
            app.ColormapFFTDropDown = uidropdown(app.AnalyzeFFTTab);
            app.ColormapFFTDropDown.Items = {'parula', 'viridis', 'plasma', 'magma', 'inferno', 'gray', 'bone'};
            app.ColormapFFTDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapFFTDropDownValueChanged, true);
            app.ColormapFFTDropDown.Value = 'parula';
            app.VisualizeFFTButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.VisualizeFFTButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeFFTButtonPushed, true);
            app.VisualizeFFTButton.Text = 'Visualize FFT';
            app.ReverseColormapFFTCheckBox = uicheckbox(app.AnalyzeFFTTab);
            app.ReverseColormapFFTCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseColormapFFTCheckBoxValueChanged, true);
            app.ReverseColormapFFTCheckBox.Text = 'Reverse';
            app.ShiftColormapFFTCheckBox = uicheckbox(app.AnalyzeFFTTab);
            app.ShiftColormapFFTCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShiftColormapFFTCheckBoxValueChanged, true);
            app.ShiftColormapFFTCheckBox.Enable = 'off';
            app.ShiftColormapFFTCheckBox.Visible = 'off';
            app.ShiftColormapFFTCheckBox.Text = 'Shift';
            app.RemoveCentralLineButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.RemoveCentralLineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCentralLineButtonPushed2, true);
            app.RemoveCentralLineButton.Text = 'Remove Central Line';
            app.RmCentralLineDropDown = uidropdown(app.AnalyzeFFTTab);
            app.RmCentralLineDropDown.Items = {'Vertical', 'Horizontal', 'Both'};
            app.RmCentralLineDropDown.ValueChangedFcn = createCallbackFcn(app, @RmCentralLineDropDownValueChanged, true);
            app.RmCentralLineDropDown.Value = 'Vertical';
            app.RotateNewButtonFFT = uibutton(app.AnalyzeFFTTab, 'push');
            app.RotateNewButtonFFT.ButtonPushedFcn = createCallbackFcn(app, @RotateNewButtonFFTPushed, true);
            app.RotateNewButtonFFT.Text = 'Rotate';
            app.EditRotateAngleFFT = uieditfield(app.AnalyzeFFTTab, 'numeric');
            app.EditRotateAngleFFT.ValueDisplayFormat = '%11.4g º';
            app.RotatePlus90Btn = uibutton(app.AnalyzeFFTTab, 'push');
            app.RotatePlus90Btn.ButtonPushedFcn = createCallbackFcn(app, @RotatePlus90BtnButtonPushed, true);
            app.RotatePlus90Btn.Text = '+90º';
            app.RotateMinus90Btn = uibutton(app.AnalyzeFFTTab, 'push');
            app.RotateMinus90Btn.ButtonPushedFcn = createCallbackFcn(app, @RotateMinus90BtnButtonPushed, true);
            app.RotateMinus90Btn.Text = '-90º';
            app.SymmetrizeButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.SymmetrizeButton.ButtonPushedFcn = createCallbackFcn(app, @SymmetrizeButtonPushed, true);
            app.SymmetrizeButton.Text = 'Symmetrize';
            app.SymmetryDropDown = uidropdown(app.AnalyzeFFTTab);
            app.SymmetryDropDown.Items = {'C3', 'C4', 'C6', 'Vertical', 'Hor+Ver'};
            app.SymmetryDropDown.ValueChangedFcn = createCallbackFcn(app, @SymmetryDropDownValueChanged, true);
            app.SymmetryDropDown.Value = 'C4';
            app.GaussianFilterNewButtonFFT = uibutton(app.AnalyzeFFTTab, 'push');
            app.GaussianFilterNewButtonFFT.ButtonPushedFcn = createCallbackFcn(app, @GaussianFilterFFTButtonPushed, true);
            app.GaussianFilterNewButtonFFT.Text = 'Gaussian Filter';
            app.EditGaussianFilterFFT = uieditfield(app.AnalyzeFFTTab, 'numeric');
            app.EditGaussianFilterFFT.LowerLimitInclusive = 'off';
            app.EditGaussianFilterFFT.Limits = [0 Inf];
            app.EditGaussianFilterFFT.ValueDisplayFormat = '%11.4g 2π/nm';
            app.EditGaussianFilterFFT.Value = 1;
            app.SquareFilterFFTButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.SquareFilterFFTButton.ButtonPushedFcn = createCallbackFcn(app, @SquareFilterFFTButtonPushed, true);
            app.SquareFilterFFTButton.Text = 'Square Filter';
            app.SquareSpinnerFFT = uispinner(app.AnalyzeFFTTab);
            app.SquareSpinnerFFT.Limits = [1 Inf];
            app.SquareSpinnerFFT.Value = 1;
            app.GaussiancoresubstractionButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.GaussiancoresubstractionButton.ButtonPushedFcn = createCallbackFcn(app, @GaussiancoresubstractionButtonPushed, true);
            app.GaussiancoresubstractionButton.Text = {'Gaussian core'; 'substraction'};
            app.SigmaCoreEditField = uieditfield(app.AnalyzeFFTTab, 'numeric');
            app.SigmaCoreEditField.LowerLimitInclusive = 'off';
            app.SigmaCoreEditField.Limits = [0 Inf];
            app.SigmaCoreEditField.ValueDisplayFormat = '%11.4g 2π/nm';
            app.SigmaCoreEditField.Value = 1;
            app.AdjustZeroButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.AdjustZeroButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustZeroButtonPushed, true);
            app.AdjustZeroButton.Tooltip = {'Warning! This adjustent is no longer needed when dealing with FFT images. It is only meant to be used with previously analyzed InfoStructs.'};
            app.AdjustZeroButton.Text = 'Adjust (0,0)';
            app.SaveallFFTButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.SaveallFFTButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallFFTButtonPushed, true);
            app.SaveallFFTButton.Text = 'Save all FFT';
            app.SaveAllFFTDropDown = uidropdown(app.AnalyzeFFTTab);
            app.SaveAllFFTDropDown.Items = {'Title and axes', 'Title only', 'Map only', 'Tiled Image', 'Video', 'GIF'};
            app.SaveAllFFTDropDown.ItemsData = {'2', '1', '0', '3', '4', '5'};
            app.SaveAllFFTDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveAllFFTDropDownValueChanged, true);
            app.SaveAllFFTDropDown.Value = '2';
            app.SaveContrastFFTButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.SaveContrastFFTButton.ButtonPushedFcn = createCallbackFcn(app, @SaveContrastFFTButtonPushed, true);
            app.SaveContrastFFTButton.Text = 'Save Contrast';
            app.LoadContrastFFTButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.LoadContrastFFTButton.ButtonPushedFcn = createCallbackFcn(app, @LoadContrastFFTButtonPushed, true);
            app.LoadContrastFFTButton.Text = 'Load Contrast';
            app.VerticalprofileButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.VerticalprofileButton.ButtonPushedFcn = createCallbackFcn(app, @VerticalprofileButtonPushed, true);
            app.VerticalprofileButton.Text = 'Vertical profile';
            app.HorizontalprofileButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.HorizontalprofileButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalprofileButtonPushed, true);
            app.HorizontalprofileButton.Text = 'Horizontal profile';
            app.DiagonalprofileButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.DiagonalprofileButton.ButtonPushedFcn = createCallbackFcn(app, @DiagonalprofileButtonPushed, true);
            app.DiagonalprofileButton.Text = 'Diagonal profile';
            app.AngleprofileButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.AngleprofileButton.ButtonPushedFcn = createCallbackFcn(app, @AngleprofileButtonPushed, true);
            app.AngleprofileButton.Text = 'Angle profile';
            app.EditProfileAngle = uieditfield(app.AnalyzeFFTTab, 'numeric');
            app.EditProfileAngle.Limits = [-180 360];
            app.EditProfileAngle.ValueDisplayFormat = '%11.4g º';
            app.RadialprofileButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.RadialprofileButton.Enable = 'off';
            app.RadialprofileButton.Visible = 'off';
            app.RadialprofileButton.Text = 'Radial profile';
            app.qanisotropyButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.qanisotropyButton.ButtonPushedFcn = createCallbackFcn(app, @qanisotropyButtonPushed, true);
            app.qanisotropyButton.Text = 'q anisotropy';
            app.qanisotropyEditField = uieditfield(app.AnalyzeFFTTab, 'numeric');
            app.qanisotropyEditField.Limits = [0 Inf];
            app.qanisotropyEditField.Value = 0.5;
            app.CutasviewButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.CutasviewButton.ButtonPushedFcn = createCallbackFcn(app, @CutasviewButtonPushed, true);
            app.CutasviewButton.Enable = 'off';
            app.CutasviewButton.Visible = 'off';
            app.CutasviewButton.Text = 'Cut as view';
            app.KeepChangesNewFFTButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.KeepChangesNewFFTButton.ButtonPushedFcn = createCallbackFcn(app, @KeepChangesNewFFTButtonPushed, true);
            app.KeepChangesNewFFTButton.Text = 'Keep Changes';
            app.SigmaFFTLbl = uilabel(app.AnalyzeFFTTab);
            app.SigmaFFTLbl.Text = 'σ = ';
            app.SigmaCoreLbl = uilabel(app.AnalyzeFFTTab);
            app.SigmaCoreLbl.Text = 'σ = ';
            app.LoadProfileBtn_2 = uibutton(app.AnalyzeFFTTab, 'push');
            app.LoadProfileBtn_2.ButtonPushedFcn = createCallbackFcn(app, @LoadProfileBtn_2ButtonPushed, true);
            app.LoadProfileBtn_2.Text = 'Load Profile Location';
            app.InterpolateMapButton = uibutton(app.AnalyzeFFTTab, 'push');
            app.InterpolateMapButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateMapButtonPushed, true);
            app.InterpolateMapButton.Enable = 'off';
            app.InterpolateMapButton.Visible = 'off';
            app.InterpolateMapButton.Text = 'Interpolate Map';
            app.InterpRatioSpin = uispinner(app.AnalyzeFFTTab);
            app.InterpRatioSpin.Limits = [0 Inf];
            app.InterpRatioSpin.Editable = 'off';
            app.InterpRatioSpin.Visible = 'off';
            app.InterpRatioSpin.Value = 1;
            app.WarpCorrectionTab = uitab(app.TabGroup);
            app.WarpCorrectionTab.Tooltip = {''};
            app.WarpCorrectionTab.Title = 'Warp Correction';
            app.FFTCorrectionTitle = uilabel(app.WarpCorrectionTab);
            app.FFTCorrectionTitle.Text = 'FFT Correction';
            app.XShearwarpEditFieldLabel = uilabel(app.WarpCorrectionTab);
            app.XShearwarpEditFieldLabel.Text = 'X Shear warp';
            app.XShearwarpEditField = uieditfield(app.WarpCorrectionTab, 'numeric');
            app.YXRatioEditFieldLabel = uilabel(app.WarpCorrectionTab);
            app.YXRatioEditFieldLabel.Text = 'Y/X Ratio';
            app.YShearwarpEditField = uieditfield(app.WarpCorrectionTab, 'numeric');
            app.YXRatioEditField = uieditfield(app.WarpCorrectionTab, 'numeric');
            app.YXRatioEditField.Value = 1;
            app.YShearwarpEditFieldLabel = uilabel(app.WarpCorrectionTab);
            app.YShearwarpEditFieldLabel.Text = 'Y Shear warp';
            app.FFTWarpCorrectionButton = uibutton(app.WarpCorrectionTab, 'push');
            app.FFTWarpCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @WarpCorrectionFFTButtonPushed, true);
            app.FFTWarpCorrectionButton.Text = 'FFT Warp Correction';
            app.ScalebyEditFieldLabel = uilabel(app.WarpCorrectionTab);
            app.ScalebyEditFieldLabel.Text = 'Scale by';
            app.ScaleFactor = uieditfield(app.WarpCorrectionTab, 'numeric');
            app.ScaleFactor.LowerLimitInclusive = 'off';
            app.ScaleFactor.Limits = [0 Inf];
            app.ScaleFactor.Value = 1;
            app.RescaleButton = uibutton(app.WarpCorrectionTab, 'push');
            app.RescaleButton.ButtonPushedFcn = createCallbackFcn(app, @RescaleButtonPushed, true);
            app.RescaleButton.Text = 'Rescale';
            app.CorrectionBtn = uibutton(app.WarpCorrectionTab, 'push');
            app.CorrectionBtn.ButtonPushedFcn = createCallbackFcn(app, @CorrectionBtnButtonPushed, true);
            app.CorrectionBtn.Text = 'Full correction (one step)';
            app.CorrectionRotBtn = uibutton(app.WarpCorrectionTab, 'push');
            app.CorrectionRotBtn.ButtonPushedFcn = createCallbackFcn(app, @CorrectionRotBtnButtonPushed, true);
            app.CorrectionRotBtn.Text = 'Correct and revert to original axis';
            app.RealCorrectionTitle = uilabel(app.WarpCorrectionTab);
            app.RealCorrectionTitle.Text = 'Real Correction';
            app.XShearwarpEditField_2Label = uilabel(app.WarpCorrectionTab);
            app.XShearwarpEditField_2Label.Text = 'X Shear warp';
            app.XShearwarpReal = uieditfield(app.WarpCorrectionTab, 'numeric');
            app.YXRatioEditField_2Label = uilabel(app.WarpCorrectionTab);
            app.YXRatioEditField_2Label.Text = 'Y/X Ratio';
            app.YShearwarpReal = uieditfield(app.WarpCorrectionTab, 'numeric');
            app.YXRatioReal = uieditfield(app.WarpCorrectionTab, 'numeric');
            app.YXRatioReal.Value = 1;
            app.YShearwarpEditField_2Label = uilabel(app.WarpCorrectionTab);
            app.YShearwarpEditField_2Label.Text = 'Y Shear warp';
            app.WarpCorrectionRealBtn = uibutton(app.WarpCorrectionTab, 'push');
            app.WarpCorrectionRealBtn.ButtonPushedFcn = createCallbackFcn(app, @WarpCorrectionRealButtonPushed, true);
            app.WarpCorrectionRealBtn.Text = 'Real Warp Correction';
            app.AngleSliderLabel = uilabel(app.WarpCorrectionTab);
            app.AngleSliderLabel.Enable = 'off';
            app.AngleSliderLabel.Visible = 'off';
            app.AngleSliderLabel.Text = 'Angle';
            app.AngleShapeSlider = uislider(app.WarpCorrectionTab);
            app.AngleShapeSlider.Limits = [0 180];
            app.AngleShapeSlider.MajorTicks = [0 45 90 135 180];
            app.AngleShapeSlider.ValueChangingFcn = createCallbackFcn(app, @AngleShapeSliderValueChanging, true);
            app.AngleShapeSlider.MinorTicks = [15 30 60 75 105 120 135 150 165];
            app.AngleShapeSlider.Enable = 'off';
            app.AngleShapeSlider.Visible = 'off';
            app.GapMappingTab = uitab(app.TabGroup);
            app.GapMappingTab.Title = 'Gap Mapping';
            app.SweepDirection = uibuttongroup(app.GapMappingTab);
            app.SweepDirection.Enable = 'off';
            app.SweepDirection.Visible = 'off';
            app.XButton = uiradiobutton(app.SweepDirection);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.SweepDirection);
            app.YButton.Text = 'Y';
            app.SweepdirectionLabel = uilabel(app.GapMappingTab);
            app.SweepdirectionLabel.Enable = 'off';
            app.SweepdirectionLabel.Visible = 'off';
            app.SweepdirectionLabel.Text = 'Sweep direction';
            app.RangePeaksmVEditFieldLabel = uilabel(app.GapMappingTab);
            app.RangePeaksmVEditFieldLabel.Text = 'Range Peaks (mV)';
            app.RangePeaksmVEditField = uieditfield(app.GapMappingTab, 'numeric');
            app.RangePeaksmVEditField.Limits = [0 Inf];
            app.RangePeaksmVEditField.Value = 0.5;
            app.NumDerivEditFieldLabel = uilabel(app.GapMappingTab);
            app.NumDerivEditFieldLabel.Text = 'Num. Deriv';
            app.NumDerivEditField = uieditfield(app.GapMappingTab, 'numeric');
            app.NumDerivEditField.Limits = [0 Inf];
            app.NumDerivEditField.ValueDisplayFormat = '%.0f';
            app.NumDerivEditField.Tooltip = {'Points used for numerical derivative'};
            app.NumDerivEditField.Value = 1;
            app.ThresholdratioEditFieldLabel = uilabel(app.GapMappingTab);
            app.ThresholdratioEditFieldLabel.Text = 'Threshold ratio';
            app.ThresholdratioEditField = uieditfield(app.GapMappingTab, 'numeric');
            app.ThresholdratioEditField.Limits = [0 Inf];
            app.ThresholdratioEditField.Tooltip = {'Minimum relative depth of gap respect to 1.'};
            app.ThresholdratioEditField.Value = 1.5;
            app.CalculateGapMapButton = uibutton(app.GapMappingTab, 'push');
            app.CalculateGapMapButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateGapMapButtonPushed, true);
            app.CalculateGapMapButton.Text = 'Calculate Gap Map';
            app.GaussianfilterEditFieldLabel = uilabel(app.GapMappingTab);
            app.GaussianfilterEditFieldLabel.Text = 'Gaussian filter';
            app.GaussianfilterEditField = uieditfield(app.GapMappingTab, 'numeric');
            app.GaussianfilterEditField.Limits = [0 Inf];
            app.GaussianfilterEditField.Value = 0.5;
            app.ContrastMinEditFieldLabel = uilabel(app.GapMappingTab);
            app.ContrastMinEditFieldLabel.Text = 'Contrast Min';
            app.ContrastMinEditField = uieditfield(app.GapMappingTab, 'numeric');
            app.ContrastMinEditField.Limits = [0 Inf];
            app.ContrastMinEditField.Value = 0.2;
            app.ContrastMaxEditFieldLabel = uilabel(app.GapMappingTab);
            app.ContrastMaxEditFieldLabel.Text = 'Contrast Max';
            app.ContrastMaxEditField = uieditfield(app.GapMappingTab, 'numeric');
            app.ContrastMaxEditField.Limits = [0 Inf];
            app.ContrastMaxEditField.Value = 0.4;
            app.GoButton = uibutton(app.GapMappingTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.MaxDerivativeEditFieldLabel = uilabel(app.GapMappingTab);
            app.MaxDerivativeEditFieldLabel.Text = 'Max Derivative';
            app.MaxDerivativeEditField = uieditfield(app.GapMappingTab, 'numeric');
            app.MaxDerivativeEditField.Limits = [0 Inf];
            app.MaxDerivativeEditField.Tooltip = {'Maximun allowed value for derivative. If exeeded, it is considered noise.'};
            app.MaxDerivativeEditField.Value = 18;
            app.MoreFunctionsTab = uitab(app.TabGroup);
            app.MoreFunctionsTab.Title = 'More Functions';
            app.AveragescatteringButton = uibutton(app.MoreFunctionsTab, 'push');
            app.AveragescatteringButton.ButtonPushedFcn = createCallbackFcn(app, @AveragescatteringButtonPushed, true);
            app.AveragescatteringButton.Text = 'Average scattering';
            app.PopcornButton = uibutton(app.MoreFunctionsTab, 'push');
            app.PopcornButton.ButtonPushedFcn = createCallbackFcn(app, @PopcornButtonPushed, true);
            app.PopcornButton.Text = 'Popcorn';
            app.SigmaEditFieldLabel = uilabel(app.MoreFunctionsTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.MoreFunctionsTab, 'numeric');
            app.SigmaEditField.Limits = [0 Inf];
            app.SigmaEditField.Value = 4;
            app.MedianFilter = uibutton(app.MoreFunctionsTab, 'push');
            app.MedianFilter.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterPushed, true);
            app.MedianFilter.Tooltip = {'Calculates the median of a square of size 2*window+1 for every point to remove outliers'};
            app.MedianFilter.Text = 'Median Filter';
            app.WindowLabel = uilabel(app.MoreFunctionsTab);
            app.WindowLabel.Text = 'Window';
            app.MedianWindow = uispinner(app.MoreFunctionsTab);
            app.MedianWindow.Limits = [1 Inf];
            app.MedianWindow.Value = 1;
            app.FlattenTopoBtn = uibutton(app.MoreFunctionsTab, 'push');
            app.FlattenTopoBtn.ButtonPushedFcn = createCallbackFcn(app, @FlattenTopoBtnButtonPushed, true);
            app.FlattenTopoBtn.Tooltip = {'Removes a function line by line to the topographic data (if available), according to the settings used i the dropdown menus on the right. Note that the filter smove the height of the images so the new minima is set to zero'};
            app.FlattenTopoBtn.Text = 'Flatten';
            app.FlattenTopoDirection = uidropdown(app.MoreFunctionsTab);
            app.FlattenTopoDirection.Items = {'Horizontal', 'Vertical', 'Both'};
            app.FlattenTopoDirection.ItemsData = {'horizontal', 'vertical', 'both'};
            app.FlattenTopoDirection.Tooltip = {'Selects the direction  for the flatten filter to by applied. Either horizontal (each row of the matrix) vertical (each column of the matrix) of both sequentially'};
            app.FlattenTopoDirection.Value = 'horizontal';
            app.FlattenTopoMode = uidropdown(app.MoreFunctionsTab);
            app.FlattenTopoMode.Items = {'Offset', 'Line', 'Parabola'};
            app.FlattenTopoMode.Tooltip = {'Chooses the method to be used for the flatten filter: It can substract an offtset to each line, a linear fit or a parabolic fit'};
            app.FlattenTopoMode.Value = 'Offset';
            app.PlaneTopoBtn = uibutton(app.MoreFunctionsTab, 'push');
            app.PlaneTopoBtn.ButtonPushedFcn = createCallbackFcn(app, @PlaneTopoBtnButtonPushed, true);
            app.PlaneTopoBtn.Tooltip = {'Removes an overall plane from the toppographic map, if available'};
            app.PlaneTopoBtn.Text = 'Plane';
            app.ParabolaTopoBtn = uibutton(app.MoreFunctionsTab, 'push');
            app.ParabolaTopoBtn.ButtonPushedFcn = createCallbackFcn(app, @ParabolaTopoBtnButtonPushed, true);
            app.ParabolaTopoBtn.Tooltip = {'Removes a global parabolic fit from the toppographic map, if available'};
            app.ParabolaTopoBtn.Text = 'Parabola';
            app.Topo2Workspace = uibutton(app.MoreFunctionsTab, 'push');
            app.Topo2Workspace.ButtonPushedFcn = createCallbackFcn(app, @Topo2WorkspaceButtonPushed, true);
            app.Topo2Workspace.Tooltip = {''};
            app.Topo2Workspace.Text = 'To Workspace';
            app.TopoLabel = uilabel(app.MoreFunctionsTab);
            app.TopoLabel.Text = 'Topo Filtering';
            app.RemoveLinesTopo = uibutton(app.MoreFunctionsTab, 'push');
            app.RemoveLinesTopo.ButtonPushedFcn = createCallbackFcn(app, @RemoveLinesTopoButtonPushed, true);
            app.RemoveLinesTopo.Tooltip = {''};
            app.RemoveLinesTopo.Text = 'Remove Lines';
            app.RemoveLinesInput = uieditfield(app.MoreFunctionsTab, 'text');
            app.RemovePointsTopo = uibutton(app.MoreFunctionsTab, 'push');
            app.RemovePointsTopo.Enable = 'off';
            app.RemovePointsTopo.Visible = 'off';
            app.RemovePointsTopo.Tooltip = {''};
            app.RemovePointsTopo.Text = 'Remove Points';
            app.RemoveLinesDirection = uidropdown(app.MoreFunctionsTab);
            app.RemoveLinesDirection.Items = {'Rows', 'Columns'};
            app.RemoveLinesDirection.Tooltip = {'Chooses the method to be used for the flatten filter: It can substract an offtset to each line, a linear fit or a parabolic fit'};
            app.RemoveLinesDirection.Value = 'Rows';
            app.EditGaussianFilterTopo = uieditfield(app.MoreFunctionsTab, 'numeric');
            app.EditGaussianFilterTopo.LowerLimitInclusive = 'off';
            app.EditGaussianFilterTopo.Limits = [0 Inf];
            app.EditGaussianFilterTopo.ValueDisplayFormat = '%11.4g nm';
            app.EditGaussianFilterTopo.Value = 1;
            app.GaussianFilterTopoBtn = uibutton(app.MoreFunctionsTab, 'push');
            app.GaussianFilterTopoBtn.ButtonPushedFcn = createCallbackFcn(app, @GaussianFilterTopoBtnButtonPushed, true);
            app.GaussianFilterTopoBtn.Text = 'Gaussian Filter';
            app.SigmaTopoLbl = uilabel(app.MoreFunctionsTab);
            app.SigmaTopoLbl.Text = 'σ = ';
            app.SymmetryTab = uitab(app.TabGroup);
            app.SymmetryTab.Title = 'Symmetry';
            app.EnergySymmetryButton = uibutton(app.SymmetryTab, 'push');
            app.EnergySymmetryButton.ButtonPushedFcn = createCallbackFcn(app, @EnergySymmetryButtonPushed, true);
            app.EnergySymmetryButton.Text = 'Energy Symmetry';
            app.SaveContrastRestasButton = uibutton(app.SymmetryTab, 'push');
            app.SaveContrastRestasButton.Text = 'Save Contrast';
            app.LoadContrastRestasButton = uibutton(app.SymmetryTab, 'push');
            app.LoadContrastRestasButton.Text = 'Load Contrast';
            app.ColormapRestasDropDown = uidropdown(app.SymmetryTab);
            app.ColormapRestasDropDown.Items = {'parula', 'viridis', 'plasma', 'magma', 'inferno', 'gray', 'bone'};
            app.ColormapRestasDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapRestasDropDownValueChanged, true);
            app.ColormapRestasDropDown.Value = 'parula';
            app.ReverseColormapRestasCheckBox = uicheckbox(app.SymmetryTab);
            app.ReverseColormapRestasCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseColormapRestasCheckBoxValueChanged, true);
            app.ReverseColormapRestasCheckBox.Text = 'Reverse';
            app.ColormapRestasDropDownCategory = uidropdown(app.SymmetryTab);
            app.ColormapRestasDropDownCategory.Items = {'Most Used', 'Uniform Sequential', 'Uniform Diverging', 'Uniform Cyclic', 'Sequential', 'Diverging', 'Cyclic', 'Misc', 'Categorical'};
            app.ColormapRestasDropDownCategory.ValueChangedFcn = createCallbackFcn(app, @ColormapRestasDropDownCategoryValueChanged, true);
            app.ColormapRestasDropDownCategory.Value = 'Most Used';
            app.ShiftColormapRestasCheckBox = uicheckbox(app.SymmetryTab);
            app.ShiftColormapRestasCheckBox.Enable = 'off';
            app.ShiftColormapRestasCheckBox.Visible = 'off';
            app.ShiftColormapRestasCheckBox.Text = 'Shift';
            app.OperationButtonGroup = uibuttongroup(app.SymmetryTab);
            app.OperationButtonGroup.Tag = 'Symmetry';
            app.BogolAngleButton = uiradiobutton(app.OperationButtonGroup);
            app.BogolAngleButton.Text = 'Bogol. Angle';
            app.DivisionButton = uiradiobutton(app.OperationButtonGroup);
            app.DivisionButton.Tooltip = {'Divides maps with opposite bias. Shifts by 0.1 before dividing.'};
            app.DivisionButton.Text = 'Division';
            app.DifferenceButton = uiradiobutton(app.OperationButtonGroup);
            app.DifferenceButton.Text = 'Difference';
            app.DifferenceButton.Value = true;
            app.blqAnalysisQPIStudyLabel = uilabel(app.blqAnalysisUIFigure);
            app.blqAnalysisQPIStudyLabel.Text = 'blqAnalysis + QPI Study';
            app.TemperatureLabel = uilabel(app.blqAnalysisUIFigure);
            app.TemperatureLabel.Visible = 'off';
            app.TemperatureLabel.Text = 'T = ';
            app.MagneticFieldLabel = uilabel(app.blqAnalysisUIFigure);
            app.MagneticFieldLabel.Visible = 'off';
            app.MagneticFieldLabel.Text = 'H = ';
            app.TemperatureValue = uilabel(app.blqAnalysisUIFigure);
            app.TemperatureValue.Visible = 'off';
            app.TemperatureValue.Text = '';
            app.MagneticFieldValue = uilabel(app.blqAnalysisUIFigure);
            app.MagneticFieldValue.Visible = 'off';
            app.MagneticFieldValue.Text = '';
            % Show the figure after all components are created
            app.blqAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blqApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.blqAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.blqAnalysisUIFigure)
        end
    end
end